classdef BinaryApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        OverlayCheckBox                 matlab.ui.control.CheckBox
        FrameSpinner                    matlab.ui.control.Spinner
        FrameSpinnerLabel               matlab.ui.control.Label
        EditField_4                     matlab.ui.control.NumericEditField
        EditField_3                     matlab.ui.control.NumericEditField
        EditField_2                     matlab.ui.control.NumericEditField
        ExpansionDistanceSlider         matlab.ui.control.Slider
        ExpansionDistanceSliderLabel    matlab.ui.control.Label
        MinimumAreaSizeSlider           matlab.ui.control.Slider
        MinimumAreaSizeSliderLabel      matlab.ui.control.Label
        MaximumOccuringFrequencySlider  matlab.ui.control.Slider
        MaximumOccuringFrequencySliderLabel  matlab.ui.control.Label
        BinaryThresholdSlider           matlab.ui.control.Slider
        BinaryThresholdSliderLabel      matlab.ui.control.Label
        EditField                       matlab.ui.control.NumericEditField
        Image                           matlab.ui.control.Image
    end

    
    properties (Access = private)
        img % Description
        vid
        BW
        fullVid
        BWimg
        maskedImg
        minAreaImg
        expandedImg
        maskVideo
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: BinaryThresholdSlider
        function BinaryThresholdSliderValueChanged(app, event)
            value = app.BinaryThresholdSlider.Value;
            app.EditField.Value = value;
            app.BW = process_binary_videoSteps_v2(app.vid,value,[app.MaximumOccuringFrequencySlider.Value,app.MinimumAreaSizeSlider.Value,app.ExpansionDistanceSlider.Value],'Binary');
            tempBW = app.BW*255;
            app.Image.ImageSource = cat(3,tempBW(:,:,1),tempBW(:,:,1),tempBW(:,:,1));
            app.MaximumOccuringFrequencySlider.Enable = "on";
            app.MinimumAreaSizeSlider.Enable = "on";
            app.ExpansionDistanceSlider.Enable = "on";
            app.MaximumOccuringFrequencySliderLabel.Enable = "on";
            app.MinimumAreaSizeSliderLabel.Enable = "on";
            app.ExpansionDistanceSliderLabel.Enable = "on";
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            [file,location] = uigetfile({'*.png;*.avi'});
            if ~isequal(file,0)
                [~,f,e] = fileparts(file);
                if e == ".png"
                   app.MaximumOccuringFrequencySlider.Enable = "off";
                   app.img = imread(fullfile(location,file));
                   app.Image.ImageSource = cat(3,app.img,app.img,app.img);
                else
                    % app.MaximumOccuringFrequencySlider.Enable = "on";
                    app.fullVid = read_avi(fullfile(location,file));
                    app.vid = app.fullVid(:,:,1:100);
                    app.img = app.vid(:,:,1);
                    app.Image.ImageSource = cat(3,app.img,app.img,app.img);
                end

            end

        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            app.BinaryThresholdSlider.Value = value;
            [app.BW,app.maskVideo] = process_binary_videoSteps_v2(app.vid,value,[app.MaximumOccuringFrequencySlider.Value,app.MinimumAreaSizeSlider.Value,app.ExpansionDistanceSlider.Value],'Binary');
            max(app.BW(:))
            app.maskedImg = app.maskVideo(:,:,1);
            app.minAreaImg = bwareaopen(app.maskedImg,app.MinimumAreaSizeSlider.Value);
            app.expandedImg = uint8(imdilate(app.minAreaImg,strel("disk",ceil(app.ExpansionDistanceSlider.Value))));

            tempBW = double(app.BW);
            app.Image.ImageSource = cat(3,tempBW(:,:,1),tempBW(:,:,1),tempBW(:,:,1));
        end

        % Callback function
        function BinaryThresholdSliderValueChanging(app, event)
            changingValue = event.Value;
            app.EditField.Value = changingValue;
            [app.BW,app.maskVideo] = process_binary_videoSteps_v2(app.vid,changingValue,[app.MaximumOccuringFrequencySlider.Value,app.MinimumAreaSizeSlider.Value,app.ExpansionDistanceSlider.Value],'Binary');
            tempBW = uint8(app.BW)*255;
            app.Image.ImageSource = cat(3,tempBW(:,:,1),tempBW(:,:,1),tempBW(:,:,1));
        end

        % Value changed function: MaximumOccuringFrequencySlider
        function MaximumOccuringFrequencySliderValueChanged(app, event)
            value = app.MaximumOccuringFrequencySlider.Value;
            app.EditField_2.Value = value;
            [tempBWvid,app.maskVideo] = process_binary_videoSteps_v2(app.BW,app.BinaryThresholdSlider.Value,[value,app.MinimumAreaSizeSlider.Value,app.ExpansionDistanceSlider.Value],'Mask');
            app.maskedImg = app.maskVideo(:,:,1);
            app.minAreaImg = bwareaopen(app.maskedImg,app.MinimumAreaSizeSlider.Value);
            app.expandedImg = uint8(imdilate(app.minAreaImg,strel("disk",ceil(app.ExpansionDistanceSlider.Value))));
            % app.minAreaImg = bwareaopen(app.maskedImg,app.MaximumOccuringFrequencySlider.Value);
            % app.M

            tempBW = uint8(tempBWvid(:,:,1))*255;
            app.Image.ImageSource = cat(3,tempBW,tempBW,tempBW);
        end

        % Callback function
        function MaximumOccuringFrequencySliderValueChanging(app, event)
            changingValue = event.Value;
            app.EditField_2.Value = changingValue;
            app.BW = process_binary_videoSteps_v2(app.vid,app.BinaryThresholdSlider.Value,[changingValue,app.MinimumAreaSizeSlider.Value,app.ExpansionDistanceSlider.Value],'Mask');
            tempBW = app.BW*255;
            app.Image.ImageSource = cat(3,tempBW(:,:,1),tempBW(:,:,1),tempBW(:,:,1));
        end

        % Value changed function: MinimumAreaSizeSlider
        function MinimumAreaSizeSliderValueChanged(app, event)
            % value = ceil(app.MinimumAreaSizeSlider.Value);
            % app.EditField_3.Value = value;
            % app.MinimumAreaSizeSlider.Value = value;
            % app.BW = process_binary_videoSteps_v2(app.BW,app.BinaryThresholdSlider.Value,[app.MaximumOccuringFrequencySlider.Value,value,app.ExpansionDistanceSlider.Value],'SmallObj');
            % tempBW = uint8(app.BW)*255;

            value = ceil(app.MinimumAreaSizeSlider.Value);
            app.EditField_3.Value = value;
            app.MinimumAreaSizeSlider.Value = value;
            app.minAreaImg = bwareaopen(app.maskedImg,value);
            app.expandedImg = uint8(imdilate(app.minAreaImg,strel("disk",ceil(app.ExpansionDistanceSlider.Value))));
            tempBW = uint8(app.expandedImg)*255;

            % class(app.BW)
            % max(app.BW(:))
            % size(app.BW)
            app.Image.ImageSource = cat(3,tempBW,tempBW,tempBW);
        end

        % Callback function
        function MinimumAreaSizeSliderValueChanging(app, event)
            changingValue = event.Value;
            app.EditField_3.Value = changingValue;
            app.BW = process_binary_videoSteps_v2(app.vid,app.BinaryThresholdSlider.Value,[app.MaximumOccuringFrequencySlider.Value,changingValue,app.ExpansionDistanceSlider.Value],'SmallObj');
            tempBW = app.BW*255;
            app.Image.ImageSource = cat(3,tempBW(:,:,1),tempBW(:,:,1),tempBW(:,:,1));
        end

        % Value changed function: ExpansionDistanceSlider
        function ExpansionDistanceSliderValueChanged(app, event)
            % value = app.ExpansionDistanceSlider.Value;
            % app.EditField_4.Value = ceil(value);
            % app.ExpansionDistanceSlider.Value = ceil(value);
            % app.BW = process_binary_videoSteps_v2(app.vid/255,app.BinaryThresholdSlider.Value,[app.MaximumOccuringFrequencySlider.Value,app.MinimumAreaSizeSlider.Value,ceil(value)],'Dilate');
            % tempBW = uint8(app.BW)*255;
            % app.Image.ImageSource = cat(3,tempBW(:,:,1),tempBW(:,:,1),tempBW(:,:,1));

            value = app.ExpansionDistanceSlider.Value;
            app.EditField_4.Value = ceil(value);
            app.ExpansionDistanceSlider.Value = ceil(value);
            % app.BW = process_binary_videoSteps_v2(app.vid/255,app.BinaryThresholdSlider.Value,[app.MaximumOccuringFrequencySlider.Value,app.MinimumAreaSizeSlider.Value,ceil(value)],'Dilate');
            app.expandedImg = uint8(imdilate(app.minAreaImg,strel("disk",ceil(value))))*255;
            tempBW = uint8(app.expandedImg)*255;
            app.Image.ImageSource = cat(3,tempBW,tempBW,tempBW);
        end

        % Callback function
        function ExpansionDistanceSliderValueChanging(app, event)
            changingValue = event.Value;
            app.EditField.Value = changingValue;
            app.BW = process_binary_videoSteps_v2(app.vid,app.BinaryThresholdSlider.Value,[app.MaximumOccuringFrequencySlider.Value,app.MinimumAreaSizeSlider.Value,changingValue],'Dilate');
            tempBW = app.BW*255;
            app.Image.ImageSource = cat(3,tempBW(:,:,1),tempBW(:,:,1),tempBW(:,:,1));
        end

        % Value changed function: FrameSpinner
        function FrameSpinnerValueChanged(app, event)
            value = app.FrameSpinner.Value;
            if mod(value-1,100) == 0
            app.vid = app.fullVid(:,:,value:100+value);
            app.img = app.vid(:,:,value-floor(value/100)*100);
            app.BW = process_binary_videoSteps_v2(app.vid,app.BinaryThresholdSlider.Value,[app.MaximumOccuringFrequencySlider.Value,app.MinimumAreaSizeSlider.Value,app.ExpansionDistanceSlider.Value],'Binary');
            tempBW = app.BW*255;
            elseif value > 100
                app.img = app.vid(:,:,value-floor(value/100)*100);
                
            end
            app.Image.ImageSource = cat(3,tempBW(:,:,1),tempBW(:,:,1),tempBW(:,:,1));
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            value = app.EditField_2.Value;
            app.MaximumOccuringFrequencySlider.Value = value;
            app.BW = process_binary_videoSteps_v2(app.vid,app.BinaryThresholdSlider.Value,[app.MaximumOccuringFrequencySlider.Value,value,app.MinimumAreaSizeSlider.Value,app.ExpansionDistanceSlider.Value],'Mask');
            tempBW = app.BW*255;
            app.Image.ImageSource = cat(3,tempBW(:,:,1),tempBW(:,:,1),tempBW(:,:,1));
        end

        % Value changed function: OverlayCheckBox
        function OverlayCheckBoxValueChanged(app, event)
            value = app.OverlayCheckBox.Value;
            if value 
                if mod(value-1,100) == 0
                    app.vid = app.fullVid(:,:,value:100+value);
                    app.img = app.vid(:,:,value-floor(value/100)*100);
                    app.BW = process_binary_videoSteps_v2(app.vid,app.BinaryThresholdSlider.Value,[app.MaximumOccuringFrequencySlider.Value,app.MinimumAreaSizeSlider.Value,app.ExpansionDistanceSlider.Value],'Binary');
                    tempBW = app.BW*255;
                elseif value > 100
                    app.img = app.vid(:,:,value-floor(value/100)*100);
                    % app.
                end
                app.sourceImg = labeloverlay(app.expandedImg,app.vid)
            else

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.EditField = uieditfield(app.GridLayout, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.BinaryThresholdSliderLabel = uilabel(app.GridLayout);
            app.BinaryThresholdSliderLabel.Text = 'Binary Threshold';
            app.BinaryThresholdSlider = uislider(app.GridLayout);
            app.BinaryThresholdSlider.Limits = [0 1];
            app.BinaryThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @BinaryThresholdSliderValueChanged, true);
            app.MaximumOccuringFrequencySliderLabel = uilabel(app.GridLayout);
            app.MaximumOccuringFrequencySliderLabel.Enable = 'off';
            app.MaximumOccuringFrequencySliderLabel.Text = 'Maximum Occuring Frequency';
            app.MaximumOccuringFrequencySlider = uislider(app.GridLayout);
            app.MaximumOccuringFrequencySlider.Limits = [0 1];
            app.MaximumOccuringFrequencySlider.ValueChangedFcn = createCallbackFcn(app, @MaximumOccuringFrequencySliderValueChanged, true);
            app.MaximumOccuringFrequencySlider.Enable = 'off';
            app.MinimumAreaSizeSliderLabel = uilabel(app.GridLayout);
            app.MinimumAreaSizeSliderLabel.Enable = 'off';
            app.MinimumAreaSizeSliderLabel.Text = 'Minimum Area Size';
            app.MinimumAreaSizeSlider = uislider(app.GridLayout);
            app.MinimumAreaSizeSlider.Limits = [1 100];
            app.MinimumAreaSizeSlider.ValueChangedFcn = createCallbackFcn(app, @MinimumAreaSizeSliderValueChanged, true);
            app.MinimumAreaSizeSlider.Enable = 'off';
            app.MinimumAreaSizeSlider.Value = 1;
            app.ExpansionDistanceSliderLabel = uilabel(app.GridLayout);
            app.ExpansionDistanceSliderLabel.Enable = 'off';
            app.ExpansionDistanceSliderLabel.Text = 'Expansion Distance';
            app.ExpansionDistanceSlider = uislider(app.GridLayout);
            app.ExpansionDistanceSlider.Limits = [1 50];
            app.ExpansionDistanceSlider.ValueChangedFcn = createCallbackFcn(app, @ExpansionDistanceSliderValueChanged, true);
            app.ExpansionDistanceSlider.Enable = 'off';
            app.ExpansionDistanceSlider.Value = 1;
            app.EditField_2 = uieditfield(app.GridLayout, 'numeric');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_3 = uieditfield(app.GridLayout, 'numeric');
            app.EditField_3.RoundFractionalValues = 'on';
            app.EditField_3.Value = 1;
            app.EditField_4 = uieditfield(app.GridLayout, 'numeric');
            app.EditField_4.Limits = [1 Inf];
            app.EditField_4.Value = 1;
            app.FrameSpinnerLabel = uilabel(app.GridLayout);
            app.FrameSpinnerLabel.Text = 'Frame#';
            app.FrameSpinner = uispinner(app.GridLayout);
            app.FrameSpinner.Limits = [1 Inf];
            app.FrameSpinner.RoundFractionalValues = 'on';
            app.FrameSpinner.ValueChangedFcn = createCallbackFcn(app, @FrameSpinnerValueChanged, true);
            app.FrameSpinner.Value = 1;
            app.OverlayCheckBox = uicheckbox(app.GridLayout);
            app.OverlayCheckBox.ValueChangedFcn = createCallbackFcn(app, @OverlayCheckBoxValueChanged, true);
            app.OverlayCheckBox.Text = 'Overlay';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BinaryApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end