classdef SegmentationAppV3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ZooplanktonAppUIFigure          matlab.ui.Figure
        ClearMemoryButton               matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        PreprocessingTab                matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        LoadingImage                    matlab.ui.control.Image
        Panel                           matlab.ui.container.Panel
        GridLayout8                     matlab.ui.container.GridLayout
        ContinuetoSegmentationButton    matlab.ui.control.Button
        ClearAllButton                  matlab.ui.control.Button
        UndoLastButton                  matlab.ui.control.Button
        SaveOutputButton                matlab.ui.control.Button
        TextArea                        matlab.ui.control.TextArea
        MorphologicalOperationsPanel    matlab.ui.container.Panel
        GridLayout10                    matlab.ui.container.GridLayout
        MorphOpt2Label                  matlab.ui.control.Label
        MorphOpt1Label                  matlab.ui.control.Label
        CloseButton                     matlab.ui.control.Button
        OpenButton                      matlab.ui.control.Button
        ErodeButton                     matlab.ui.control.Button
        DilateButton                    matlab.ui.control.Button
        MorphOpt2                       matlab.ui.control.Spinner
        MorphOpt1                       matlab.ui.control.Spinner
        StructureDropDown               matlab.ui.control.DropDown
        StructureDropDownLabel          matlab.ui.control.Label
        ContrastToolsPanel              matlab.ui.container.Panel
        GridLayout9                     matlab.ui.container.GridLayout
        DegreeSmooth                    matlab.ui.control.EditField
        HighIn                          matlab.ui.control.EditField
        LowIn                           matlab.ui.control.EditField
        HighOut                         matlab.ui.control.EditField
        LowOut                          matlab.ui.control.EditField
        Intensity                       matlab.ui.control.EditField
        DefaultCheckBox_4               matlab.ui.control.CheckBox
        DefaultCheckBox_3               matlab.ui.control.CheckBox
        DefaultCheckBox                 matlab.ui.control.CheckBox
        IntensityLevelsLabel            matlab.ui.control.Label
        DefaultCheckBox_2               matlab.ui.control.CheckBox
        AlphaLabel                      matlab.ui.control.Label
        SigmaLabel_2                    matlab.ui.control.Label
        BilatFiltButton                 matlab.ui.control.Button
        SigmaLabel                      matlab.ui.control.Label
        DoSLabel                        matlab.ui.control.Label
        BilatSigma                      matlab.ui.control.NumericEditField
        BilateralFilterLabel            matlab.ui.control.Label
        LaplaceFiltButton               matlab.ui.control.Button
        Alpha                           matlab.ui.control.NumericEditField
        Sigma                           matlab.ui.control.NumericEditField
        LaplaceFilterLabel              matlab.ui.control.Label
        AdjustButton                    matlab.ui.control.Button
        LowHighOutLabel                 matlab.ui.control.Label
        LowHighInLabel                  matlab.ui.control.Label
        AdjustLabel                     matlab.ui.control.Label
        SharpenButton                   matlab.ui.control.Button
        ThresholdLabel                  matlab.ui.control.Label
        AmountLabel                     matlab.ui.control.Label
        RadiusLabel                     matlab.ui.control.Label
        SharpThresholdSpinner           matlab.ui.control.NumericEditField
        SharpAmountSpinner              matlab.ui.control.NumericEditField
        SharpRadSpinner                 matlab.ui.control.NumericEditField
        SharpenLabel                    matlab.ui.control.Label
        FlatFieldPanel                  matlab.ui.container.Panel
        GridLayout7                     matlab.ui.container.GridLayout
        BeginFlatFieldButton            matlab.ui.control.Button
        BrowseButton_3                  matlab.ui.control.Button
        VideoSourceEditField_2          matlab.ui.control.EditField
        VideoSourceEditField_2Label     matlab.ui.control.Label
        GenerateCalibrationFrameButton  matlab.ui.control.Button
        EditField_3                     matlab.ui.control.NumericEditField
        BrightnessSlider                matlab.ui.control.Slider
        BrightnessSliderLabel           matlab.ui.control.Label
        FlatFieldingMethodDropDown      matlab.ui.control.DropDown
        FlatFieldingMethodDropDownLabel  matlab.ui.control.Label
        Image2                          matlab.ui.control.UIAxes
        SegmentationTab                 matlab.ui.container.Tab
        GridLayout11                    matlab.ui.container.GridLayout
        VideoSourceDropDown             matlab.ui.control.DropDown
        VideoSourceDropDownLabel        matlab.ui.control.Label
        Label2                          matlab.ui.control.Label
        Frame1Label                     matlab.ui.control.Label
        Button_4                        matlab.ui.control.Button
        Button_3                        matlab.ui.control.Button
        LoadingImage3                   matlab.ui.control.Image
        Button                          matlab.ui.control.Button
        Button_2                        matlab.ui.control.Button
        SegmentationParametersPanel     matlab.ui.container.Panel
        GridLayout14                    matlab.ui.container.GridLayout
        SavesmallimagesCheckBox         matlab.ui.control.CheckBox
        EditField_9                     matlab.ui.control.EditField
        EditField_8                     matlab.ui.control.EditField
        StartEndSlider                  matlab.ui.control.RangeSlider
        StartEndSliderLabel             matlab.ui.control.Label
        BinaryThresholdSlider           matlab.ui.control.Slider
        BinaryThresholdSliderLabel      matlab.ui.control.Label
        DepthFramesSlider               matlab.ui.control.Slider
        DepthFramesSliderLabel          matlab.ui.control.Label
        CropPaddingSlider               matlab.ui.control.Slider
        CropPaddingSliderLabel          matlab.ui.control.Label
        MaxFrequencySlider              matlab.ui.control.Slider
        MaxFrequencySliderLabel         matlab.ui.control.Label
        MinAreaSlider                   matlab.ui.control.Slider
        MinAreaSliderLabel              matlab.ui.control.Label
        ExpansionDistanceSlider         matlab.ui.control.Slider
        ExpansionDistanceSliderLabel    matlab.ui.control.Label
        EditField_2                     matlab.ui.control.NumericEditField
        EditField                       matlab.ui.control.NumericEditField
        ContinuetoClassificationButton  matlab.ui.control.Button
        SettoReccomendedButton          matlab.ui.control.Button
        BeginSegmentationButton         matlab.ui.control.Button
        ProcessBinaryVideoButton        matlab.ui.control.Button
        BrowseButton                    matlab.ui.control.Button
        LoadingImage2                   matlab.ui.control.Image
        RawImg                          matlab.ui.control.UIAxes
        BWimg                           matlab.ui.control.UIAxes
        ClassificationTab               matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        PrepareDatasetPanel             matlab.ui.container.Panel
        GridLayout6                     matlab.ui.container.GridLayout
        Panel_2                         matlab.ui.container.Panel
        GridLayout13                    matlab.ui.container.GridLayout
        Image5                          matlab.ui.control.Image
        histAxes2                       matlab.ui.control.UIAxes
        BrowseButton_4                  matlab.ui.control.Button
        DatasetSourceEditField          matlab.ui.control.EditField
        DatasetSourceEditFieldLabel     matlab.ui.control.Label
        PrepareButton                   matlab.ui.control.Button
        FinalImageSizeEditField         matlab.ui.control.EditField
        FinalImageSizeEditFieldLabel    matlab.ui.control.Label
        ClassIdentifiersEditField       matlab.ui.control.EditField
        ClassIdentifiersEditFieldLabel  matlab.ui.control.Label
        ClassifyZooplanktonImagesPanel  matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        histPanel                       matlab.ui.container.Panel
        GridLayout12                    matlab.ui.container.GridLayout
        Image4                          matlab.ui.control.Image
        histAxis                        matlab.ui.control.UIAxes
        EditField_7                     matlab.ui.control.NumericEditField
        ConfidenceThresholdSlider       matlab.ui.control.Slider
        ConfidenceThresholdSliderLabel  matlab.ui.control.Label
        BeginClassificationButton       matlab.ui.control.Button
        UnsortedImagesEditField         matlab.ui.control.EditField
        UnsortedImagesEditFieldLabel    matlab.ui.control.Label
        TrainedNetworkDropDownLabel     matlab.ui.control.Label
        TrainedNetworkDropDown          matlab.ui.control.DropDown
        BrowseButton_2                  matlab.ui.control.Button
        TrainNewNetworkPanel            matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        Image3                          matlab.ui.control.Image
        EnvironmentDropDown             matlab.ui.control.DropDown
        EnvironmentDropDownLabel        matlab.ui.control.Label
        ConfusionMatPanel               matlab.ui.container.Panel
        EditField_6                     matlab.ui.control.NumericEditField
        EditField_5                     matlab.ui.control.NumericEditField
        EditField_4                     matlab.ui.control.NumericEditField
        BeginTrainingButton             matlab.ui.control.Button
        LearningRateSlider              matlab.ui.control.Slider
        LearningRateSliderLabel         matlab.ui.control.Label
        ValidationFrequencySlider       matlab.ui.control.Slider
        ValidationFrequencySliderLabel  matlab.ui.control.Label
        MaxEpochsSlider                 matlab.ui.control.Slider
        MaxEpochsSliderLabel            matlab.ui.control.Label
        NetworkArchitecureDropDown      matlab.ui.control.DropDown
        NetworkArchitecureDropDownLabel  matlab.ui.control.Label
        TrainingDatasetDropDown         matlab.ui.control.DropDown
        TrainingDatasetDropDownLabel    matlab.ui.control.Label
        CurrentTrainingAccuracyLabel    matlab.ui.control.Label
    end

    
    properties (Access = private)
        vid % Description
        h_vars
        BW
        frameNum = 1;
        BWframeNum = 1;
        trainingDatset
        trainingClasses
        rawVid
        cal_frame
        ffvid
        rawVidFilePath
        processSequence={};
        previousImage
        sampleImage
        MorphOptions
        NetArch
        net
        TrainingOptions
        imds
        ClassNames
        dataSetFilePath
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function ZooplanktonAppUIFigureSizeChanged(app, event)
            
        end

        % Button pushed function: SettoReccomendedButton
        function SettoReccomendedButtonPushed(app, event)
            app.MaxFrequencySlider.Value = 0.2;
            app.MinAreaSlider.Value = 15;
            app.ExpansionDistanceSlider.Value = 5;
            app.DepthFramesSlider.Value = 2;
            app.CropPaddingSlider.Value = 5;
            app.BinaryThresholdSlider.Value = 0.60;
        end

        % Button pushed function: ProcessBinaryVideoButton
        function ProcessBinaryVideoButtonPushed(app, event)
            app.LoadingImage3.Enable = 'on';
            app.LoadingImage3.Visible = 'on';
            pause(0.5);
            app.h_vars = [app.MaxFrequencySlider.Value,app.MinAreaSlider.Value,app.ExpansionDistanceSlider.Value,app.CropPaddingSlider.Value,app.DepthFramesSlider.Value];
            % if ~isempty(app.VideoSourceEditField.Value)
            %     f = msgbox("Video Source Field is empty, please provide valid path");
            % else
                % vid = read_avi(app.VideoSourceEditField.Value);
            app.BW = process_binary_videoV2(app.vid,app.BinaryThresholdSlider.Value,app.h_vars);
            % app.LoadingImage3.ImageSource = cat(3,app.BW(:,:,1),app.BW(:,:,1),app.BW(:,:,1))*255;
            app.LoadingImage3.Visible = "off";
            imshow(app.BW(:,:,1),"Parent",app.BWimg)
            set(app.BWimg,'Xtick',[])
            set(app.BWimg,'Ytick',[])
            set(app.BWimg,'Xlim',[0,size(app.BW,2)])
            set(app.BWimg,'Ylim',[0,size(app.BW,1)])
            app.BeginSegmentationButton.Enable = 'on';
            app.StartEndSlider.Enable = "on";
            app.StartEndSliderLabel.Enable = "on";
            app.EditField.Enable = "on";
            app.EditField_2.Enable = "on";
            app.BWframeNum = 1;
            app.Button_3.Visible = "on";
            app.Button_4.Visible = "on";
            app.Label2.Visible = "on";
            app.Label2.Text = "Frame 1";
            app.SavesmallimagesCheckBox.Enable = "on";
            app.EditField_8.Enable = "on";
            app.EditField_9.Enable = "on";
            % end
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            app.ZooplanktonAppUIFigure.WindowStyle = "normal";
            [file,location] = uigetfile('*.avi');    
            app.ZooplanktonAppUIFigure.WindowStyle = "alwaysontop";
            if ~isequal(file,0)
                app.LoadingImage2.Visible = 'on';
                app.VideoSourceEditField.Value = fullfile(location,file);
                value = app.VideoSourceEditField.Value;
                app.vid = read_avi(value);
                app.LoadingImage2.ImageSource = cat(3,app.vid(:,:,1),app.vid(:,:,1),app.vid(:,:,1));
                app.StartEndSlider.Limits = [0 size(app.vid,3)];
                app.EditField_2.Value = size(app.vid,3);
                app.StartEndSlider.Value(2) = size(app.vid,3);
                app.Button.Visible = "on";
                app.Button_2.Visible = "on";
                app.ProcessBinaryVideoButton.Enable = 'on';
            end
                
        end

        % Callback function
        function VideoSourceEditFieldValueChanged(app, event)
            value = app.VideoSourceEditField.Value;
            app.LoadingImage2.Visible = 'on';
            app.vid = read_avi(value);
            app.LoadingImage2.ImageSource = cat(3,app.vid(:,:,1),app.vid(:,:,1),app.vid(:,:,1)); 
            app.StartEndSlider.Limits = [0 size(app.vid,3)];
            app.EditField_2.Value = size(app.vid,3);
            app.Button.Visible = "on";
            app.Button_2.Visible = "on";
            app.Frame1Label.Visible = 'on';
            app.Frame1Label.Text = "Frame 1";
            app.ProcessBinaryVideoButton.Enable = 'on';
        end

        % Button pushed function: BeginSegmentationButton
        function BeginSegmentationButtonPushed(app, event)
            startFrame = app.StartEndSlider.Value(1);
            endFrame = app.StartEndSlider.Value(2);
            if app.SavesmallimagesCheckBox.Value
                if isempty(app.EditField_8.Value)
                    app.EditField_8.Value = 200;
                end
                if isempty(app.EditField_9.Value)
                    app.EditField_9.Value = 200;
                end
                app.dataSetFilePath = segment_objects(app.vid(:,:,startFrame:endFrame),app.BW(:,:,startFrame:endFrame),app.h_vars,app.rawVidFilePath,app.SavesmallimagesCheckBox.Value,string2num(app.EditField_8.Value),string2num(app.EditField_9.Value));
            else
                app.dataSetFilePath = segment_objects(app.vid(:,:,startFrame:endFrame),app.BW(:,:,startFrame:endFrame),app.h_vars,app.rawVidFilePath,app.SavesmallimagesCheckBox.Value,0,0);
            end
            app.ContinuetoClassificationButton.Enable = "on";
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            displayFrame = app.frameNum - 1;
            imshow(app.vid(:,:,displayFrame),"Parent",app.RawImg)
            app.frameNum = app.frameNum - 1;
            if app.frameNum == 1
                app.Button.Enable = "off";
            elseif app.frameNum == size(app.vid,3) - 1
                app.Button_2.Enable = "on";
            end
            app.Frame1Label.Text = strcat("Frame ",num2str(displayFrame));
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            displayFrame = app.frameNum + 1;
            imshow(app.vid(:,:,displayFrame),"Parent",app.RawImg)
            app.frameNum = app.frameNum + 1;
            if app.frameNum == 2
                app.Button.Enable = "on";
            elseif app.frameNum == size(app.vid,3)
                app.Button_2.Enable = "off";
            end    
            app.Frame1Label.Text = strcat("Frame ",num2str(displayFrame));
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            displayFrame = app.BWframeNum - 1;
            imshow(app.BW(:,:,displayFrame),"Parent",app.BWimg)
            app.BWframeNum = app.BWframeNum - 1;
            if app.BWframeNum == 1
                app.Button_3.Enable = "off";
            elseif app.BWframeNum == size(app.BW,3) - 1
                app.Button_4.Enable = "on";
            end
            app.Label2.Text = strcat("Frame ",num2str(displayFrame));
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            displayFrame = app.BWframeNum + 1;
            imshow(app.BW(:,:,displayFrame),"Parent",app.BWimg)
            app.BWframeNum = app.BWframeNum + 1;
            if app.BWframeNum == 2
                app.Button_3.Enable = "on";
            elseif app.BWframeNum == size(app.BW,3)
                app.Button_4.Enable = "off";
            end
            app.Label2.Text = strcat("Frame ",num2str(displayFrame));
        end

        % Value changed function: StartEndSlider
        function StartEndSliderValueChanged(app, event)
            value = app.StartEndSlider.Value;
            app.StartEndSlider.Value = ceil(value);
            app.EditField.Value = ceil(value(1));
            app.EditField_2.Value = ceil(value(2));
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            app.StartEndSlider.Value(1) = ceil(value);
            app.EditField.Value = ceil(value);
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            value = app.EditField_2.Value;
            app.StartEndSlider.Value(2) = ceil(value);
            app.EditField_2.Value = ceil(value);
        end

        % Button pushed function: ContinuetoClassificationButton
        function ContinuetoClassificationButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.ClassificationTab;
            app.UnsortedImagesEditField.Value = app.dataSetFilePath;
            app.imds = imageDatastore(app.dataSetFilePath,"IncludeSubfolders",true,"LabelSource","foldernames");
        end

        % Callback function
        function TrainingDatasetDropDownClicked(app, event)
            
        end

        % Callback function
        function TrainingDatasetDropDownValueChanged(app, event)
            % if value == "Browse..."
            %     oldItems = app.TrainingDatasetDropDown.Items;
            %     location = uigetdir;
            %     newDataset = location;
            %     if ~isequal(location,0)
            %         app.imds = imageDatastore(location,"IncludeSubfolders",true,"LabelSource","foldernames");
            %         trainingSet = cat(2,oldItems,{newDataset});
            %         app.TrainingDatasetDropDown.Items = unique(trainingSet);
            %         app.TrainingDatasetDropDown.Value = string(newDataset);
            %         app.trainingClasses = length(app.imds.Labels);
            %         app.NetworkArchitecureDropDown.Enable = "on";
            %         app.NetworkArchitecureDropDownLabel.Enable = "on";
            %     end
            % end    
            % if value ~= "Browse..."
            %     app.NetworkArchitecureDropDown.Enable = "on";
            %     app.NetworkArchitecureDropDownLabel.Enable = "on";
            % 
            % end    
        end

        % Button pushed function: BeginClassificationButton
        function BeginClassificationButtonPushed(app, event)
            classifySegmentedImages(app.net,app.imds,app.ConfidenceThresholdSlider.Value,app.histAxis,pwd);
            app.histAxis.Visible = "on";
        end

        % Callback function
        function ofClassesSpinnerValueChanged(app, event)
            value = app.ofClassesSpinner.Value;
            app.trainingClasses = value;
        end

        % Button pushed function: GenerateCalibrationFrameButton
        function GenerateCalibrationFrameButtonPushed(app, event)
            % app.Image2.ImageSource = 'Wedges@1x-4.0s-200px-200px(1).gif';
            imshow("solid-color-image.png","Parent",app.Image2)
            % app.Image2.Toolbar.Visible = "off";
            app.LoadingImage.Visible = "on";
            % imshow('Wedges@1x-4.0s-200px-200px(1).gif',"Parent",app.Image2)
            pause(0.5)
            tic
            app.cal_frame = calibrateV2(app.rawVid);
            imshow(uint8(app.cal_frame),"Parent",app.Image2)
            app.Image2.Visible = "on";
            % app.Image2.Toolbar.Visible = "on";
            app.LoadingImage.Visible ="off";
            % app.Image2.ImageSource = cat(3,app.cal_frame,app.cal_frame,app.cal_frame);
            tEnd = toc;
            app.TextArea.Value = cat(1,app.TextArea.Value,{['Calibration Frame generated in',' ',char(num2str(tEnd,'%.3f')),' seconds']});
            app.FlatFieldingMethodDropDown.Enable = "on";
            app.FlatFieldingMethodDropDownLabel.Enable = "on";
            app.BrightnessSlider.Enable ="on";
            app.BrightnessSliderLabel.Enable = "on";
            app.BeginFlatFieldButton.Enable = "on";
            app.EditField_3.Enable = "on";
        end

        % Button pushed function: BrowseButton_3
        function BrowseButton_3Pushed(app, event)
            app.ZooplanktonAppUIFigure.WindowStyle = "normal";
            [file,location] = uigetfile('*.avi');
            app.ZooplanktonAppUIFigure.WindowStyle = "alwaysontop";
            if ~isequal(file,0)
            app.rawVidFilePath = file;
            % imshow('Wedges@1x-4.0s-200px-200px(1).gif',"Parent",app.Image2)
            app.LoadingImage.Visible = 'on';
            app.VideoSourceEditField_2.Value = fullfile(location,file);
            value = app.VideoSourceEditField_2.Value;
            tic
            try
            app.rawVid = read_avi(value);
            app.LoadingImage.Visible = "off";
            imshow(app.rawVid(:,:,1),"Parent",app.Image2);
            app.Image2.Visible = 'on';
            set(app.Image2,'Xtick',[])
            set(app.Image2,'Ytick',[])
            set(app.Image2,'Xlim',[0,size(app.rawVid,2)])
            set(app.Image2,'Ylim',[0,size(app.rawVid,1)])
            catch ME
                if (strcmp(ME.identifier,"MATLAB:audiovideo:VideoReader:FileNotFound"))
                    uialert(app.ZooplanktonAppUIFigure,"File not found, please provide valid path.","Invalid File Path")
                end
            end
            % app.Image2.ImageSource = cat(3,app.rawVid(:,:,1),app.rawVid(:,:,1),app.rawVid(:,:,1));
            tEnd = toc;
            app.TextArea.Value = {['Read Video in',' ',char(num2str(tEnd,' %.3f')),' seconds']};            
            app.GenerateCalibrationFrameButton.Enable = "on";
            end
        end

        % Callback function
        function VideoSourceEditField_2ValueChanged(app, event)
            value = app.VideoSourceEditField_2.Value;
            if exist(value)
            app.Image2.Visible = 'on';
            
            app.rawVid = read_avi(value);
            app.Image2.ImageSource = cat(3,app.rawVid(:,:,1),app.rawVid(:,:,1),app.rawVid(:,:,1));
            app.GenerateCalibrationFrameButton.Enable = "on";
            strs = strsplit(value,'/');
            app.rawVidFilePath = strs(end);
            elseif ~isempty(value)
                uialert(app.ZooplanktonAppUIFigure,"File not found, please provide valid path.","Invalid File Path")
            end    
        end

        % Button pushed function: BeginFlatFieldButton
        function BeginFlatFieldButtonPushed(app, event)
            path = app.VideoSourceEditField_2.Value;
            % app.Image2.ImageSource = 'Wedges@1x-4.0s-200px-200px(1).gif';
            % imshow('Wedges@1x-4.0s-200px-200px(1).gif',"Parent",app.Image2)
            imshow("solid-color-image.png","Parent",app.Image2)
            app.Image2.Visible = "off";
            app.LoadingImage.Visible = "on";

            pause(0.5)
            newPath = strcat(path(1:end-4),"_",app.FlatFieldingMethodDropDown.Value,"_ff.avi");
            [~,f,e] = fileparts(newPath);
            tic
            app.ffvid = preprocess_video(app.rawVid,app.cal_frame,app.BrightnessSlider.Value,app.FlatFieldingMethodDropDown.Value);
            tEnd = toc;
            app.TextArea.Value = cat(1,app.TextArea.Value,{['Flat Fielding Completed in',' ',char(num2str(tEnd,'%.3f')),' seconds']});
            app.Image2.Visible = "on";
            app.LoadingImage.Visible = "off";
            imshow(app.ffvid(:,:,1),"Parent",app.Image2)
            app.ZooplanktonAppUIFigure.WindowStyle = "normal";
            bigFigPos = app.ZooplanktonAppUIFigure.Position;
            alertPos = [100,100,bigFigPos(3)/2,bigFigPos(4)/2];
            % fig = uifigure('Position',alertPos);
            answer = uiconfirm(app.ZooplanktonAppUIFigure,'Would you like to save?','Save Video',"Options",{'Yes','No'},"DefaultOption","Yes");
            app.ZooplanktonAppUIFigure.WindowStyle = "alwaysontop";
            if answer == "Yes"
            tic;
            write_avi(strcat(f,e),app.ffvid);
            tEnd = toc;
            app.TextArea.Value = cat(1,app.TextArea.Value,{['Wrote Video to File in',' ',char(num2str(tEnd,'%.3f')),' seconds']});
            end
            app.ContrastToolsPanel.Enable = "on";
            app.MorphologicalOperationsPanel.Enable = "on";
            app.SaveOutputButton.Enable = "on";
            app.UndoLastButton.Enable = "on";
            app.ClearAllButton.Enable = "on";
            app.sampleImage = app.ffvid(:,:,1);
            app.ContinuetoSegmentationButton.Visible = "on";
            app.vid = app.ffvid;
            % app.Image2.ImageSource = cat(3,app.ffvid(:,:,1),app.ffvid(:,:,1),app.ffvid(:,:,1));

        end

        % Callback function
        function BrightnessSliderValueChanged(app, event)
            value = app.BrightnessSlider.Value;
            app.BrightnessSlider.Value = round(value,2);
            app.EditField_3.Value = round(value,2);
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            value = app.EditField_3.Value;
            app.BrightnessSlider.Value = round(value,2);
            app.EditField_3.Value = round(value,2);
        end

        % Value changed function: VideoSourceDropDown
        function VideoSourceDropDownValueChanged(app, event)
            value = app.VideoSourceDropDown.Value;
            if value == "Browse..."
                app.ZooplanktonAppUIFigure.WindowStyle = "normal";
                [file,location] = uigetfile('*.avi');
                app.ZooplanktonAppUIFigure.WindowStyle = "alwaysontop";
                if ~isequal(file,0)
                app.LoadingImage2.Visible = 'on';
                app.VideoSourceEditField.Value = fullfile(location,file);
                value = app.VideoSourceEditField.Value;
                app.vid = read_avi(value);
                % app.LoadingImage2.ImageSource = cat(3,app.vid(:,:,1),app.vid(:,:,1),app.vid(:,:,1));
                app.LoadingImage2.Visible = "off";
                imshow(app.vid(:,:,1),"Parent",app.RawImg)
                app.RawImg.Visible = "on";
                app.StartEndSlider.Limits = [1 size(app.vid,3)];
                app.EditField_2.Value = size(app.vid,3);
                app.StartEndSlider.Value(2) = size(app.vid,3);
                app.Button.Visible = "on";
                app.Button_2.Visible = "on";
                app.Frame1Label.Visible = "on";
                app.ProcessBinaryVideoButton.Enable = 'on';
                end
            elseif value ~= "Select..."
                if value == "Generated PrecProcessed Video"
                    imshow(app.vid(:,:,1),"Parent",app.RawImg)
                    app.StartEndSlider.Limits = [1 size(app.vid,3)];
                    app.EditField_2.Value = size(app.vid,3);
                    app.StartEndSlider.Value(2) = size(app.vid,3);
                    app.Button.Visible = "on";
                    app.Button_2.Visible = "on";
                    app.ProcessBinaryVideoButton.Enable = 'on';
                    app.Frame1Label.Visible = "on";
                end
            end    
        end

        % Value changed function: StructureDropDown
        function StructureDropDownValueChanged(app, event)
            value = app.StructureDropDown.Value;
            if value == "Diamond"
                app.MorphOpt2.Visible = "off";
                app.MorphOpt2Label.Visible = "off";
                
                app.MorphOpt1Label.Text = "Radius";
                app.MorphOpt1.Visible = "on";
                app.MorphOpt1Label.Visible = "on";
            elseif value == "Disk"

                app.MorphOpt1Label.Text = "Radius";
                app.MorphOpt1.Visible = "on";
                app.MorphOpt1Label.Visible = "on";

                app.MorphOpt2Label.Text = "# of Sides";
                app.MorphOpt2.Visible = "on";
                app.MorphOpt2Label.Visible = "on";
            elseif value == "Octagon"
                app.MorphOpt2.Visible = "off";
                app.MorphOpt2Label.Visible = "off";

                app.MorphOpt1Label.Text = "Radius";
                app.MorphOpt1.Visible = "on";
                app.MorphOpt1Label.Visible = "on";
            elseif value == "Line"

                app.MorphOpt1Label.Text = "Length";
                app.MorphOpt1.Visible = "on";
                app.MorphOpt1Label.Visible = "on";

                app.MorphOpt2Label.Text = "Degree";
                app.MorphOpt2.Visible = "on";
                app.MorphOpt2Label.Visible = "on";
            elseif value == "Rectangle"   

                app.MorphOpt2Label.Text = "Width";
                app.MorphOpt2.Visible = "on";
                app.MorphOpt2Label.Visible = "on";

                app.MorphOpt1Label.Text = "Height";
                app.MorphOpt1.Visible = "on";
                app.MorphOpt1Label.Visible = "on";
            elseif value == "Select..."
                app.MorphOpt2.Visible = "off";
                app.MorphOpt2Label.Visible = "off";
                app.MorphOpt1.Visible = "off";
                app.MorphOpt1Label.Visible = "off";
                
            end
            if value ~= "Select..."
                app.MorphOptions = [{app.MorphOpt1.Value} {app.MorphOpt2.Value} {lower(value)}];
                app.DilateButton.Enable = "on";
                app.ErodeButton.Enable = "on";
                app.OpenButton.Enable = "on";
                app.CloseButton.Enable = "on";
            end
            if value == "Select..."
                app.MorphOpt2.Visible = "off";
                app.MorphOpt2Label.Visible = "off";
                app.MorphOpt1.Visible = "off";
                app.MorphOpt1Label.Visible = "off";
                
                app.DilateButton.Enable = "off";
                app.ErodeButton.Enable = "off";
                app.OpenButton.Enable = "off";
                app.CloseButton.Enable = "off";
            end
        end

        % Button pushed function: SaveOutputButton
        function SaveOutputButtonPushed(app, event)
            app.ffvid = processVideo(app.ffvid,app.processSequence);
            app.vid = app.ffvid;
            fileName = char(app.rawVidFilePath);
            app.ZooplanktonAppUIFigure.WindowStyle = "normal";
            fileName = inputdlg(strcat("Save video as ", strcat(fileName(1:end-4),"pre_processed.avi"),"?"),strcat(app.rawVidFilePath,"pre_processed.avi"));
            app.ZooplanktonAppUIFigure.WindowStyle = "alwaysontop";
            if isempty(fileName)
                tic;
                fileName = char(app.rawVidFilePath);
                write_avi(strcat(fileName(1:end-4),"pre_processed.avi"),app.vid)
                tEnd = toc;
            else
                tic;
                write_avi(strcat(fileName,"pre_processed.avi"),app.vid)
                tEnd = toc;
            end
            app.VideoSourceDropDown.Items = cat(2,app.VideoSourceDropDown.Items,{'Generated PrecProcessed Video'});
            app.TextArea.Value = cat(1,app.TextArea.Value,{['Wrote Video to File in',' ',char(num2str(tEnd,'%.3f')),' seconds']});
            app.ContinuetoSegmentationButton.Visible = "on";
        end

        % Button pushed function: SharpenButton
        function SharpenButtonPushed(app, event)
            app.previousImage = app.sampleImage;
            if app.DefaultCheckBox.Value == false
            app.processSequence = cat(1,app.processSequence,[{'S'},{app.SharpRadSpinner.Value},{app.SharpAmountSpinner.Value},{app.SharpThresholdSpinner.Value},{"X"}]);
            app.sampleImage = imsharpen(app.sampleImage,"Radius",app.SharpRadSpinner.Value,"Amount",app.SharpAmountSpinner.Value,"Threshold",app.SharpThresholdSpinner.Value);
            else
            app.processSequence = cat(1,app.processSequence,[{'S'},{'X'},{'X'},{'X'},{"X"}]);
            app.sampleImage = imsharpen(app.sampleImage,"Radius",app.SharpRadSpinner.Value,"Amount",app.SharpAmountSpinner.Value,"Threshold",app.SharpThresholdSpinner.Value);
            
            end
            imshow(app.sampleImage,"Parent",app.Image2)
            app.UndoLastButton.Enable = "on";
        end

        % Button pushed function: UndoLastButton
        function UndoLastButtonPushed(app, event)
            app.sampleImage = app.previousImage;
            imshow(app.sampleImage,"Parent",app.Image2)
            app.processSequence(end,:) = [];
            app.UndoLastButton.Enable = "off";
        end

        % Button pushed function: AdjustButton
        function AdjustButtonPushed(app, event)
            app.previousImage = app.sampleImage;
            if app.DefaultCheckBox_2.Value == false
            app.processSequence = cat(1,app.processSequence,[{'A'},{app.LowIn.Value},{app.HighIn.Value}, ...
                {app.LowOut.Value},{app.HighOut.Value}]);
            app.sampleImage = imadjust(app.sampleImage,[app.LowIn.Value app.HighIn.Value],[app.LowOut.Value app.HighOut.Value]);

            else
            app.processSequence = cat(1,app.processSequence,[{'A'},{'X'},{'X'},{'X'},{"X"}]);
            app.sampleImage = imadjust(app.sampleImage);

            end
            imshow(app.sampleImage,"Parent",app.Image2)
            app.UndoLastButton.Enable = "on";

        end

        % Button pushed function: LaplaceFiltButton
        function LaplaceFiltButtonPushed(app, event)
            app.previousImage = app.sampleImage;
            if app.DefaultCheckBox_3.Value == false
            app.processSequence = cat(1,app.processSequence,[{'L'},{app.Sigma.Value},{app.Alpha.Value},{app.Intensity.Value},{"X"}]);
            app.sampleImage = locallapfilt(app.sampleImage,app.Sigma.Value,app.Alpha.Value,"NumIntensityLevels",app.Intensity.Value);
            else
                % laplace has no default, need at least alpha and sigma
            app.processSequence = cat(1,app.processSequence,[{'L'},{app.Sigma.Value},{app.Alpha.Value},{'X'},{"X"}]);
            app.sampleImage = locallapfilt(app.sampleImage,app.Sigma.Value,app.Alpha.Value);
            end
            imshow(app.sampleImage,"Parent",app.Image2)
            app.UndoLastButton.Enable = "on";
        end

        % Button pushed function: BilatFiltButton
        function BilatFiltButtonPushed(app, event)
            app.previousImage = app.sampleImage;
            if app.DefaultCheckBox_4.Value == false
            app.processSequence = cat(1,app.processSequence,[{'B'},{app.DegreeSmooth.Value},{app.BilatSigma.Value},{"X"},{"X"}]);
            app.sampleImage = imbilatfilt(app.sampleImage,app.DegreeSmooth.Value,app.BilatSigma.Value);
            else
            app.processSequence = cat(1,app.processSequence,[{'B'},{'X'},{'X'},{'X'},{"X"}]);
            app.sampleImage = imbilatfilt(app.sampleImage);
            end
            imshow(app.sampleImage,"Parent",app.Image2)
            app.UndoLastButton.Enable = "on";
        end

        % Callback function
        function ClearAllButtonPushed(app, event)
            answer = questdlg("Are you sure you want to clear? This will erase all unsaved data.","Clear","Yes, I'm Sure","No, Cancel");
            if answer == "Yes, I'm Sure"
            app.sampleImage = app.ffvid(:,:,1);
            app.processSequence = {};
            app.TextArea.Value = cat(1,app.TextArea.Value,{'Cleared all edits.'});

            end
        end

        % Button pushed function: DilateButton
        function DilateButtonPushed(app, event)
            app.previousImage = app.sampleImage;
            app.MorphOptions
            app.processSequence = cat(1,app.processSequence,[{'M'},app.MorphOptions(3),app.MorphOptions(1),app.MorphOptions(2),{@imdilate}]);
            enableString = get(app.MorphOpt2, 'Enable');
            if strcmp(lower(enableString), 'on')
                app.sampleImage = imdilate(app.sampleImage,strel(string(app.MorphOptions(3)),cell2mat(app.MorphOptions(1))));
                imshow(app.sampleImage,"Parent",app.Image2)
            else
                app.sampleImage = imdilate(strel(string(app.MorphOptions(3)),cell2mat(app.MorphOptions(1)),cell2mat(app.MorphOptions(2))),app.sampleImage);
                imshow(app.sampleImage,"Parent",app.Image2)
            end    
        end

        % Button pushed function: ErodeButton
        function ErodeButtonPushed(app, event)
            app.previousImage = app.sampleImage;
            app.processSequence = cat(1,app.processSequence,[{'M'},{app.MorphOptions(3)},{app.MorphOptions(1)},{app.MorphOptions(2)},{@imdilate}]);
            enableString = get(app.MorphOpt2, 'Enable');
            if strcmp(lower(enableString), 'on')
                app.sampleImage = imerode(app.sampleImage,strel(string(app.MorphOptions(3)),cell2mat(app.MorphOptions(1))));
                imshow(app.sampleImage,"Parent",app.Image2)
            else
                app.sampleImage = imerode(app.sampleImage,strel(string(app.MorphOptions(3)),cell2mat(app.MorphOptions(1)),cell2mat(app.MorphOptions(2))));
                imshow(app.sampleImage,"Parent",app.Image2)
            end 
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            app.previousImage = app.sampleImage;
            app.processSequence = cat(1,app.processSequence,[{'M'},{app.MorphOptions(3)},{app.MorphOptions(1)},{app.MorphOptions(2)},{@imdilate}]);
            enableString = get(app.MorphOpt2, 'Enable');
            if strcmp(lower(enableString), 'on')
                app.sampleImage = imopen(app.sampleImage,strel(string(app.MorphOptions(3)),cell2mat(app.MorphOptions(1))));
                imshow(app.sampleImage,"Parent",app.Image2)
            else
                app.sampleImage = imopen(app.sampleImage,strel(string(app.MorphOptions(3)),cell2mat(app.MorphOptions(1)),cell2mat(app.MorphOptions(2))));
                imshow(app.sampleImage,"Parent",app.Image2)
            end 
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            app.previousImage = app.sampleImage;
            app.processSequence = cat(1,app.processSequence,[{'M'},{app.MorphOptions(3)},{app.MorphOptions(1)},{app.MorphOptions(2)},{@imdilate}]);
            enableString = get(app.MorphOpt2, 'Enable');
            if strcmp(lower(enableString), 'on')
                app.sampleImage = imclose(app.sampleImage,strel(string(app.MorphOptions(3)),cell2mat(app.MorphOptions(1))));
                imshow(app.sampleImage,"Parent",app.Image2)
            else
                app.sampleImage = imclose(app.sampleImage,strel(string(app.MorphOptions(3)),cell2mat(app.MorphOptions(1)),cell2mat(app.MorphOptions(2))));
                imshow(app.sampleImage,"Parent",app.Image2)
            end 
        end

        % Button pushed function: BeginTrainingButton
        function BeginTrainingButtonPushed(app, event)
            envDict = dictionary(string(app.EnvironmentDropDown.Items),["cpu","gpu","parallel-cpu","parallel-gpu"]);
            app.ConfusionMatPanel.Visible = "on";
            classNames = categories(app.imds.Labels);
            [l,w]=size(app.imds.preview);
            if app.NetworkArchitecureDropDown.Value == "Adapted ResNet"
                app.net = setupNetworkAdaptedResnet([l,w,1],length(classNames));
            elseif app.NetworkArchitecureDropDown.Value == "Adapted GoogLeNet"
                app.net = setupNetworkGoogLe([l,w,1],length(classNames));
            elseif app.NetworkArchitecureDropDown.Value == "Adapted DarkNet"
                app.net = setupDarkNet([l,w,1],length(classNames));
            end
            if envDict(string(app.EnvironmentDropDown.Value)) == "parallel-cpu"
            poolobj = gcp("nocreate");
            delete(poolobj);
            pool = parpool("Processes");
            end
            [imdsTrain,imdsValidation,imdsTest] = splitEachLabel(app.imds,0.6,0.2,0.2,"randomized");
            app.TrainingOptions = trainingOptions("sgdm", ...
                MaxEpochs=app.MaxEpochsSlider.Value, ...
                ValidationData=imdsValidation, ...
                ValidationFrequency=app.ValidationFrequencySlider.Value, ...
                Plots="none", ...
                Metrics="accuracy", ...
                InitialLearnRate=app.LearningRateSlider.Value, ...                            
                ExecutionEnvironment=envDict(string(app.EnvironmentDropDown.Value)),...
                Verbose=true);
            
            app.net = trainnet(imdsTrain,app.net,"crossentropy",app.TrainingOptions);
            YValidation = imdsTest.Labels;
            if isMATLABReleaseOlderThan("R2023A")
                X = readall(imdsTest);
                Xtrain = reshape(cat(3,X{:}),[l,w,1,length(X)]);
                scores = app.net.predict(dlarray(double(Xtrain),'SSCB'));
                [~,b] = max(scores,[],1);
                YTest = categorical(b);
                accuracy = mean(YValidation == YTest');
            else
                scores = minibatchpredict(app.net,imdsTest);
                classNames = categories(app.imds.Labels);
                YTest = scores2label(scores,classNames);
                accuracy = mean(YValidation == YTest);
            end
            cm = confusionchart(app.ConfusionMatPanel,YTest,YValidation);
            app.CurrentTrainingAccuracyLabel.Text = strcat("Test Accuracy: ", num2str(accuracy*100),"%");
            app.CurrentTrainingAccuracyLabel.Visible = "on";
            answer = uiconfirm(app.ZooplanktonAppUIFigure,sprintf("Model accuracy reached %.2f, save?",accuracy*100),"Save?","Options",{'Yes','No'},"DefaultOption",'Yes');
            if answer == "Yes"
                trainedNet = app.net;
                if exist(strcat("TrainedModel_",string(datetime("today","Format",'ddMMyy')),".mat"))
                    k=1;
                    while exist(strcat("TrainedModel_",string(datetime("today","Format",'ddMMyy')),"(",num2str(k),").mat"))
                        k = k + 1;
                    end
                    save(strcat("TrainedModel_",string(datetime("today","Format",'ddMMyy')),"(",num2str(k),").mat"),"trainedNet")
                else
                    save(strcat("TrainedModel_",string(datetime("today","Format",'ddMMyy')),".mat"),"trainedNet")
                end
            end    

            app.TrainedNetworkDropDown.Items = cat(2,app.TrainedNetworkDropDown.Items,strcat("TrainedModel_",string(datetime("today","Format",'ddMMyy'))));
        end

        % Value changed function: NetworkArchitecureDropDown
        function NetworkArchitecureDropDownValueChanged(app, event)
            value = app.NetworkArchitecureDropDown.Value;
            if value == "Browse Workplace..."
                app.ZooplanktonAppUIFigure.WindowStyle = "normal";
                [file,location] = uigetfile('*.mat');
                app.ZooplanktonAppUIFigure.WindowStyle = "alwaysontop";
                if ~isequal(file,0)
                    variableinfo = who('-file',fullfile(file,location));
                    if length(variableinfo) > 1
                        uialert(app.ZooplanktonAppUIFigure,"Training Network .mat file contains multiple variables, please correct file to only contained trained dlnetwork object.","Invalid File Contents")
                    else
                        load(fullfile(file,location))
                        app.NetworkArchitecureDropDown.Items = {app.NetworkArchitecureDropDown.Items,variableinfo};
                        app.NetArch = variableinfo;
                    end
                end
            end
            if value ~= "Browse Workplace..." & app.TrainingDatasetDropDown.Value ~= "Browse..."
                app.BeginTrainingButton.Enable = "on";
            end    
            app.BeginTrainingButton.Enable = "on";
            app.MaxEpochsSlider.Enable = "on";
            app.MaxEpochsSliderLabel.Enable = "on";
            app.ValidationFrequencySlider.Enable = "on";
        app.ValidationFrequencySliderLabel.Enable = "on";
            app.LearningRateSlider.Enable = "on";
            app.LearningRateSliderLabel.Enable = "on";
            app.EditField_4.Enable = "on";
            app.EditField_5.Enable = "on";
            app.EditField_6.Enable = "on";
            app.EnvironmentDropDown.Enable = "on";
            app.EnvironmentDropDownLabel.Enable ="on";
        end

        % Button pushed function: BrowseButton_2
        function BrowseButton_2Pushed(app, event)
            app.ZooplanktonAppUIFigure.WindowStyle = "normal";
            location = uigetdir('.');
            app.ZooplanktonAppUIFigure.WindowStyle = "alwaysontop";
            if ~isequal(location,0)
                app.imds = imageDatastore(location,"IncludeSubfolders",true,"LabelSource","foldernames");
                if app.TrainedNetworkDropDown.Value ~= "Select..."
                    inputSize = app.net.Layers(1).InputSize;
                    if any(size(app.imds.preview) ~= [inputSize(1),inputSize(2)])

                        answer = uiconfirm(app.ZooplanktonAppUIFigure,sprintf("One or more dimensions are invalid, images must be [%d %d] for chosen network. Auto resize images?",inputSize(1),inputSize(2)),"Invalid Image Size","Options",{'Yes','No'},"DefaultOption","Yes");
                        if answer == "Yes"
                            prepareDataset(location,[inputSize(1),inputSize(2)],"1")
                            app.imds = imageDatastore('./PreparedDataset',"IncludeSubfolders",true,"LabelSource","foldernames");
                            app.BeginClassificationButton.Enable = "on";
                            app.UnsortedImagesEditField.Value = "./PreparedDataset";
                        else
                            uialert(app.ZooplanktonAppUIFigure,"Please choose a new network or manually prepare images in the 'Prepare Dataset' panel.")
                        end
                    else
                        app.UnsortedImagesEditField.Value = location;
                        app.BeginClassificationButton.Enable = "on";
                    end
                end
            end
        end

        % Clicked callback: TrainingDatasetDropDown
        function TrainingDatasetDropDownClicked2(app, event)
            value = app.TrainingDatasetDropDown.Value;
            if value == "Browse..."
                oldItems = app.TrainingDatasetDropDown.Items;
                app.ZooplanktonAppUIFigure.WindowStyle = "normal";
                location = uigetdir;
                app.ZooplanktonAppUIFigure.WindowStyle = "alwaysontop";
                newDataset = location;
                if ~isequal(location,0)
                    app.imds = imageDatastore(location,"IncludeSubfolders",true,"LabelSource","foldernames");
                    trainingSet = cat(2,oldItems,{newDataset});
                    app.TrainingDatasetDropDown.Items = unique(trainingSet);
                    app.TrainingDatasetDropDown.Value = string(newDataset);
                    app.trainingClasses = length(app.imds.Labels);
                    app.NetworkArchitecureDropDown.Enable = "on";
                    app.NetworkArchitecureDropDownLabel.Enable = "on";
                end
            end    
            if value ~= "Browse..."
                app.NetworkArchitecureDropDown.Enable = "on";
                app.NetworkArchitecureDropDownLabel.Enable = "on";

            end 
        end

        % Value changed function: MaxEpochsSlider
        function MaxEpochsSliderValueChanged(app, event)
            value = app.MaxEpochsSlider.Value;
            app.EditField_4.Value = ceil(value);
            app.MaxEpochsSlider.Value = ceil(value);
        end

        % Value changed function: EditField_4
        function EditField_4ValueChanged(app, event)
            value = app.EditField_4.Value;
            app.MaxEpochsSlider.Value = value;
        end

        % Value changed function: ValidationFrequencySlider
        function ValidationFrequencySliderValueChanged(app, event)
            value = app.ValidationFrequencySlider.Value;
            app.EditField_5.Value = ceil(value);
            app.ValidationFrequencySlider.Value = ceil(value);

        end

        % Value changed function: EditField_5
        function EditField_5ValueChanged(app, event)
            value = app.EditField_5.Value;
            app.ValidationFrequencySlider.Value = value;
        end

        % Value changed function: LearningRateSlider
        function LearningRateSliderValueChanged(app, event)
            value = app.LearningRateSlider.Value;
            app.EditField_6.Value = round(value,3);
            app.LearningRateSlider.Value = round(value,3);
        end

        % Value changed function: EditField_6
        function EditField_6ValueChanged(app, event)
            value = app.EditField_6.Value;
            app.LearningRateSlider.Value = value;
        end

        % Value changed function: TrainedNetworkDropDown
        function TrainedNetworkDropDownValueChanged(app, event)
            value = app.TrainedNetworkDropDown.Value;
            if app.TrainedNetworkDropDown.Value == "Adapted ResNet"
                load("adaptedResnet.mat")
                app.net = adaptedResnet;
            elseif value == "Browse..."
                app.ZooplanktonAppUIFigure.WindowStyle = "normal";
                [file,location] = uigetfile('*.mat');
                app.ZooplanktonAppUIFigure.WindowStyle = "alwaysontop";
                if ~isequal(file,0)
                    variableinfo = who('-file',fullfile(location,file));
                    if length(variableinfo) > 1
                        uialert(app.ZooplanktonAppUIFigure,"Training Network .mat file contains multiple variables, please correct file to only contained trained dlnetwork object.","Invalid File Contents")
                    else
                        S = load(fullfile(location,file));
                        C = struct2table(S);
                        app.net = C.(1);
                        app.TrainedNetworkDropDown.Items = cat(2,app.TrainedNetworkDropDown.Items,{char(strcat(variableinfo,'(',file,')'))});
                        app.TrainedNetworkDropDown.Value = strcat(variableinfo,'(',file,')');
                        app.NetArch = variableinfo;
                    end
                end
            end   
            if ~isempty(app.UnsortedImagesEditField.Value)
                inputSize = app.net.Layers(1).InputSize;
                    if any(size(app.imds.preview) ~= [inputSize(1),inputSize(2)])

                        answer = uiconfirm(app.ZooplanktonAppUIFigure,sprintf("One or more dimensions are invalid, images must be [%d %d] for chosen network. Auto resize images?",inputSize(1),inputSize(2)),"Invalid Image Size","Options",{'Yes','No'},"DefaultOption","Yes");
                        if answer == "Yes"
                            prepareDataset(app.UnsortedImagesEditField.Value,[inputSize(1),inputSize(2)],"1")
                            app.imds = imageDatastore('./PreparedDataset',"IncludeSubfolders",true,"LabelSource","foldernames");
                            app.BeginClassificationButton.Enable = "on";
                            app.UnsortedImagesEditField.Value = "./PreparedDataset";
                        else
                            uialert(app.ZooplanktonAppUIFigure,"Please choose a new network or manually prepare images in the 'Prepare Dataset' panel.")
                        end
                    else
                        app.BeginClassificationButton.Enable = "on";
                    end
            end

           
        end

        % Value changed function: ConfidenceThresholdSlider
        function ConfidenceThresholdSliderValueChanged(app, event)
            value = app.ConfidenceThresholdSlider.Value;
            app.EditField_7.Value = round(value,3);
            app.ConfidenceThresholdSlider.Value = round(value,3);
        end

        % Value changed function: EditField_7
        function EditField_7ValueChanged(app, event)
            value = app.EditField_7.Value;
            app.EditField_7.Value = round(value,3);
            app.ConfidenceThresholdSlider.Value = round(value,3);
        end

        % Callback function
        function EditField_7ValueChanged2(app, event)

        end

        % Value changed function: DefaultCheckBox
        function DefaultCheckBoxValueChanged(app, event)
            value = app.DefaultCheckBox.Value;
            if value == false
                app.SharpRadSpinner.Enable = "on";
                app.SharpAmountSpinner.Enable = "on";
                app.SharpThresholdSpinner.Enable = "on";
            else
                app.SharpRadSpinner.Enable = "off";
                app.SharpAmountSpinner.Enable = "off";
                app.SharpThresholdSpinner.Enable = "off";

            end
            
        end

        % Value changed function: DefaultCheckBox_2
        function DefaultCheckBox_2ValueChanged(app, event)
            value = app.DefaultCheckBox_2.Value;
            if value == false
                app.LowIn.Enable = "on";
                app.LowOut.Enable = "on";
                app.HighIn.Enable = "on";
                app.HighOut.Enable = "on";
            else

                app.LowIn.Enable = "off";
                app.LowOut.Enable = "off";
                app.HighIn.Enable = "off";
                app.HighOut.Enable = "off";
            end
        end

        % Value changed function: DefaultCheckBox_3
        function DefaultCheckBox_3ValueChanged(app, event)
            value = app.DefaultCheckBox_3.Value;
            if value == false
                app.Sigma.Enable = "on";
                app.Alpha.Enable = "on";
                app.Intensity.Enable = "on";
            else
                app.Sigma.Enable = "off";
                app.Alpha.Enable = "off";
                app.Intensity.Enable = "off";

            end
        end

        % Value changed function: DefaultCheckBox_4
        function DefaultCheckBox_4ValueChanged(app, event)
            value = app.DefaultCheckBox_4.Value;
            if value == false
                app.DegreeSmooth.Enable = "on";
                app.BilatSigma.Enable = "on";
            else
                app.DegreeSmooth.Enable = "off";
                app.BilatSigma.Enable = "off";
         
            end
        end

        % Button pushed function: PrepareButton
        function PrepareButtonPushed(app, event)
            app.Image5.Visible = "on";
            pause(.5)
            if ~isempty(app.ClassIdentifiersEditField.Value)
                if ~isempty(app.FinalImageSizeEditField.Value)
                    prepareDataset((app.DatasetSourceEditField.Value),extract(app.FinalImageSizeEditField.Value,digitsPattern),app.ClassNames)
                else
                    prepareDataset(app.DatasetSourceEditField.Value,[227,227],app.ClassNames)
                end
            else
                location = dir(app.DatasetSourceEditField.Value);
                subFolderCount = length(location) - 2;
                if ~isempty(app.FinalImageSizeEditField.Value)
                    prepareDataset(app.DatasetSourceEditField.Value,extract(app.FinalImageSizeEditField.Value,digitsPattern),string(1:subFolderCount));
                else
                    prepareDataset(app.DatasetSourceEditField.Value,[227,227],string(1:subFolderCount))
                end
            end
            app.ZooplanktonAppUIFigure.WindowStyle = "normal";
            msgbox("Dataset Prepared Successfully. It can be found in your current workspace in the 'Prepared Dataset' folder.","Operation Complete");
            app.ZooplanktonAppUIFigure.WindowStyle = "alwaysontop";
            app.Image5.Visible = "off";
            app.histAxes2.Visible = "on";
            location = dir(app.DatasetSourceEditField.Value);
            locationStr = app.DatasetSourceEditField.Value;
            classCounts = zeros(1,length(location)-2);
            for k = 3:length(location)
            classCounts(k-2) = length(dir(fullfile(locationStr,string(location(k).name)))) - 2;
            end
            if ~isempty(app.ClassIdentifiersEditField.Value)
                b = bar(app.histAxes2,app.ClassNames,classCounts);
            else
                b = bar(app.histAxes2,1:size(classCounts,2),classCounts);
            end
            % b.("Class ID")
            % Title(b,"Class Distribution")
            xtips2 = b.XEndPoints;
            ytips2 = b.YEndPoints;
            labels2 = string(b.YData);
            text(app.histAxes2,xtips2,ytips2,labels2,'HorizontalAlignment','center',...
                'VerticalAlignment','bottom')
          
        end

        % Button pushed function: ClearAllButton, ClearMemoryButton
        function ClearMemoryButtonPushed(app, event)
            answer = questdlg("Are you sure you want to clear all variables? This will keep your chosen settings but remove all variables from memory, any unsaved work will be lost.","Are you sure?","Continue","Cancel","Cancel");
            if answer == "Continue"
                clearvars -except app app.ZooplanktonAppUIFigure
            end
        end

        % Value changed function: MorphOpt1
        function MorphOpt1ValueChanged(app, event)
            value = app.MorphOpt1.Value;
            app.MorphOptions = [{value} {app.MorphOpt2.Value} {lower(app.StructureDropDown.Value)}];
        end

        % Value changed function: MorphOpt2
        function MorphOpt2ValueChanged(app, event)
            value = app.MorphOpt2.Value;
            app.MorphOptions = [{app.MorphOpt1.Value} {value} {lower(app.StructureDropDown.Value)}];
        end

        % Button pushed function: ContinuetoSegmentationButton
        function ContinuetoSegmentationButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.SegmentationTab;
            app.VideoSourceDropDown.Items = cat(2,app.VideoSourceDropDown.Items,{app.rawVidFilePath});
            app.VideoSourceDropDown.Value = app.rawVidFilePath;
            % app.LoadingImage2.ImageSource = cat(3,app.vid(:,:,1),app.vid(:,:,1),app.vid(:,:,1));
            imshow(app.vid(:,:,1),"Parent",app.RawImg)
            set(app.RawImg,'Xtick',[])
            set(app.RawImg,'Ytick',[])
            set(app.RawImg,'Xlim',[0,size(app.vid,2)])
            set(app.RawImg,'Ylim',[0,size(app.vid,1)])
            app.ProcessBinaryVideoButton.Enable = "on";
            app.RawImg.Visible = "on";
            app.StartEndSlider.Limits = [1 size(app.vid,3)];
            app.EditField_2.Value = size(app.vid,3);
            app.StartEndSlider.Value(2) = size(app.vid,3);
            app.Button.Visible = "on";
            app.Button_2.Visible = "on";
        end

        % Button pushed function: BrowseButton_4
        function BrowseButton_4Pushed(app, event)
            app.ZooplanktonAppUIFigure.WindowStyle = "normal";
            location = uigetdir('.');
            app.ZooplanktonAppUIFigure.WindowStyle = "alwaysontop";
            if ~isequal(location,0)
                app.DatasetSourceEditField.Value = location;
            end
        end

        % Value changed function: DatasetSourceEditField
        function DatasetSourceEditFieldValueChanged(app, event)
            value = app.DatasetSourceEditField.Value;
            if exist(value)
            strs = strsplit(value,'/');
            app.rawImds = strs(end);
            elseif ~isempty(value)
                app.ZooplanktonAppUIFigure.WindowStyle = "normal";
                uialert(app.ZooplanktonAppUIFigure,"File not found, please provide valid path.","Invalid File Path")
                app.ZooplanktonAppUIFigure.WindowStyle = "alwaysontop";
            end  
        end

        % Value changed function: ClassIdentifiersEditField
        function ClassIdentifiersEditFieldValueChanged(app, event)
            value = app.ClassIdentifiersEditField.Value;
            if contains(value,"[") & contains(value,",") & contains(value,"]")
                str1 = extractBetween(value,"[",",");
                str2 = extractBetween(value,",","]");
                string(str1)
                strsplit(string(str2),",")
                app.ClassNames = [string(str1),strsplit(string(str2),",")];
                app.ZooplanktonAppUIFigure.WindowStyle = "normal";
                answer = questdlg(strcat(sprintf("ClassNames read in as:"),sprintf(" %s,",app.ClassNames)," Is this correct?"),'Correct Identifiers?','Yes','No','Yes');
                app.ZooplanktonAppUIFigure.WindowStyle = "alwaysontop";
            elseif ~isempty(value)
                uialert(app.ZooplanktonAppUIFigure,"Classnames are in an unknown format, please ensure they are in the form '[Var1,Var2,Var3,...]'.","Invalid Format")
            end
        end

        % Value changed function: FinalImageSizeEditField
        function FinalImageSizeEditFieldValueChanged(app, event)
            value = app.FinalImageSizeEditField.Value;
            strs = extract(value,digitsPattern);
            if ~isempty(strs)
                app.ZooplanktonAppUIFigure.WindowStyle = "normal";
                answer = questdlg(strcat(sprintf("Image size read in as:"),sprintf(" %s,",string(strs))," Is this correct?"),'Correct Image Size?','Yes','No','Yes');
                app.ZooplanktonAppUIFigure.WindowStyle = "alwaysontop";
            end
        end

        % Callback function
        function BeginFlatFieldButtonPushed2(app, event)
            
        end

        % Value changed function: VideoSourceEditField_2
        function VideoSourceEditField_2ValueChanged2(app, event)
            value = app.VideoSourceEditField_2.Value;
            if exist(value)
            app.Image2.Visible = 'on';
            
            app.rawVid = read_avi(value);
            app.Image2.ImageSource = cat(3,app.rawVid(:,:,1),app.rawVid(:,:,1),app.rawVid(:,:,1));
            app.GenerateCalibrationFrameButton.Enable = "on";
            strs = strsplit(value,'/');
            app.rawVidFilePath = strs(end);
            elseif ~isempty(value)
                uialert(app.ZooplanktonAppUIFigure,"File not found, please provide valid path.","Invalid File Path")
            end    
        end

        % Value changed function: SavesmallimagesCheckBox
        function SavesmallimagesCheckBoxValueChanged(app, event)

        end

        % Value changed function: UnsortedImagesEditField
        function UnsortedImagesEditFieldValueChanged(app, event)
        value = app.UnsortedImagesEditField.Value;
            if exist(value)
                app.imds = imageDatastore(location,"IncludeSubfolders",true,"LabelSource","foldernames");
                if app.TrainedNetworkDropDown.Value ~= "Select..."
                    inputSize = app.net.Layers(1).InputSize;
                    if any(size(app.imds.preview) ~= [inputSize(1),inputSize(2)])

                        answer = uiconfirm(app.ZooplanktonAppUIFigure,sprintf("One or more dimensions are invalid, images must be [%d %d] for chosen network. Auto resize images?",inputSize(1),inputSize(2)),"Invalid Image Size","Options",{'Yes','No'},"DefaultOption","Yes");
                        if answer == "Yes"
                            prepareDataset(value,[inputSize(1),inputSize(2)],"1")
                            app.imds = imageDatastore('./PreparedDataset',"IncludeSubfolders",true,"LabelSource","foldernames");
                            app.BeginClassificationButton.Enable = "on";
                            app.UnsortedImagesEditField.Value = "./PreparedDataset";
                        else
                            uialert(app.ZooplanktonAppUIFigure,"Please choose a new network or manually prepare images in the 'Prepare Dataset' panel.")
                        end
                    else
                        app.BeginClassificationButton.Enable = "on";
                    end
                end
            elseif ~isempty(value)
                uialert(app.ZooplanktonAppUIFigure,"File not found, please provide valid path.","Invalid File Path")
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.ZooplanktonAppUIFigure = uifigure('Visible', 'off');
            app.ZooplanktonAppUIFigure.Name = 'Zooplankton App';
            app.TabGroup = uitabgroup(app.ZooplanktonAppUIFigure);
            app.PreprocessingTab = uitab(app.TabGroup);
            app.PreprocessingTab.Title = 'Preprocessing';
            app.GridLayout2 = uigridlayout(app.PreprocessingTab);
            app.Image2 = uiaxes(app.GridLayout2);
            zlabel(app.Image2, 'Z')
            app.Image2.Visible = 'off';
            app.FlatFieldPanel = uipanel(app.GridLayout2);
            app.FlatFieldPanel.Title = 'Flat-Field';
            app.GridLayout7 = uigridlayout(app.FlatFieldPanel);
            app.FlatFieldingMethodDropDownLabel = uilabel(app.GridLayout7);
            app.FlatFieldingMethodDropDownLabel.Enable = 'off';
            app.FlatFieldingMethodDropDownLabel.Text = 'Flat-Fielding Method';
            app.FlatFieldingMethodDropDown = uidropdown(app.GridLayout7);
            app.FlatFieldingMethodDropDown.Items = {'Standard', 'Dynamic', 'Precise'};
            app.FlatFieldingMethodDropDown.Enable = 'off';
            app.FlatFieldingMethodDropDown.Value = 'Standard';
            app.BrightnessSliderLabel = uilabel(app.GridLayout7);
            app.BrightnessSliderLabel.Enable = 'off';
            app.BrightnessSliderLabel.Text = 'Brightness';
            app.BrightnessSlider = uislider(app.GridLayout7);
            app.BrightnessSlider.Limits = [0 2];
            app.BrightnessSlider.Enable = 'off';
            app.BrightnessSlider.Value = 1;
            app.EditField_3 = uieditfield(app.GridLayout7, 'numeric');
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.EditField_3.Enable = 'off';
            app.EditField_3.Value = 1;
            app.GenerateCalibrationFrameButton = uibutton(app.GridLayout7, 'push');
            app.GenerateCalibrationFrameButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateCalibrationFrameButtonPushed, true);
            app.GenerateCalibrationFrameButton.Enable = 'off';
            app.GenerateCalibrationFrameButton.Text = 'Generate Calibration Frame';
            app.VideoSourceEditField_2Label = uilabel(app.GridLayout7);
            app.VideoSourceEditField_2Label.Text = 'Video Source';
            app.VideoSourceEditField_2 = uieditfield(app.GridLayout7, 'text');
            app.VideoSourceEditField_2.ValueChangedFcn = createCallbackFcn(app, @VideoSourceEditField_2ValueChanged2, true);
            app.BrowseButton_3 = uibutton(app.GridLayout7, 'push');
            app.BrowseButton_3.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_3Pushed, true);
            app.BrowseButton_3.Text = 'Browse...';
            app.BeginFlatFieldButton = uibutton(app.GridLayout7, 'push');
            app.BeginFlatFieldButton.ButtonPushedFcn = createCallbackFcn(app, @BeginFlatFieldButtonPushed, true);
            app.BeginFlatFieldButton.Enable = 'off';
            app.BeginFlatFieldButton.Text = 'Begin Flat Field';
            app.ContrastToolsPanel = uipanel(app.GridLayout2);
            app.ContrastToolsPanel.Tooltip = {'Tools to Adjust Contrast'};
            app.ContrastToolsPanel.Enable = 'off';
            app.ContrastToolsPanel.Title = 'Contrast Tools';
            app.GridLayout9 = uigridlayout(app.ContrastToolsPanel);
            app.SharpenLabel = uilabel(app.GridLayout9);
            app.SharpenLabel.Text = 'Sharpen';
            app.SharpRadSpinner = uieditfield(app.GridLayout9, 'numeric');
            app.SharpRadSpinner.Enable = 'off';
            app.SharpRadSpinner.Value = 1;
            app.SharpAmountSpinner = uieditfield(app.GridLayout9, 'numeric');
            app.SharpAmountSpinner.Enable = 'off';
            app.SharpAmountSpinner.Value = 0.8;
            app.SharpThresholdSpinner = uieditfield(app.GridLayout9, 'numeric');
            app.SharpThresholdSpinner.Enable = 'off';
            app.RadiusLabel = uilabel(app.GridLayout9);
            app.RadiusLabel.Text = 'Radius';
            app.AmountLabel = uilabel(app.GridLayout9);
            app.AmountLabel.Text = 'Amount';
            app.ThresholdLabel = uilabel(app.GridLayout9);
            app.ThresholdLabel.Text = 'Threshold';
            app.SharpenButton = uibutton(app.GridLayout9, 'push');
            app.SharpenButton.ButtonPushedFcn = createCallbackFcn(app, @SharpenButtonPushed, true);
            app.SharpenButton.Text = 'Apply';
            app.AdjustLabel = uilabel(app.GridLayout9);
            app.AdjustLabel.Text = 'Adjust';
            app.LowHighInLabel = uilabel(app.GridLayout9);
            app.LowHighInLabel.Text = 'Low/High In';
            app.LowHighOutLabel = uilabel(app.GridLayout9);
            app.LowHighOutLabel.Text = 'Low/High Out';
            app.AdjustButton = uibutton(app.GridLayout9, 'push');
            app.AdjustButton.ButtonPushedFcn = createCallbackFcn(app, @AdjustButtonPushed, true);
            app.AdjustButton.Text = 'Apply';
            app.LaplaceFilterLabel = uilabel(app.GridLayout9);
            app.LaplaceFilterLabel.Tooltip = {'Sigma: [0,1],  Amplitude of edges'; 'Alpha: <1) Increases detail, =1) Unchanged, >1) Smoothes detail'};
            app.LaplaceFilterLabel.Text = 'Laplace Filter';
            app.Sigma = uieditfield(app.GridLayout9, 'numeric');
            app.Sigma.Value = 1;
            app.Alpha = uieditfield(app.GridLayout9, 'numeric');
            app.Alpha.Value = 1;
            app.LaplaceFiltButton = uibutton(app.GridLayout9, 'push');
            app.LaplaceFiltButton.ButtonPushedFcn = createCallbackFcn(app, @LaplaceFiltButtonPushed, true);
            app.LaplaceFiltButton.Text = 'Apply';
            app.BilateralFilterLabel = uilabel(app.GridLayout9);
            app.BilateralFilterLabel.Text = 'Bilateral Filter';
            app.BilatSigma = uieditfield(app.GridLayout9, 'numeric');
            app.BilatSigma.Enable = 'off';
            app.BilatSigma.Value = 1;
            app.DoSLabel = uilabel(app.GridLayout9);
            app.DoSLabel.Text = 'DoS';
            app.SigmaLabel = uilabel(app.GridLayout9);
            app.SigmaLabel.Text = 'Sigma';
            app.BilatFiltButton = uibutton(app.GridLayout9, 'push');
            app.BilatFiltButton.ButtonPushedFcn = createCallbackFcn(app, @BilatFiltButtonPushed, true);
            app.BilatFiltButton.Text = 'Apply';
            app.SigmaLabel_2 = uilabel(app.GridLayout9);
            app.SigmaLabel_2.Text = 'Sigma';
            app.AlphaLabel = uilabel(app.GridLayout9);
            app.AlphaLabel.Text = 'Alpha';
            app.DefaultCheckBox_2 = uicheckbox(app.GridLayout9);
            app.DefaultCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @DefaultCheckBox_2ValueChanged, true);
            app.DefaultCheckBox_2.Text = 'Default';
            app.DefaultCheckBox_2.Value = true;
            app.IntensityLevelsLabel = uilabel(app.GridLayout9);
            app.IntensityLevelsLabel.Text = 'Intensity Levels';
            app.DefaultCheckBox = uicheckbox(app.GridLayout9);
            app.DefaultCheckBox.ValueChangedFcn = createCallbackFcn(app, @DefaultCheckBoxValueChanged, true);
            app.DefaultCheckBox.Text = 'Default';
            app.DefaultCheckBox.Value = true;
            app.DefaultCheckBox_3 = uicheckbox(app.GridLayout9);
            app.DefaultCheckBox_3.ValueChangedFcn = createCallbackFcn(app, @DefaultCheckBox_3ValueChanged, true);
            app.DefaultCheckBox_3.Text = 'Default';
            app.DefaultCheckBox_3.Value = true;
            app.DefaultCheckBox_4 = uicheckbox(app.GridLayout9);
            app.DefaultCheckBox_4.ValueChangedFcn = createCallbackFcn(app, @DefaultCheckBox_4ValueChanged, true);
            app.DefaultCheckBox_4.Text = 'Default';
            app.DefaultCheckBox_4.Value = true;
            app.Intensity = uieditfield(app.GridLayout9, 'text');
            app.Intensity.InputType = 'alphanumerics';
            app.Intensity.Enable = 'off';
            app.Intensity.Placeholder = 'auto';
            app.LowOut = uieditfield(app.GridLayout9, 'text');
            app.LowOut.Enable = 'off';
            app.LowOut.Placeholder = 'auto';
            app.HighOut = uieditfield(app.GridLayout9, 'text');
            app.HighOut.Enable = 'off';
            app.HighOut.Placeholder = 'auto';
            app.LowIn = uieditfield(app.GridLayout9, 'text');
            app.LowIn.Enable = 'off';
            app.LowIn.Placeholder = 'auto';
            app.HighIn = uieditfield(app.GridLayout9, 'text');
            app.HighIn.Enable = 'off';
            app.HighIn.Placeholder = 'auto';
            app.DegreeSmooth = uieditfield(app.GridLayout9, 'text');
            app.DegreeSmooth.Enable = 'off';
            app.DegreeSmooth.Placeholder = 'auto';
            app.MorphologicalOperationsPanel = uipanel(app.GridLayout2);
            app.MorphologicalOperationsPanel.Enable = 'off';
            app.MorphologicalOperationsPanel.Title = 'Morphological Operations';
            app.GridLayout10 = uigridlayout(app.MorphologicalOperationsPanel);
            app.StructureDropDownLabel = uilabel(app.GridLayout10);
            app.StructureDropDownLabel.Text = 'Structure';
            app.StructureDropDown = uidropdown(app.GridLayout10);
            app.StructureDropDown.Items = {'Select...', 'Diamond', 'Disk', 'Octagon', 'Line', 'Rectangle'};
            app.StructureDropDown.ValueChangedFcn = createCallbackFcn(app, @StructureDropDownValueChanged, true);
            app.StructureDropDown.Value = 'Select...';
            app.MorphOpt1 = uispinner(app.GridLayout10);
            app.MorphOpt1.ValueChangedFcn = createCallbackFcn(app, @MorphOpt1ValueChanged, true);
            app.MorphOpt1.Visible = 'off';
            app.MorphOpt2 = uispinner(app.GridLayout10);
            app.MorphOpt2.ValueChangedFcn = createCallbackFcn(app, @MorphOpt2ValueChanged, true);
            app.MorphOpt2.Visible = 'off';
            app.DilateButton = uibutton(app.GridLayout10, 'push');
            app.DilateButton.ButtonPushedFcn = createCallbackFcn(app, @DilateButtonPushed, true);
            app.DilateButton.Enable = 'off';
            app.DilateButton.Text = 'Dilate';
            app.ErodeButton = uibutton(app.GridLayout10, 'push');
            app.ErodeButton.ButtonPushedFcn = createCallbackFcn(app, @ErodeButtonPushed, true);
            app.ErodeButton.Enable = 'off';
            app.ErodeButton.Text = 'Erode';
            app.OpenButton = uibutton(app.GridLayout10, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Enable = 'off';
            app.OpenButton.Text = 'Open';
            app.CloseButton = uibutton(app.GridLayout10, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Enable = 'off';
            app.CloseButton.Text = 'Close';
            app.MorphOpt1Label = uilabel(app.GridLayout10);
            app.MorphOpt1Label.Visible = 'off';
            app.MorphOpt1Label.Text = 'MorphOpt1';
            app.MorphOpt2Label = uilabel(app.GridLayout10);
            app.MorphOpt2Label.Visible = 'off';
            app.MorphOpt2Label.Text = 'MorphOpt2';
            app.Panel = uipanel(app.GridLayout2);
            app.GridLayout8 = uigridlayout(app.Panel);
            app.TextArea = uitextarea(app.GridLayout8);
            app.TextArea.Editable = 'off';
            app.SaveOutputButton = uibutton(app.GridLayout8, 'push');
            app.SaveOutputButton.ButtonPushedFcn = createCallbackFcn(app, @SaveOutputButtonPushed, true);
            app.SaveOutputButton.Enable = 'off';
            app.SaveOutputButton.Text = 'Save Output';
            app.UndoLastButton = uibutton(app.GridLayout8, 'push');
            app.UndoLastButton.ButtonPushedFcn = createCallbackFcn(app, @UndoLastButtonPushed, true);
            app.UndoLastButton.Enable = 'off';
            app.UndoLastButton.Text = 'Undo Last';
            app.ClearAllButton = uibutton(app.GridLayout8, 'push');
            app.ClearAllButton.ButtonPushedFcn = createCallbackFcn(app, @ClearMemoryButtonPushed, true);
            app.ClearAllButton.Enable = 'off';
            app.ClearAllButton.Text = 'Clear All';
            app.ContinuetoSegmentationButton = uibutton(app.GridLayout8, 'push');
            app.ContinuetoSegmentationButton.ButtonPushedFcn = createCallbackFcn(app, @ContinuetoSegmentationButtonPushed, true);
            app.ContinuetoSegmentationButton.Visible = 'off';
            app.ContinuetoSegmentationButton.Text = 'Continue to Segmentation';
            app.LoadingImage = uiimage(app.GridLayout2);
            app.LoadingImage.Visible = 'off';
            app.LoadingImage.ImageSource = fullfile(pathToMLAPP, 'Wedges@1x-4.0s-200px-200px(1).gif');
            app.SegmentationTab = uitab(app.TabGroup);
            app.SegmentationTab.Title = 'Segmentation';
            app.GridLayout11 = uigridlayout(app.SegmentationTab);
            app.BWimg = uiaxes(app.GridLayout11);
            app.BWimg.Visible = 'off';
            app.RawImg = uiaxes(app.GridLayout11);
            app.RawImg.Visible = 'off';
            app.LoadingImage2 = uiimage(app.GridLayout11);
            app.LoadingImage2.Visible = 'off';
            app.LoadingImage2.ImageSource = fullfile(pathToMLAPP, 'Wedges@1x-4.0s-200px-200px(1).gif');
            app.BrowseButton = uibutton(app.GridLayout11, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse...';
            app.SegmentationParametersPanel = uipanel(app.GridLayout11);
            app.SegmentationParametersPanel.Title = 'Segmentation Parameters';
            app.GridLayout14 = uigridlayout(app.SegmentationParametersPanel);
            app.ProcessBinaryVideoButton = uibutton(app.GridLayout14, 'push');
            app.ProcessBinaryVideoButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessBinaryVideoButtonPushed, true);
            app.ProcessBinaryVideoButton.Enable = 'off';
            app.ProcessBinaryVideoButton.Text = 'Process Binary Video';
            app.BeginSegmentationButton = uibutton(app.GridLayout14, 'push');
            app.BeginSegmentationButton.ButtonPushedFcn = createCallbackFcn(app, @BeginSegmentationButtonPushed, true);
            app.BeginSegmentationButton.Enable = 'off';
            app.BeginSegmentationButton.Text = 'Begin Segmentation';
            app.SettoReccomendedButton = uibutton(app.GridLayout14, 'push');
            app.SettoReccomendedButton.ButtonPushedFcn = createCallbackFcn(app, @SettoReccomendedButtonPushed, true);
            app.SettoReccomendedButton.Text = 'Set to Reccomended';
            app.ContinuetoClassificationButton = uibutton(app.GridLayout14, 'push');
            app.ContinuetoClassificationButton.ButtonPushedFcn = createCallbackFcn(app, @ContinuetoClassificationButtonPushed, true);
            app.ContinuetoClassificationButton.Enable = 'off';
            app.ContinuetoClassificationButton.Text = 'Continue to Classification';
            app.EditField = uieditfield(app.GridLayout14, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Enable = 'off';
            app.EditField.Value = 1;
            app.EditField_2 = uieditfield(app.GridLayout14, 'numeric');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_2.Enable = 'off';
            app.EditField_2.Value = 100;
            app.ExpansionDistanceSliderLabel = uilabel(app.GridLayout14);
            app.ExpansionDistanceSliderLabel.Text = 'Expansion Distance';
            app.ExpansionDistanceSlider = uislider(app.GridLayout14);
            app.ExpansionDistanceSlider.Limits = [0 10];
            app.MinAreaSliderLabel = uilabel(app.GridLayout14);
            app.MinAreaSliderLabel.Text = 'Min Area';
            app.MinAreaSlider = uislider(app.GridLayout14);
            app.MinAreaSlider.Limits = [0 30];
            app.MaxFrequencySliderLabel = uilabel(app.GridLayout14);
            app.MaxFrequencySliderLabel.Text = 'Max Frequency';
            app.MaxFrequencySlider = uislider(app.GridLayout14);
            app.MaxFrequencySlider.Limits = [0 1];
            app.CropPaddingSliderLabel = uilabel(app.GridLayout14);
            app.CropPaddingSliderLabel.Text = 'Crop Padding';
            app.CropPaddingSlider = uislider(app.GridLayout14);
            app.CropPaddingSlider.Limits = [0 50];
            app.DepthFramesSliderLabel = uilabel(app.GridLayout14);
            app.DepthFramesSliderLabel.Text = 'Depth Frames';
            app.DepthFramesSlider = uislider(app.GridLayout14);
            app.DepthFramesSlider.Limits = [0 10];
            app.BinaryThresholdSliderLabel = uilabel(app.GridLayout14);
            app.BinaryThresholdSliderLabel.Text = 'Binary Threshold';
            app.BinaryThresholdSlider = uislider(app.GridLayout14);
            app.BinaryThresholdSlider.Limits = [0 1];
            app.StartEndSliderLabel = uilabel(app.GridLayout14);
            app.StartEndSliderLabel.Enable = 'off';
            app.StartEndSliderLabel.Text = 'Start/End';
            app.StartEndSlider = uislider(app.GridLayout14, 'range');
            app.StartEndSlider.Limits = [1 100];
            app.StartEndSlider.ValueChangedFcn = createCallbackFcn(app, @StartEndSliderValueChanged, true);
            app.StartEndSlider.Enable = 'off';
            app.StartEndSlider.Value = [1 100];
            app.EditField_8 = uieditfield(app.GridLayout14, 'text');
            app.EditField_8.Enable = 'off';
            app.EditField_8.Tooltip = {'Defaults to 400px if blank'};
            app.EditField_8.Placeholder = 'Min Length';
            app.EditField_9 = uieditfield(app.GridLayout14, 'text');
            app.EditField_9.Enable = 'off';
            app.EditField_9.Tooltip = {'Defaults to 400px if blank'};
            app.EditField_9.Placeholder = 'Min Width';
            app.SavesmallimagesCheckBox = uicheckbox(app.GridLayout14);
            app.SavesmallimagesCheckBox.ValueChangedFcn = createCallbackFcn(app, @SavesmallimagesCheckBoxValueChanged, true);
            app.SavesmallimagesCheckBox.Enable = 'off';
            app.SavesmallimagesCheckBox.Text = 'Save small images?';
            app.Button_2 = uibutton(app.GridLayout11, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Visible = 'off';
            app.Button_2.Text = '>';
            app.Button = uibutton(app.GridLayout11, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Enable = 'off';
            app.Button.Visible = 'off';
            app.Button.Text = '<';
            app.LoadingImage3 = uiimage(app.GridLayout11);
            app.LoadingImage3.Visible = 'off';
            app.LoadingImage3.ImageSource = fullfile(pathToMLAPP, 'Wedges@1x-4.0s-200px-200px(1).gif');
            app.Button_3 = uibutton(app.GridLayout11, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Enable = 'off';
            app.Button_3.Visible = 'off';
            app.Button_3.Text = '<';
            app.Button_4 = uibutton(app.GridLayout11, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Visible = 'off';
            app.Button_4.Text = '>';
            app.Frame1Label = uilabel(app.GridLayout11);
            app.Frame1Label.Visible = 'off';
            app.Frame1Label.Text = 'Frame 1';
            app.Label2 = uilabel(app.GridLayout11);
            app.Label2.Visible = 'off';
            app.Label2.Text = 'Label2';
            app.VideoSourceDropDownLabel = uilabel(app.GridLayout11);
            app.VideoSourceDropDownLabel.Text = 'Video Source';
            app.VideoSourceDropDown = uidropdown(app.GridLayout11);
            app.VideoSourceDropDown.Items = {'Select...', 'Browse...'};
            app.VideoSourceDropDown.ValueChangedFcn = createCallbackFcn(app, @VideoSourceDropDownValueChanged, true);
            app.VideoSourceDropDown.Value = 'Select...';
            app.ClassificationTab = uitab(app.TabGroup);
            app.ClassificationTab.Title = 'Classification';
            app.GridLayout3 = uigridlayout(app.ClassificationTab);
            app.TrainNewNetworkPanel = uipanel(app.GridLayout3);
            app.TrainNewNetworkPanel.Title = 'Train New Network';
            app.GridLayout4 = uigridlayout(app.TrainNewNetworkPanel);
            app.CurrentTrainingAccuracyLabel = uilabel(app.GridLayout4);
            app.CurrentTrainingAccuracyLabel.Visible = 'off';
            app.CurrentTrainingAccuracyLabel.Text = 'Current Training Accuracy';
            app.TrainingDatasetDropDownLabel = uilabel(app.GridLayout4);
            app.TrainingDatasetDropDownLabel.Tooltip = {'Comes with pretrained dataset of XXX images across XX classes. Otherwise, Browse for labeled datasets.'};
            app.TrainingDatasetDropDownLabel.Text = 'Training Dataset';
            app.TrainingDatasetDropDown = uidropdown(app.GridLayout4);
            app.TrainingDatasetDropDown.Items = {'Browse...'};
            app.TrainingDatasetDropDown.Tooltip = {'Comes with pretrained dataset of XXX images across XX classes. Otherwise, Browse for labeled datasets.'};
            app.TrainingDatasetDropDown.ClickedFcn = createCallbackFcn(app, @TrainingDatasetDropDownClicked2, true);
            app.TrainingDatasetDropDown.Value = 'Browse...';
            app.NetworkArchitecureDropDownLabel = uilabel(app.GridLayout4);
            app.NetworkArchitecureDropDownLabel.Enable = 'off';
            app.NetworkArchitecureDropDownLabel.Tooltip = {'Comes with pre-built untrained network, requires dataset of size [227,227,1] images. Otherwise, Browse for .mat files containing only the untrained architecure. '};
            app.NetworkArchitecureDropDownLabel.Text = 'Network Architecure';
            app.NetworkArchitecureDropDown = uidropdown(app.GridLayout4);
            app.NetworkArchitecureDropDown.Items = {'None Selected...', 'Adapted GoogLeNet', 'Adapted ResNet', 'Adapted DarkNet'};
            app.NetworkArchitecureDropDown.ValueChangedFcn = createCallbackFcn(app, @NetworkArchitecureDropDownValueChanged, true);
            app.NetworkArchitecureDropDown.Enable = 'off';
            app.NetworkArchitecureDropDown.Tooltip = {'Comes with pre-built untrained network, requires dataset of size [227,227,1] images. Otherwise, Browse for .mat files containing only the untrained architecure. '};
            app.NetworkArchitecureDropDown.Value = 'None Selected...';
            app.MaxEpochsSliderLabel = uilabel(app.GridLayout4);
            app.MaxEpochsSliderLabel.Enable = 'off';
            app.MaxEpochsSliderLabel.Text = 'Max Epochs';
            app.MaxEpochsSlider = uislider(app.GridLayout4);
            app.MaxEpochsSlider.ValueChangedFcn = createCallbackFcn(app, @MaxEpochsSliderValueChanged, true);
            app.MaxEpochsSlider.Enable = 'off';
            app.MaxEpochsSlider.Value = 1;
            app.ValidationFrequencySliderLabel = uilabel(app.GridLayout4);
            app.ValidationFrequencySliderLabel.Enable = 'off';
            app.ValidationFrequencySliderLabel.Text = 'Validation Frequency';
            app.ValidationFrequencySlider = uislider(app.GridLayout4);
            app.ValidationFrequencySlider.Limits = [0 50];
            app.ValidationFrequencySlider.ValueChangedFcn = createCallbackFcn(app, @ValidationFrequencySliderValueChanged, true);
            app.ValidationFrequencySlider.Enable = 'off';
            app.LearningRateSliderLabel = uilabel(app.GridLayout4);
            app.LearningRateSliderLabel.Enable = 'off';
            app.LearningRateSliderLabel.Text = 'Learning Rate';
            app.LearningRateSlider = uislider(app.GridLayout4);
            app.LearningRateSlider.Limits = [0 1];
            app.LearningRateSlider.ValueChangedFcn = createCallbackFcn(app, @LearningRateSliderValueChanged, true);
            app.LearningRateSlider.Enable = 'off';
            app.BeginTrainingButton = uibutton(app.GridLayout4, 'push');
            app.BeginTrainingButton.ButtonPushedFcn = createCallbackFcn(app, @BeginTrainingButtonPushed, true);
            app.BeginTrainingButton.Enable = 'off';
            app.BeginTrainingButton.Text = 'Begin Training';
            app.EditField_4 = uieditfield(app.GridLayout4, 'numeric');
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @EditField_4ValueChanged, true);
            app.EditField_4.Enable = 'off';
            app.EditField_5 = uieditfield(app.GridLayout4, 'numeric');
            app.EditField_5.ValueChangedFcn = createCallbackFcn(app, @EditField_5ValueChanged, true);
            app.EditField_5.Enable = 'off';
            app.EditField_6 = uieditfield(app.GridLayout4, 'numeric');
            app.EditField_6.ValueChangedFcn = createCallbackFcn(app, @EditField_6ValueChanged, true);
            app.EditField_6.Enable = 'off';
            app.ConfusionMatPanel = uipanel(app.GridLayout4);
            app.EnvironmentDropDownLabel = uilabel(app.GridLayout4);
            app.EnvironmentDropDownLabel.Enable = 'off';
            app.EnvironmentDropDownLabel.Text = 'Environment';
            app.EnvironmentDropDown = uidropdown(app.GridLayout4);
            app.EnvironmentDropDown.Items = {'Single CPU', 'Single GPU', 'Parallel CPU', 'Parallel GPU'};
            app.EnvironmentDropDown.Enable = 'off';
            app.EnvironmentDropDown.Value = 'Single CPU';
            app.Image3 = uiimage(app.GridLayout4);
            app.Image3.Visible = 'off';
            app.Image3.ImageSource = 'Wedges@1x-4.0s-200px-200px(1).gif';
            app.ClassifyZooplanktonImagesPanel = uipanel(app.GridLayout3);
            app.ClassifyZooplanktonImagesPanel.Title = 'Classify Zooplankton Images';
            app.GridLayout5 = uigridlayout(app.ClassifyZooplanktonImagesPanel);
            app.BrowseButton_2 = uibutton(app.GridLayout5, 'push');
            app.BrowseButton_2.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_2Pushed, true);
            app.BrowseButton_2.Text = 'Browse...';
            app.TrainedNetworkDropDown = uidropdown(app.GridLayout5);
            app.TrainedNetworkDropDown.Items = {'Select...', 'Adapted ResNet', 'Browse...'};
            app.TrainedNetworkDropDown.ValueChangedFcn = createCallbackFcn(app, @TrainedNetworkDropDownValueChanged, true);
            app.TrainedNetworkDropDown.Value = 'Select...';
            app.TrainedNetworkDropDownLabel = uilabel(app.GridLayout5);
            app.TrainedNetworkDropDownLabel.Text = 'Trained Network';
            app.UnsortedImagesEditFieldLabel = uilabel(app.GridLayout5);
            app.UnsortedImagesEditFieldLabel.Text = 'Unsorted Images';
            app.UnsortedImagesEditField = uieditfield(app.GridLayout5, 'text');
            app.UnsortedImagesEditField.ValueChangedFcn = createCallbackFcn(app, @UnsortedImagesEditFieldValueChanged, true);
            app.BeginClassificationButton = uibutton(app.GridLayout5, 'push');
            app.BeginClassificationButton.ButtonPushedFcn = createCallbackFcn(app, @BeginClassificationButtonPushed, true);
            app.BeginClassificationButton.Enable = 'off';
            app.BeginClassificationButton.Text = 'Begin Classification';
            app.ConfidenceThresholdSliderLabel = uilabel(app.GridLayout5);
            app.ConfidenceThresholdSliderLabel.Text = 'Confidence Threshold';
            app.ConfidenceThresholdSlider = uislider(app.GridLayout5);
            app.ConfidenceThresholdSlider.Limits = [0 1];
            app.ConfidenceThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @ConfidenceThresholdSliderValueChanged, true);
            app.EditField_7 = uieditfield(app.GridLayout5, 'numeric');
            app.EditField_7.ValueChangedFcn = createCallbackFcn(app, @EditField_7ValueChanged, true);
            app.histPanel = uipanel(app.GridLayout5);
            app.GridLayout12 = uigridlayout(app.histPanel);
            app.histAxis = uiaxes(app.GridLayout12);
            title(app.histAxis, 'Class Distribution')
            xlabel(app.histAxis, 'Class ID')
            zlabel(app.histAxis, 'Z')
            app.histAxis.YTick = [];
            app.histAxis.Visible = 'off';
            app.Image4 = uiimage(app.GridLayout12);
            app.Image4.Visible = 'off';
            app.Image4.ImageSource = 'Wedges@1x-4.0s-200px-200px(1).gif';
            app.PrepareDatasetPanel = uipanel(app.GridLayout3);
            app.PrepareDatasetPanel.Title = 'Prepare Dataset';
            app.GridLayout6 = uigridlayout(app.PrepareDatasetPanel);
            app.ClassIdentifiersEditFieldLabel = uilabel(app.GridLayout6);
            app.ClassIdentifiersEditFieldLabel.Text = 'Class Identifiers';
            app.ClassIdentifiersEditField = uieditfield(app.GridLayout6, 'text');
            app.ClassIdentifiersEditField.ValueChangedFcn = createCallbackFcn(app, @ClassIdentifiersEditFieldValueChanged, true);
            app.ClassIdentifiersEditField.Tooltip = {'For custom Class ID, please use the format: [X,Y,Z,etc...]'};
            app.ClassIdentifiersEditField.Placeholder = 'Default [1,2,3,etc...]';
            app.FinalImageSizeEditFieldLabel = uilabel(app.GridLayout6);
            app.FinalImageSizeEditFieldLabel.Text = 'Final Image Size';
            app.FinalImageSizeEditField = uieditfield(app.GridLayout6, 'text');
            app.FinalImageSizeEditField.ValueChangedFcn = createCallbackFcn(app, @FinalImageSizeEditFieldValueChanged, true);
            app.FinalImageSizeEditField.Placeholder = 'Default [227,227]';
            app.PrepareButton = uibutton(app.GridLayout6, 'push');
            app.PrepareButton.ButtonPushedFcn = createCallbackFcn(app, @PrepareButtonPushed, true);
            app.PrepareButton.Text = 'Prepare';
            app.DatasetSourceEditFieldLabel = uilabel(app.GridLayout6);
            app.DatasetSourceEditFieldLabel.Text = 'Dataset Source';
            app.DatasetSourceEditField = uieditfield(app.GridLayout6, 'text');
            app.DatasetSourceEditField.ValueChangedFcn = createCallbackFcn(app, @DatasetSourceEditFieldValueChanged, true);
            app.BrowseButton_4 = uibutton(app.GridLayout6, 'push');
            app.BrowseButton_4.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_4Pushed, true);
            app.BrowseButton_4.Text = 'Browse...';
            app.Panel_2 = uipanel(app.GridLayout6);
            app.GridLayout13 = uigridlayout(app.Panel_2);
            app.histAxes2 = uiaxes(app.GridLayout13);
            title(app.histAxes2, 'Dataset Distribution')
            xlabel(app.histAxes2, 'Class ID')
            app.histAxes2.Visible = 'off';
            app.Image5 = uiimage(app.GridLayout13);
            app.Image5.Visible = 'off';
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'Wedges@1x-4.0s-200px-200px(1).gif');
            app.ClearMemoryButton = uibutton(app.ZooplanktonAppUIFigure, 'push');
            app.ClearMemoryButton.ButtonPushedFcn = createCallbackFcn(app, @ClearMemoryButtonPushed, true);
            app.ClearMemoryButton.Text = 'Clear Memory';
            % Show the figure after all components are created
            app.ZooplanktonAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SegmentationAppV3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ZooplanktonAppUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ZooplanktonAppUIFigure)
        end
    end
end