classdef boxedVideoValidate < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        FrameNumLabel        matlab.ui.control.Label
        PreviousFrameButton  matlab.ui.control.Button
        NextFrameButton      matlab.ui.control.Button
        UIAxes               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        boxedVideo % Description
        frameNum = 1;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, boxedVideo)
            app.boxedVideo = boxedVideo;
            
            imshow(app.boxedVideo(:,:,:,app.frameNum),"Parent",app.UIAxes);
            set(app.UIAxes,'Xtick',[])
            set(app.UIAxes,'Ytick',[])
            set(app.UIAxes,'Xlim',[0,size(app.boxedVideo,2)])
            set(app.UIAxes,'Ylim',[0,size(app.boxedVideo,1)])
        end

        % Button pushed function: NextFrameButton
        function NextFrameButtonPushed(app, event)
            app.frameNum = app.frameNum + 1;
            imshow(app.boxedVideo(:,:,:,app.frameNum),"Parent",app.UIAxes);
            if app.frameNum > 1 
                app.PreviousFrameButton.Enable = "on";
            end
            if app.frameNum == size(app.boxedVideo,4)
                app.NextFrameButton.Enable = "off";
            end
            app.FrameNumLabel.Text = strcat("Frame ",num2str(app.frameNum));
        end

        % Button pushed function: PreviousFrameButton
        function PreviousFrameButtonPushed(app, event)
            app.frameNum = app.frameNum - 1;
            imshow(app.boxedVideo(:,:,:,app.frameNum),"Parent",app.UIAxes);
            if app.frameNum == 1 
                app.PreviousFrameButton.Enable = "off";
            end
            if app.frameNum < size(app.boxedVideo,4)
                app.NextFrameButton.Enable = "on";
            end
            app.FrameNumLabel.Text = strcat("Frame ",num2str(app.frameNum));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            app.NextFrameButton = uibutton(app.GridLayout, 'push');
            app.NextFrameButton.ButtonPushedFcn = createCallbackFcn(app, @NextFrameButtonPushed, true);
            app.NextFrameButton.Text = 'Next Frame';
            app.PreviousFrameButton = uibutton(app.GridLayout, 'push');
            app.PreviousFrameButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousFrameButtonPushed, true);
            app.PreviousFrameButton.Enable = 'off';
            app.PreviousFrameButton.Text = 'Previous Frame';
            app.FrameNumLabel = uilabel(app.GridLayout);
            app.FrameNumLabel.Text = 'FrameNum';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = boxedVideoValidate(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end