classdef SegmentationAppV3_Batch < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ZooplanktonAppUIFigure          matlab.ui.Figure
        StartRunButton                  matlab.ui.control.Button
        ClearMemoryButton               matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        PreprocessingTab                matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        Panel_3                         matlab.ui.container.Panel
        GridLayout15                    matlab.ui.container.GridLayout
        DirectorySourceEditField        matlab.ui.control.EditField
        DirectorySourceEditFieldLabel   matlab.ui.control.Label
        BrowseButton_3                  matlab.ui.control.Button
        LoadingImage                    matlab.ui.control.Image
        Panel                           matlab.ui.container.Panel
        GridLayout8                     matlab.ui.container.GridLayout
        BeginPreProcessingButton        matlab.ui.control.Button
        BeginContinuetoSegmentationButton  matlab.ui.control.Button
        ClearAllButton                  matlab.ui.control.Button
        UndoLastButton                  matlab.ui.control.Button
        TextArea                        matlab.ui.control.TextArea
        MorphologicalOperationsPanel    matlab.ui.container.Panel
        GridLayout10                    matlab.ui.container.GridLayout
        MorphOpt2Label                  matlab.ui.control.Label
        MorphOpt1Label                  matlab.ui.control.Label
        CloseButton                     matlab.ui.control.Button
        OpenButton                      matlab.ui.control.Button
        ErodeButton                     matlab.ui.control.Button
        DilateButton                    matlab.ui.control.Button
        MorphOpt2                       matlab.ui.control.Spinner
        MorphOpt1                       matlab.ui.control.Spinner
        StructureDropDown               matlab.ui.control.DropDown
        StructureDropDownLabel          matlab.ui.control.Label
        ContrastToolsPanel              matlab.ui.container.Panel
        GridLayout9                     matlab.ui.container.GridLayout
        DegreeSmooth                    matlab.ui.control.EditField
        HighIn                          matlab.ui.control.EditField
        LowIn                           matlab.ui.control.EditField
        HighOut                         matlab.ui.control.EditField
        LowOut                          matlab.ui.control.EditField
        Intensity                       matlab.ui.control.EditField
        DefaultCheckBox_4               matlab.ui.control.CheckBox
        DefaultCheckBox_3               matlab.ui.control.CheckBox
        DefaultCheckBox                 matlab.ui.control.CheckBox
        IntensityLevelsLabel            matlab.ui.control.Label
        DefaultCheckBox_2               matlab.ui.control.CheckBox
        AlphaLabel                      matlab.ui.control.Label
        SigmaLabel_2                    matlab.ui.control.Label
        BilatFiltButton                 matlab.ui.control.Button
        SigmaLabel                      matlab.ui.control.Label
        DoSLabel                        matlab.ui.control.Label
        BilatSigma                      matlab.ui.control.NumericEditField
        BilateralFilterLabel            matlab.ui.control.Label
        LaplaceFiltButton               matlab.ui.control.Button
        Alpha                           matlab.ui.control.NumericEditField
        Sigma                           matlab.ui.control.NumericEditField
        LaplaceFilterLabel              matlab.ui.control.Label
        AdjustButton                    matlab.ui.control.Button
        LowHighOutLabel                 matlab.ui.control.Label
        LowHighInLabel                  matlab.ui.control.Label
        AdjustLabel                     matlab.ui.control.Label
        SharpenButton                   matlab.ui.control.Button
        ThresholdLabel                  matlab.ui.control.Label
        AmountLabel                     matlab.ui.control.Label
        RadiusLabel                     matlab.ui.control.Label
        SharpThresholdSpinner           matlab.ui.control.NumericEditField
        SharpAmountSpinner              matlab.ui.control.NumericEditField
        SharpRadSpinner                 matlab.ui.control.NumericEditField
        SharpenLabel                    matlab.ui.control.Label
        FlatFieldPanel                  matlab.ui.container.Panel
        GridLayout7                     matlab.ui.container.GridLayout
        FlatFieldButton                 matlab.ui.control.Button
        EditField_3                     matlab.ui.control.NumericEditField
        BrightnessSlider                matlab.ui.control.Slider
        BrightnessSliderLabel           matlab.ui.control.Label
        FlatFieldingMethodDropDown      matlab.ui.control.DropDown
        FlatFieldingMethodDropDownLabel  matlab.ui.control.Label
        Image2                          matlab.ui.control.UIAxes
        SegmentationTab                 matlab.ui.container.Tab
        GridLayout11                    matlab.ui.container.GridLayout
        SegmentationParametersPanel     matlab.ui.container.Panel
        GridLayout14                    matlab.ui.container.GridLayout
        BrowseButton                    matlab.ui.control.Button
        DirectorySourceEditField_2Label  matlab.ui.control.Label
        DirectorySourceEditField_2      matlab.ui.control.EditField
        FullVideoCheckBox               matlab.ui.control.CheckBox
        EditField_15                    matlab.ui.control.EditField
        ParallelCheckBox                matlab.ui.control.CheckBox
        DropDown                        matlab.ui.control.DropDown
        EditField_14                    matlab.ui.control.NumericEditField
        EditField_13                    matlab.ui.control.NumericEditField
        EditField_12                    matlab.ui.control.NumericEditField
        EditField_11                    matlab.ui.control.NumericEditField
        EditField_10                    matlab.ui.control.NumericEditField
        CheckMovementCheckBox           matlab.ui.control.CheckBox
        EndFrameLabel                   matlab.ui.control.Label
        StartFrameLabel                 matlab.ui.control.Label
        SavesmallimagesCheckBox         matlab.ui.control.CheckBox
        EditField_9                     matlab.ui.control.EditField
        EditField_8                     matlab.ui.control.EditField
        BinaryThresholdSlider           matlab.ui.control.Slider
        BinaryThresholdSliderLabel      matlab.ui.control.Label
        FrameDepthSlider                matlab.ui.control.Slider
        FrameDepthSliderLabel           matlab.ui.control.Label
        CropPaddingSlider               matlab.ui.control.Slider
        CropPaddingSliderLabel          matlab.ui.control.Label
        MaxFrequencySlider              matlab.ui.control.Slider
        MaxFrequencySliderLabel         matlab.ui.control.Label
        MinAreaSlider                   matlab.ui.control.Slider
        MinAreaSliderLabel              matlab.ui.control.Label
        ExpansionDistanceSlider         matlab.ui.control.Slider
        ExpansionDistanceSliderLabel    matlab.ui.control.Label
        EditField_2                     matlab.ui.control.NumericEditField
        EditField                       matlab.ui.control.NumericEditField
        BeginContinuetoClassificationButton  matlab.ui.control.Button
        SettoReccomendedButton          matlab.ui.control.Button
        BeginSegmentationButton         matlab.ui.control.Button
        ClassificationTab               matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        PrepareDatasetPanel             matlab.ui.container.Panel
        GridLayout6                     matlab.ui.container.GridLayout
        BrowseButton_5                  matlab.ui.control.Button
        RawDatasetDirectoriesEditField  matlab.ui.control.EditField
        RawDatasetDirectoriesEditFieldLabel  matlab.ui.control.Label
        Panel_2                         matlab.ui.container.Panel
        GridLayout13                    matlab.ui.container.GridLayout
        Image5                          matlab.ui.control.Image
        histAxes2                       matlab.ui.control.UIAxes
        PrepareImagesButton             matlab.ui.control.Button
        FinalImageSizeEditField         matlab.ui.control.EditField
        FinalImageSizeEditFieldLabel    matlab.ui.control.Label
        ClassIdentifiersEditField       matlab.ui.control.EditField
        ClassIdentifiersEditFieldLabel  matlab.ui.control.Label
        ClassifyZooplanktonImagesPanel  matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        BrowseButton_4                  matlab.ui.control.Button
        PreparedDatasetsDirectoryEditField  matlab.ui.control.EditField
        PreparedDatasetsDirectoryEditFieldLabel  matlab.ui.control.Label
        histPanel                       matlab.ui.container.Panel
        GridLayout12                    matlab.ui.container.GridLayout
        Image4                          matlab.ui.control.Image
        histAxis                        matlab.ui.control.UIAxes
        EditField_7                     matlab.ui.control.NumericEditField
        ConfidenceThresholdSlider       matlab.ui.control.Slider
        ConfidenceThresholdSliderLabel  matlab.ui.control.Label
        ClassifyImagesButton            matlab.ui.control.Button
        TrainedNetworkDropDownLabel     matlab.ui.control.Label
        TrainedNetworkDropDown          matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        vid % Description
        h_vars
        BW
        frameNum = 1;
        BWframeNum = 1;
        trainingDatset
        trainingClasses
        rawVid
        cal_frame
        ffvid
        rawVidFilePath
        processSequence={};
        previousImage
        sampleImage
        MorphOptions
        NetArch
        net
        TrainingOptions
        imds
        ClassNames
        dataSetFilePath
        OutputDirectory
        CSVDirectory
        vidFilePath
        boxedVideo
        batchMode = 0;
        rawVidDir % Description
        trainingStats
        prepConfigFile
        segConfigFile
        classConfigFile
        currentDate
        instructionTicket = "Start Run";
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            filePath = which('SegmentationAppV3_Batch.mlapp');
            answer = uiconfirm(app.ZooplanktonAppUIFigure,'Where would you like to save the following outputs?','Output Directory',"Options",{'Current Folder','Choose Location'},"DefaultOption","Current Folder");
            app.currentDate = string(datetime("today","Format",'ddMMyy'));
            if answer == "Current Folder"
                app.OutputDirectory = pwd;
            else
                app.OutputDirectory = uigetdir;
            end
            if exist(fullfile(app.OutputDirectory,strcat("ZooPlanktonBatchOutput_",string(datetime("today","Format",'ddMMyy')))))
                k=1;
                while exist(fullfile(app.OutputDirectory,strcat("ZooPlanktonBatchOutput_",string(datetime("today","Format",'ddMMyy')),"(",num2str(k),")")))
                    k = k + 1;
                end
                mkdir(fullfile(app.OutputDirectory,strcat("ZooPlanktonBatchOutput_",string(datetime("today","Format",'ddMMyy')),"(",num2str(k),")")))
                app.OutputDirectory = (fullfile(app.OutputDirectory,strcat("ZooPlanktonBatchOutput_",string(datetime("today","Format",'ddMMyy')),"(",num2str(k),")")));
            else
                mkdir(fullfile(app.OutputDirectory,strcat("ZooPlanktonBatchOutput_",string(datetime("today","Format",'ddMMyy')))))
                app.OutputDirectory =(fullfile(app.OutputDirectory,strcat("ZooPlanktonBatchOutput_",string(datetime("today","Format",'ddMMyy')))));
            end
            fileParts = strsplit(filePath,"\");
            basePath = fileParts(1);
            for z = 2:length(fileParts)-2
            basePath = fullfile(basePath,fileParts(z));
            end
            mkdir(fullfile(app.OutputDirectory,"ConfigScripts"))
            addpath(fullfile(basePath,"assets"))
            addpath(fullfile(basePath,"networkFunctions"))
            addpath(fullfile(basePath,"videoProcessing"))
        end

        % Callback function
        function ZooplanktonAppUIFigureSizeChanged(app, event)
            
        end

        % Button pushed function: SettoReccomendedButton
        function SettoReccomendedButtonPushed(app, event)
            app.MaxFrequencySlider.Value = 0.2;
            app.MinAreaSlider.Value = 15;
            app.ExpansionDistanceSlider.Value = 5;
            app.FrameDepthSlider.Value = 2;
            app.CropPaddingSlider.Value = 5;
            app.BinaryThresholdSlider.Value = 0.60;
            app.EditField_14.Value = 0.60;
            app.EditField_13.Value = 5;
            app.EditField_12.Value = 5;
            app.EditField_11.Value = 15;
            app.EditField_10.Value = 0.2;
        end

        % Callback function
        function ProcessBinaryVideoButtonPushed(app, event)
            app.LoadingImage3.Enable = 'on';
            app.LoadingImage3.Visible = 'on';
            pause(0.5);
            app.h_vars = [app.MaxFrequencySlider.Value,app.MinAreaSlider.Value,app.ExpansionDistanceSlider.Value,app.CropPaddingSlider.Value,app.FrameDepthSlider.Value];
            % if ~isempty(app.VideoSourceEditField.Value)
            %     f = msgbox("Video Source Field is empty, please provide valid path");
            % else
                % vid = read_avi(app.VideoSourceEditField.Value);
            app.BW = process_binary_videoV2(app.vid,app.BinaryThresholdSlider.Value,app.h_vars);
            % app.LoadingImage3.ImageSource = cat(3,app.BW(:,:,1),app.BW(:,:,1),app.BW(:,:,1))*255;
            app.LoadingImage3.Visible = "off";
            imshow(app.BW(:,:,1),"Parent",app.BWimg)
            set(app.BWimg,'Xtick',[])
            set(app.BWimg,'Ytick',[])
            set(app.BWimg,'Xlim',[0,size(app.BW,2)])
            set(app.BWimg,'Ylim',[0,size(app.BW,1)])
            app.BeginSegmentationButton.Enable = 'on';
            % app.StartEndSlider.Enable = "on";
            % app.StartEndSliderLabel.Enable = "on";
            app.StartFrameLabel.Enable = "on";
            app.EndFrameLabel.Enable = "on";
            app.EditField.Enable = "on";
            app.EditField_2.Enable = "on";
            app.BWframeNum = 1;
            app.Button_3.Visible = "on";
            app.Button_4.Visible = "on";
            app.Label2.Visible = "on";
            app.Label2.Text = "Frame 1";
            app.SavesmallimagesCheckBox.Enable = "on";
            app.EditField_8.Enable = "on";
            app.EditField_9.Enable = "on";
            app.StartFrameLabel.Enable = "on";
            app.EndFrameLabel.Enable = "on";
            app.CheckMovementCheckBox.Enable = "on";
            app.SavesmallimagesCheckBox.Enable = "on";
            app.ParallelCheckBox.Enable = "on";
            
            % end
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            app.ZooplanktonAppUIFigure.WindowStyle = "normal";
            location = uigetdir;    
            app.ZooplanktonAppUIFigure.WindowStyle = "alwaysontop";
            if ~isequal(location,0)
                app.DirectorySourceEditField_2.Value = location;
                app.instructionTicket = cat(1,app.instructionTicket,strcat("SegmentationInputDirectory,",location));
            end
                
        end

        % Callback function
        function VideoSourceEditFieldValueChanged(app, event)
            value = app.VideoSourceEditField.Value;
            app.LoadingImage2.Visible = 'on';
            app.vid = read_avi(value);
            app.LoadingImage2.ImageSource = cat(3,app.vid(:,:,1),app.vid(:,:,1),app.vid(:,:,1)); 
            app.StartEndSlider.Limits = [0 size(app.vid,3)];
            app.EditField_2.Value = size(app.vid,3);
            app.BrowseButton_4.Visible = "on";
            app.Button_2.Visible = "on";
            app.Frame1Label.Visible = 'on';
            app.Frame1Label.Text = "Frame 1";
            app.ProcessBinaryVideoButton.Enable = 'on';
        end

        % Button pushed function: BeginSegmentationButton
        function BeginSegmentationButtonPushed(app, event)
            if exist(fullfile(app.OutputDirectory,"SegmentationOutput"))
                k = 1;
                while exist(strcat(app.OutputDirectory,"/SegmentationOutput(",num2str(k),")"))
                    k = k + 1;
                end
                mkdir(strcat(app.OutputDirectory,"/SegmentationOutput(",num2str(k),")"))
            else
            mkdir(fullfile(app.OutputDirectory,"SegmentationOutput"))
            end

            if app.CheckMovementCheckBox.Value
                    app.h_vars = [app.MaxFrequencySlider.Value,app.MinAreaSlider.Value,app.ExpansionDistanceSlider.Value,app.CropPaddingSlider.Value,app.FrameDepthSlider.Value];
            else
                    app.h_vars = [app.MaxFrequencySlider.Value,app.MinAreaSlider.Value,app.ExpansionDistanceSlider.Value,app.CropPaddingSlider.Value,0];
            end
   
            if app.ParallelCheckBox.Value
                app.instructionTicket = cat(1,app.instructionTicket,strcat("beginSegmentation,",string(app.h_vars),app.vidFilePath,app.SavesmallimagesCheckBox.Value,app.EditField_8.Value,app.EditField_9.Value,app.FullVideoCheckBox.Value,[app.EditField.Value,app.EditField_2.Value],fullfile(app.OutputDirectory,"SegmentationOutput"),app.DropDown.Value,app.EditField_15.Value));
            else
                app.instructionTicket = cat(1,app.instructionTicket,strcat("beginSegmentationParallel,",string(app.h_vars),app.vidFilePath,app.SavesmallimagesCheckBox.Value,app.EditField_8.Value,app.EditField_9.Value,app.FullVideoCheckBox.Value,[app.EditField.Value,app.EditField_2.Value],fullfile(app.OutputDirectory,"SegmentationOutput"),app.DropDown.Value,app.EditField_15.Value));
            end
        end

        % Callback function: not associated with a component
        function ButtonPushed(app, event)
            displayFrame = app.frameNum - 1;
            imshow(app.vid(:,:,displayFrame),"Parent",app.RawImg)
            app.frameNum = app.frameNum - 1;
            if app.frameNum == 1
                app.BrowseButton_4.Enable = "off";
            elseif app.frameNum == size(app.vid,3) - 1
                app.Button_2.Enable = "on";
            end
            app.Frame1Label.Text = strcat("Frame ",num2str(displayFrame));
        end

        % Callback function: not associated with a component
        function Button_2Pushed(app, event)
            displayFrame = app.frameNum + 1;
            imshow(app.vid(:,:,displayFrame),"Parent",app.RawImg)
            app.frameNum = app.frameNum + 1;
            if app.frameNum == 2
                app.BrowseButton_4.Enable = "on";
            elseif app.frameNum == size(app.vid,3)
                app.Button_2.Enable = "off";
            end    
            app.Frame1Label.Text = strcat("Frame ",num2str(displayFrame));
        end

        % Callback function: not associated with a component
        function Button_3Pushed(app, event)
            displayFrame = app.BWframeNum - 1;
            imshow(app.BW(:,:,displayFrame),"Parent",app.BWimg)
            app.BWframeNum = app.BWframeNum - 1;
            if app.BWframeNum == 1
                app.Button_3.Enable = "off";
            elseif app.BWframeNum == size(app.BW,3) - 1
                app.Button_4.Enable = "on";
            end
            app.Label2.Text = strcat("Frame ",num2str(displayFrame));
        end

        % Callback function: not associated with a component
        function Button_4Pushed(app, event)
            displayFrame = app.BWframeNum + 1;
            imshow(app.BW(:,:,displayFrame),"Parent",app.BWimg)
            app.BWframeNum = app.BWframeNum + 1;
            if app.BWframeNum == 2
                app.Button_3.Enable = "on";
            elseif app.BWframeNum == size(app.BW,3)
                app.Button_4.Enable = "off";
            end
            app.Label2.Text = strcat("Frame ",num2str(displayFrame));
        end

        % Callback function
        function StartEndSliderValueChanged(app, event)
            value = app.StartEndSlider.Value;
            app.StartEndSlider.Value = ceil(value);
            app.EditField.Value = ceil(value(1));
            app.EditField_2.Value = ceil(value(2));
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            app.EditField.Value = ceil(value);
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            value = app.EditField_2.Value;
            app.EditField_2.Value = ceil(value);
        end

        % Button pushed function: BeginContinuetoClassificationButton
        function BeginContinuetoClassificationButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.ClassificationTab;
            app.UnsortedImagesEditField.Value = app.dataSetFilePath;
            app.imds = imageDatastore(app.dataSetFilePath,"IncludeSubfolders",true,"LabelSource","foldernames");
        end

        % Callback function
        function TrainingDatasetDropDownClicked(app, event)
            
        end

        % Callback function
        function TrainingDatasetDropDownValueChanged(app, event)
            % if value == "Browse..."
            %     oldItems = app.TrainingDatasetDropDown.Items;
            %     location = uigetdir;
            %     newDataset = location;
            %     if ~isequal(location,0)
            %         app.imds = imageDatastore(location,"IncludeSubfolders",true,"LabelSource","foldernames");
            %         trainingSet = cat(2,oldItems,{newDataset});
            %         app.TrainingDatasetDropDown.Items = unique(trainingSet);
            %         app.TrainingDatasetDropDown.Value = string(newDataset);
            %         app.trainingClasses = length(app.imds.Labels);
            %         app.NetworkArchitecureDropDown.Enable = "on";
            %         app.NetworkArchitecureDropDownLabel.Enable = "on";
            %     end
            % end    
            % if value ~= "Browse..."
            %     app.NetworkArchitecureDropDown.Enable = "on";
            %     app.NetworkArchitecureDropDownLabel.Enable = "on";
            % 
            % end    
        end

        % Button pushed function: ClassifyImagesButton
        function ClassifyImagesButtonPushed(app, event)
            % classifySegmentedImages(app.net,app.imds,app.ConfidenceThresholdSlider.Value,app.histAxis,pwd);
            % app.histAxis.Visible = "on";
            app.instructionTicket = cat(1,app.instructionTicket,strcat("Classify,",app.TrainedNetworkDropDown.Value,",",string(app.ConfidenceThresholdSlider.Value)));
        end

        % Callback function
        function ofClassesSpinnerValueChanged(app, event)
            value = app.ofClassesSpinner.Value;
            app.trainingClasses = value;
        end

        % Callback function: not associated with a component
        function GenerateCalibrationFrameButtonPushed(app, event)
            imshow("./assets/solid-color-image.png","Parent",app.Image2)
            % app.Image2.Toolbar.Visible = "off";
            app.LoadingImage.Visible = "on";
            % imshow('Wedges@1x-4.0s-200px-200px(1).gif',"Parent",app.Image2)
            pause(0.5)
            tic
            app.cal_frame = calibrateV2(app.rawVid);
            imshow(uint8(app.cal_frame),"Parent",app.Image2)
            app.Image2.Visible = "on";
            % app.Image2.Toolbar.Visible = "on";
            app.LoadingImage.Visible ="off";
            % app.Image2.ImageSource = cat(3,app.cal_frame,app.cal_frame,app.cal_frame);
            tEnd = toc;
            app.TextArea.Value = cat(1,app.TextArea.Value,{['Calibration Frame generated in',' ',char(num2str(tEnd,'%.3f')),' seconds']});
            app.FlatFieldingMethodDropDown.Enable = "on";
            app.FlatFieldingMethodDropDownLabel.Enable = "on";
            app.BrightnessSlider.Enable ="on";
            app.BrightnessSliderLabel.Enable = "on";
            app.FlatFieldButton.Enable = "on";
            app.EditField_3.Enable = "on";
        end

        % Button pushed function: BrowseButton_3
        function BrowseButton_3Pushed(app, event)
                app.ZooplanktonAppUIFigure.WindowStyle = "normal";
                location = uigetdir;
                app.ZooplanktonAppUIFigure.WindowStyle = "alwaysontop";
                if ~isequal(location,0)
                    app.rawVidDir = location;
                    app.DirectorySourceEditField.Value = location;
                    app.prepConfigFile = fullfile(app.OutputDirectory,"ConfigFiles",strcat("PreProcessing_",app.currentDate,".txt"));
                    mkdir(fullfile(app.OutputDirectory,"PreProcessingOutput"))
                    if any(contains(app.instructionTicket,"RawVideoDirectory"))
                        app.instructionTicket = cat(1,app.instructionTicket,"Start Prep");
                        app.instructionTicket(contains(app.instructionTicket,"RawVideoDirectory")) = sprintf("%s,%s","RawVideoDirectory",app.rawVidDir);
                    else
                        if length(app.instructionTicket) ~= 1
                            app.instructionTicket = cat(1,app.instructionTicket(1),sprintf("%s,%s","RawVideoDirectory",app.rawVidDir),app.instructionTicket(2:end));
                        else
                            app.instructionTicket = cat(1,app.instructionTicket,"Start Prep");
                            app.instructionTicket = cat(1,app.instructionTicket,sprintf("%s,%s","RawVideoDirectory",app.rawVidDir));
                        end
                    end
                end
        end

        % Callback function
        function VideoSourceEditField_2ValueChanged(app, event)
            value = app.VideoSourceEditField_2.Value;
            if exist(value)
            app.Image2.Visible = 'on';
            
            app.rawVid = read_avi(value);
            app.Image2.ImageSource = cat(3,app.rawVid(:,:,1),app.rawVid(:,:,1),app.rawVid(:,:,1));
            app.GenerateCalibrationFrameButton.Enable = "on";
            strs = strsplit(value,'/');
            app.rawVidFilePath = strs(end);
            elseif ~isempty(value)
                uialert(app.ZooplanktonAppUIFigure,"File not found, please provide valid path.","Invalid File Path")
            end    
        end

        % Button pushed function: FlatFieldButton
        function FlatFieldButtonPushed(app, event)
        if any(contains(app.instructionTicket,"beginFlatfield"))
            app.instructionTicket(contains(app.instructionTicket,"beginFlatfield")) = sprintf("%s,%s,%f","beginFlatfield",app.FlatFieldingMethodDropDown.Value,app.EditField_3.Value);
        else
            app.instructionTicket = cat(1,app.instructionTicket,sprintf("%s,%s,%f","beginFlatfield",app.FlatFieldingMethodDropDown.Value,app.EditField_3.Value));
        end
        disp(app.instructionTicket)
        end

        % Callback function
        function BrightnessSliderValueChanged(app, event)
            value = app.BrightnessSlider.Value;
            app.BrightnessSlider.Value = round(value,2);
            app.EditField_3.Value = round(value,2);
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            value = app.EditField_3.Value;
            app.BrightnessSlider.Value = round(value,2);
            app.EditField_3.Value = round(value,2);
        end

        % Callback function
        function VideoSourceDropDownValueChanged(app, event)
            value = app.VideoSourceDropDown.Value;
            if value == "Browse..."
                app.ZooplanktonAppUIFigure.WindowStyle = "normal";
                [file,location] = uigetfile('*.avi');
                app.ZooplanktonAppUIFigure.WindowStyle = "alwaysontop";
                if ~isequal(file,0)
                app.LoadingImage2.Visible = 'on';
                app.DirectorySourceEditField_2.Value = fullfile(location,file);
                value = app.VideoSourceEditField.Value;
                app.vid = read_avi(value);
                % app.LoadingImage2.ImageSource = cat(3,app.vid(:,:,1),app.vid(:,:,1),app.vid(:,:,1));
                app.LoadingImage2.Visible = "off";
                imshow(app.vid(:,:,1),"Parent",app.RawImg)
                app.RawImg.Visible = "on";
                app.StartEndSlider.Limits = [1 size(app.vid,3)];
                app.EditField_2.Value = size(app.vid,3);
                app.StartEndSlider.Value(2) = size(app.vid,3);
                app.BrowseButton_4.Visible = "on";
                app.Button_2.Visible = "on";
                app.Frame1Label.Visible = "on";
                app.ProcessBinaryVideoButton.Enable = 'on';
                end
            elseif value ~= "Select..."
                if value == "Generated PrecProcessed Video"
                    imshow(app.vid(:,:,1),"Parent",app.RawImg)
                    app.StartEndSlider.Limits = [1 size(app.vid,3)];
                    app.EditField_2.Value = size(app.vid,3);
                    app.StartEndSlider.Value(2) = size(app.vid,3);
                    app.BrowseButton_4.Visible = "on";
                    app.Button_2.Visible = "on";
                    app.ProcessBinaryVideoButton.Enable = 'on';
                    app.Frame1Label.Visible = "on";
                end
            end    
        end

        % Value changed function: StructureDropDown
        function StructureDropDownValueChanged(app, event)
            value = app.StructureDropDown.Value;
            if value == "Diamond"
                app.MorphOpt2.Visible = "off";
                app.MorphOpt2Label.Visible = "off";
                
                app.MorphOpt1Label.Text = "Radius";
                app.MorphOpt1.Visible = "on";
                app.MorphOpt1Label.Visible = "on";
            elseif value == "Disk"

                app.MorphOpt1Label.Text = "Radius";
                app.MorphOpt1.Visible = "on";
                app.MorphOpt1Label.Visible = "on";

                app.MorphOpt2Label.Text = "# of Sides";
                app.MorphOpt2.Visible = "on";
                app.MorphOpt2Label.Visible = "on";
            elseif value == "Octagon"
                app.MorphOpt2.Visible = "off";
                app.MorphOpt2Label.Visible = "off";

                app.MorphOpt1Label.Text = "Radius";
                app.MorphOpt1.Visible = "on";
                app.MorphOpt1Label.Visible = "on";
            elseif value == "Line"

                app.MorphOpt1Label.Text = "Length";
                app.MorphOpt1.Visible = "on";
                app.MorphOpt1Label.Visible = "on";

                app.MorphOpt2Label.Text = "Degree";
                app.MorphOpt2.Visible = "on";
                app.MorphOpt2Label.Visible = "on";
            elseif value == "Rectangle"   

                app.MorphOpt2Label.Text = "Width";
                app.MorphOpt2.Visible = "on";
                app.MorphOpt2Label.Visible = "on";

                app.MorphOpt1Label.Text = "Height";
                app.MorphOpt1.Visible = "on";
                app.MorphOpt1Label.Visible = "on";
            elseif value == "Select..."
                app.MorphOpt2.Visible = "off";
                app.MorphOpt2Label.Visible = "off";
                app.MorphOpt1.Visible = "off";
                app.MorphOpt1Label.Visible = "off";
                
            end
            if value ~= "Select..."
                app.MorphOptions = [{app.MorphOpt1.Value} {app.MorphOpt2.Value} {lower(value)}];
                app.DilateButton.Enable = "on";
                app.ErodeButton.Enable = "on";
                app.OpenButton.Enable = "on";
                app.CloseButton.Enable = "on";
            end
            if value == "Select..."
                app.MorphOpt2.Visible = "off";
                app.MorphOpt2Label.Visible = "off";
                app.MorphOpt1.Visible = "off";
                app.MorphOpt1Label.Visible = "off";
                
                app.DilateButton.Enable = "off";
                app.ErodeButton.Enable = "off";
                app.OpenButton.Enable = "off";
                app.CloseButton.Enable = "off";
            end
        end

        % Callback function: not associated with a component
        function SaveOutputButtonPushed(app, event)
            app.ffvid = processVideo(app.ffvid,app.processSequence);
            app.vid = app.ffvid;
            fileName = char(app.rawVidFilePath);
            % app.ZooplanktonAppUIFigure.WindowStyle = "normal";
            % fileName = inputdlg(strcat("Save video as ", strcat(fileName(1:end-4),"pre_processed.avi"),"?"),strcat(app.rawVidFilePath,"pre_processed.avi"));
            % app.ZooplanktonAppUIFigure.WindowStyle = "alwaysontop";
            if isempty(fileName)
                tic;
                fileName = char(app.rawVidFilePath);
                write_avi(fullfile(app.OutputDirectory,"PreProcessingOutput",strcat(fileName(1:end-4),"pre_processed.avi")),app.vid)
                tEnd = toc;
            % else
            %     tic;
            %     write_avi(strcat(fileName,"pre_processed.avi"),app.vid)
            %     tEnd = toc;
            end
            app.VideoSourceDropDown.Items = cat(2,app.VideoSourceDropDown.Items,{'Generated PrecProcessed Video'});
            app.TextArea.Value = cat(1,app.TextArea.Value,{['Wrote Video to File in',' ',char(num2str(tEnd,'%.3f')),' seconds']});
            app.BeginContinuetoSegmentationButton.Visible = "on";
        end

        % Button pushed function: SharpenButton
        function SharpenButtonPushed(app, event)
            if app.DefaultCheckBox.Value == false
                app.instructionTicket = cat(1,app.instructionTicket,strcat("Contrast,S,",string(app.SharpRadSpinner.Value),",",string(app.SharpAmountSpinner.Value),",",string(app.SharpThresholdSpinner.Value),",X"));
            else
                app.instructionTicket = cat(1,app.instructionTicket,"Contrast,S,X,X,X,X");
            end

        end

        % Button pushed function: UndoLastButton
        function UndoLastButtonPushed(app, event)
            app.instructionTicket(end) = [];
            app.UndoLastButton.Enable = "off";
        end

        % Button pushed function: AdjustButton
        function AdjustButtonPushed(app, event)
            if app.DefaultCheckBox_2.Value == false
                app.instructionTicket = cat(1,app.instructionTicket,"Contrast,A,",strcat(string(app.LowIn.Value),",",string(app.HighIn.Value), ...
                ",",string(app.LowOut.Value),",",string(app.HighOut.Value)));
            else
                app.instructionTicket = cat(1,app.instructionTicket,"Contrast,A,X,X,X,X");
            end
        end

        % Button pushed function: LaplaceFiltButton
        function LaplaceFiltButtonPushed(app, event)
            app.previousImage = app.sampleImage;
            if app.DefaultCheckBox_3.Value == false
                app.instructionTicket = cat(1,app.instructionTicket,strcat("Contrast,L,",string(app.Sigma.Value),",",string(app.Alpha.Value),",",string(app.Intensity.Value),",X,X"));
            else
                % laplace has no default, need at least alpha and sigma
                app.instructionTicket = cat(1,app.instructionTicket,strcat("Contrast,L,",string(app.Sigma.Value),",",string(app.Alpha.Value),",X,X"));
            end
        end

        % Button pushed function: BilatFiltButton
        function BilatFiltButtonPushed(app, event)
            app.previousImage = app.sampleImage;
            if app.DefaultCheckBox_4.Value == false
                app.instructionTicket = cat(1,app.instructionTicket,"Contrast,B,",strcat(string(app.DegreeSmooth.Value),",",string(app.BilatSigma.Value),",X,X"));
            else
                app.instructionTicket = cat(1,app.instructionTicket,"Contrast,B,X,X,X,X");
            end

        end

        % Callback function
        function ClearAllButtonPushed(app, event)
            answer = questdlg("Are you sure you want to clear? This will erase all unsaved data.","Clear","Yes, I'm Sure","No, Cancel");
            if answer == "Yes, I'm Sure"
            app.sampleImage = app.ffvid(:,:,1);
            app.processSequence = {};
            app.TextArea.Value = cat(1,app.TextArea.Value,{'Cleared all edits.'});

            end
        end

        % Button pushed function: DilateButton
        function DilateButtonPushed(app, event)
            app.instructionTicket = cat(1,app.instructionTicket,strcat("Morph,Dilate,",string(app.MorphOptions(3)),",",string(app.MorphOptions(1)),",",string(app.MorphOptions(2))));
        end

        % Button pushed function: ErodeButton
        function ErodeButtonPushed(app, event)
            app.instructionTicket = cat(1,app.instructionTicket,strcat("Morph,Erode,",string(app.MorphOptions(3)),",",string(app.MorphOptions(1)),",",string(app.MorphOptions(2))));
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            app.instructionTicket = cat(1,app.instructionTicket,strcat("Morph,Open,",string(app.MorphOptions(3)),",",string(app.MorphOptions(1)),",",string(app.MorphOptions(2))));
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            app.instructionTicket = cat(1,app.instructionTicket,strcat("Morph,Close,",string(app.MorphOptions(3)),",",string(app.MorphOptions(1)),",",string(app.MorphOptions(2))));
        end

        % Callback function: not associated with a component
        function BeginTrainingButtonPushed(app, event)
            envDict = dictionary(string(app.EnvironmentDropDown.Items),["cpu","gpu","parallel-cpu","parallel-gpu"]);
            tStart = tic;
            app.ConfusionMatPanel.Visible = "on";
            classNames = categories(app.imds.Labels);
            [l,w]=size(app.imds.preview);
            if app.NetworkArchitecureDropDown.Value == "Adapted ResNet"
                app.net = setupNetworkAdaptedResnet([l,w,1],length(classNames));
            elseif app.NetworkArchitecureDropDown.Value == "Adapted GoogLeNet"
                app.net = setupNetworkGoogLe([l,w,1],length(classNames));
            elseif app.NetworkArchitecureDropDown.Value == "Adapted DarkNet"
                app.net = setupDarkNet([l,w,1],length(classNames));
            elseif app.NetworkArchitecureDropDown.Value == "Adapted DenseNet"
                app.net = setupNetworkAdaptedDenseNet([l,w,1],length(classNames));
            elseif app.NetworkArchitecureDropDown.Value == "Adapted Google365"
                app.net = setupNetworkAdaptedGoogle365([l,w,1],length(classNames));
            elseif app.NetworkArchitecureDropDown.Value == "Adapted Xception"
                app.net = setupNetworkAdaptedXception([l,w,1],length(classNames));
            end
            if envDict(string(app.EnvironmentDropDown.Value)) == "parallel-cpu"
            poolobj = gcp("nocreate");
            delete(poolobj);
            pool = parpool("Processes");
            end
            [imdsTrain,imdsValidation,imdsTest] = splitEachLabel(app.imds,0.6,0.2,0.2,"randomized");
            app.TrainingOptions = trainingOptions("sgdm", ...
                MaxEpochs=app.MaxEpochsSlider.Value, ...
                ValidationData=imdsValidation, ...
                ValidationFrequency=app.ValidationFrequencySlider.Value, ...
                Plots="none", ...
                Metrics="accuracy", ...
                InitialLearnRate=app.LearningRateSlider.Value, ...                            
                ExecutionEnvironment=envDict(string(app.EnvironmentDropDown.Value)),...
                Verbose=true);
            
            app.net = trainnet(imdsTrain,app.net,"crossentropy",app.TrainingOptions);
            YValidation = imdsTest.Labels;
            if isMATLABReleaseOlderThan("R2023A")
                X = readall(imdsTest);
                Xtrain = reshape(cat(3,X{:}),[l,w,1,length(X)]);
                scores = app.net.predict(dlarray(double(Xtrain),'SSCB'));
                [~,b] = max(scores,[],1);
                YTest = categorical(b);
                accuracy = mean(YValidation == YTest');
            else
                scores = minibatchpredict(app.net,imdsTest);
                classNames = categories(app.imds.Labels);
                YTest = scores2label(scores,classNames);
                accuracy = mean(YValidation == YTest);
            end
            tEnd = toc(tStart);
            fprintf("<strong>Elaspsed Time: %.3f seconds.</strong>",tEnd);
            cm = confusionchart(app.ConfusionMatPanel,YTest,YValidation);
            app.CurrentTrainingAccuracyLabel.Text = strcat("Test Accuracy: ", num2str(accuracy*100),"%");
            app.CurrentTrainingAccuracyLabel.Visible = "on";
            answer = uiconfirm(app.ZooplanktonAppUIFigure,sprintf("Model accuracy reached %.2f, save?",accuracy*100),"Save?","Options",{'Yes','No'},"DefaultOption",'Yes');
            if answer == "Yes"
                trainedNet = app.net;
                if exist(strcat("TrainedModel_",string(datetime("today","Format",'ddMMyy')),".mat"))
                    k=1;
                    while exist(strcat("TrainedModel_",string(datetime("today","Format",'ddMMyy')),"(",num2str(k),").mat"))
                        k = k + 1;
                    end
                    save(fullfile(app.OutputDirectory,"Classification",strcat("TrainedModel_",string(datetime("today","Format",'ddMMyy'))),"(",num2str(k),").mat"),"trainedNet")
                else
                    save(fullfile(app.OutputDirectory,"Classification",strcat("TrainedModel_",string(datetime("today","Format",'ddMMyy')),".mat")),"trainedNet")
                end
            end    
            app.ExportTrainingStatsButton.Visible = "on";
            app.trainingStats = classAccPreRec(YValidation,YTest);
            app.trainingStats = cat(2,app.trainingStats,tEnd);
            exportgraphics(cm,fullfile(app.OutputDirectory,"Classification","ConfusionMat.png"))
            app.TrainedNetworkDropDown.Items = cat(2,app.TrainedNetworkDropDown.Items,strcat("TrainedModel_",string(datetime("today","Format",'ddMMyy'))));
        end

        % Callback function: not associated with a component
        function NetworkArchitecureDropDownValueChanged(app, event)
            value = app.NetworkArchitecureDropDown.Value;
            if value == "Browse Workplace..."
                app.ZooplanktonAppUIFigure.WindowStyle = "normal";
                [file,location] = uigetfile('*.mat');
                app.ZooplanktonAppUIFigure.WindowStyle = "alwaysontop";
                if ~isequal(file,0)
                    variableinfo = who('-file',fullfile(file,location));
                    if length(variableinfo) > 1
                        uialert(app.ZooplanktonAppUIFigure,"Training Network .mat file contains multiple variables, please correct file to only contained trained dlnetwork object.","Invalid File Contents")
                    else
                        load(fullfile(file,location))
                        app.NetworkArchitecureDropDown.Items = {app.NetworkArchitecureDropDown.Items,variableinfo};
                        app.NetArch = variableinfo;
                    end
                end
            end
            if value ~= "Browse Workplace..." & app.TrainingDatasetDropDown.Value ~= "Browse..."
                app.BeginTrainingButton.Enable = "on";
            end    
            app.BeginTrainingButton.Enable = "on";
            app.MaxEpochsSlider.Enable = "on";
            app.MaxEpochsSliderLabel.Enable = "on";
            app.ValidationFrequencySlider.Enable = "on";
        app.ValidationFrequencySliderLabel.Enable = "on";
            app.LearningRateSlider.Enable = "on";
            app.LearningRateSliderLabel.Enable = "on";
            app.EditField_4.Enable = "on";
            app.EditField_5.Enable = "on";
            app.EditField_6.Enable = "on";
            app.EnvironmentDropDown.Enable = "on";
            app.EnvironmentDropDownLabel.Enable ="on";
            mkdir(fullfile(app.OutputDirectory,"Classification"))
        end

        % Callback function: not associated with a component
        function BrowseButton_2Pushed(app, event)
            app.ZooplanktonAppUIFigure.WindowStyle = "normal";
            location = uigetdir('.');
            app.ZooplanktonAppUIFigure.WindowStyle = "alwaysontop";
            if ~isequal(location,0)
                app.imds = imageDatastore(location,"IncludeSubfolders",true,"LabelSource","foldernames");
                if app.TrainedNetworkDropDown.Value ~= "Select..."
                    inputSize = app.net.Layers(1).InputSize;
                    if any(size(app.imds.preview) ~= [inputSize(1),inputSize(2)])

                        answer = uiconfirm(app.ZooplanktonAppUIFigure,sprintf("One or more dimensions are invalid, images must be [%d %d] for chosen network. Auto resize images?",inputSize(1),inputSize(2)),"Invalid Image Size","Options",{'Yes','No'},"DefaultOption","Yes");
                        if answer == "Yes"
                            prepareDataset(location,[inputSize(1),inputSize(2)],"1")
                            app.imds = imageDatastore('./PreparedDataset',"IncludeSubfolders",true,"LabelSource","foldernames");
                            app.ClassifyImagesButton.Enable = "on";
                            app.UnsortedImagesEditField.Value = "./PreparedDataset";
                        else
                            uialert(app.ZooplanktonAppUIFigure,"Please choose a new network or manually prepare images in the 'Prepare Dataset' panel.")
                        end
                    else
                        app.UnsortedImagesEditField.Value = location;
                        app.ClassifyImagesButton.Enable = "on";
                    end
                end
            end
        end

        % Callback function: not associated with a component
        function TrainingDatasetDropDownClicked2(app, event)
            value = app.TrainingDatasetDropDown.Value;
            if value == "Browse..."
                oldItems = app.TrainingDatasetDropDown.Items;
                app.ZooplanktonAppUIFigure.WindowStyle = "normal";
                location = uigetdir;
                app.ZooplanktonAppUIFigure.WindowStyle = "alwaysontop";
                newDataset = location;
                if ~isequal(location,0)
                    app.imds = imageDatastore(location,"IncludeSubfolders",true,"LabelSource","foldernames");
                    trainingSet = cat(2,oldItems,{newDataset});
                    app.TrainingDatasetDropDown.Items = unique(trainingSet);
                    app.TrainingDatasetDropDown.Value = string(newDataset);
                    app.trainingClasses = length(app.imds.Labels);
                    app.NetworkArchitecureDropDown.Enable = "on";
                    app.NetworkArchitecureDropDownLabel.Enable = "on";
                end
            end    
            if value ~= "Browse..."
                app.NetworkArchitecureDropDown.Enable = "on";
                app.NetworkArchitecureDropDownLabel.Enable = "on";

            end 

        end

        % Callback function: not associated with a component
        function MaxEpochsSliderValueChanged(app, event)
            value = app.MaxEpochsSlider.Value;
            app.EditField_4.Value = ceil(value);
            app.MaxEpochsSlider.Value = ceil(value);
        end

        % Callback function: not associated with a component
        function EditField_4ValueChanged(app, event)
            value = app.EditField_4.Value;
            app.MaxEpochsSlider.Value = value;
        end

        % Callback function: not associated with a component
        function ValidationFrequencySliderValueChanged(app, event)
            value = app.ValidationFrequencySlider.Value;
            app.EditField_5.Value = ceil(value);
            app.ValidationFrequencySlider.Value = ceil(value);

        end

        % Callback function: not associated with a component
        function EditField_5ValueChanged(app, event)
            value = app.EditField_5.Value;
            app.ValidationFrequencySlider.Value = value;
        end

        % Callback function: not associated with a component
        function LearningRateSliderValueChanged(app, event)
            value = app.LearningRateSlider.Value;
            app.EditField_6.Value = round(value,3);
            app.LearningRateSlider.Value = round(value,3);
        end

        % Callback function: not associated with a component
        function EditField_6ValueChanged(app, event)
            value = app.EditField_6.Value;
            app.LearningRateSlider.Value = value;
        end

        % Value changed function: TrainedNetworkDropDown
        function TrainedNetworkDropDownValueChanged(app, event)
            value = app.TrainedNetworkDropDown.Value;
            if app.TrainedNetworkDropDown.Value == "Adapted ResNet"
                load("adaptedResnet.mat")
                app.net = adaptedResnet;
            elseif value == "Browse..."
                app.ZooplanktonAppUIFigure.WindowStyle = "normal";
                [file,location] = uigetfile('*.mat');
                app.ZooplanktonAppUIFigure.WindowStyle = "alwaysontop";
                if ~isequal(file,0)
                    variableinfo = who('-file',fullfile(location,file));
                    if length(variableinfo) > 1
                        uialert(app.ZooplanktonAppUIFigure,"Training Network .mat file contains multiple variables, please correct file to only contained trained dlnetwork object.","Invalid File Contents")
                    else
                        S = load(fullfile(location,file));
                        C = struct2table(S);
                        app.net = C.(1);
                        app.TrainedNetworkDropDown.Items = cat(2,app.TrainedNetworkDropDown.Items,{char(strcat(variableinfo,'(',file,')'))});
                        app.TrainedNetworkDropDown.Value = strcat(variableinfo,'(',file,')');
                        app.NetArch = variableinfo;
                    end
                end
            end   
            if ~isempty(app.UnsortedImagesEditField.Value)
                inputSize = app.net.Layers(1).InputSize;
                    if any(size(app.imds.preview) ~= [inputSize(1),inputSize(2)])

                        answer = uiconfirm(app.ZooplanktonAppUIFigure,sprintf("One or more dimensions are invalid, images must be [%d %d] for chosen network. Auto resize images?",inputSize(1),inputSize(2)),"Invalid Image Size","Options",{'Yes','No'},"DefaultOption","Yes");
                        if answer == "Yes"
                            prepareDataset(app.UnsortedImagesEditField.Value,[inputSize(1),inputSize(2)],"1")
                            app.imds = imageDatastore('./PreparedDataset',"IncludeSubfolders",true,"LabelSource","foldernames");
                            app.ClassifyImagesButton.Enable = "on";
                            app.UnsortedImagesEditField.Value = "./PreparedDataset";
                        else
                            uialert(app.ZooplanktonAppUIFigure,"Please choose a new network or manually prepare images in the 'Prepare Dataset' panel.")
                        end
                    else
                        app.ClassifyImagesButton.Enable = "on";
                    end
            end

           
        end

        % Value changed function: ConfidenceThresholdSlider
        function ConfidenceThresholdSliderValueChanged(app, event)
            value = app.ConfidenceThresholdSlider.Value;
            app.EditField_7.Value = round(value,3);
            app.ConfidenceThresholdSlider.Value = round(value,3);
        end

        % Value changed function: EditField_7
        function EditField_7ValueChanged(app, event)
            value = app.EditField_7.Value;
            app.EditField_7.Value = round(value,3);
            app.ConfidenceThresholdSlider.Value = round(value,3);
        end

        % Callback function
        function EditField_7ValueChanged2(app, event)

        end

        % Value changed function: DefaultCheckBox
        function DefaultCheckBoxValueChanged(app, event)
            value = app.DefaultCheckBox.Value;
            if value == false
                app.SharpRadSpinner.Enable = "on";
                app.SharpAmountSpinner.Enable = "on";
                app.SharpThresholdSpinner.Enable = "on";
            else
                app.SharpRadSpinner.Enable = "off";
                app.SharpAmountSpinner.Enable = "off";
                app.SharpThresholdSpinner.Enable = "off";

            end
            
        end

        % Value changed function: DefaultCheckBox_2
        function DefaultCheckBox_2ValueChanged(app, event)
            value = app.DefaultCheckBox_2.Value;
            if value == false
                app.LowIn.Enable = "on";
                app.LowOut.Enable = "on";
                app.HighIn.Enable = "on";
                app.HighOut.Enable = "on";
            else

                app.LowIn.Enable = "off";
                app.LowOut.Enable = "off";
                app.HighIn.Enable = "off";
                app.HighOut.Enable = "off";
            end
        end

        % Value changed function: DefaultCheckBox_3
        function DefaultCheckBox_3ValueChanged(app, event)
            value = app.DefaultCheckBox_3.Value;
            if value == false
                app.Sigma.Enable = "on";
                app.Alpha.Enable = "on";
                app.Intensity.Enable = "on";
            else
                app.Sigma.Enable = "off";
                app.Alpha.Enable = "off";
                app.Intensity.Enable = "off";

            end
        end

        % Value changed function: DefaultCheckBox_4
        function DefaultCheckBox_4ValueChanged(app, event)
            value = app.DefaultCheckBox_4.Value;
            if value == false
                app.DegreeSmooth.Enable = "on";
                app.BilatSigma.Enable = "on";
            else
                app.DegreeSmooth.Enable = "off";
                app.BilatSigma.Enable = "off";
         
            end
        end

        % Button pushed function: PrepareImagesButton
        function PrepareImagesButtonPushed(app, event)
            if ~isempty(app.ClassIdentifiersEditField.Value)
                if ~isempty(app.FinalImageSizeEditField.Value)
                    prepareDataset(fullfile(app.OutputDirectory,"Classification"),(app.DatasetSourceEditField.Value),extract(app.FinalImageSizeEditField.Value,digitsPattern),app.ClassNames)
                    app.instructionTicket = cat(1,app.instructionTicket,strcat("PrepareData,",extract(app.FinalImageSizeEditField.Value,digitsPattern),app.ClassNames));
                else
                    prepareDataset(fullfile(app.OutputDirectory,"Classification"),app.DatasetSourceEditField.Value,[227,227],app.ClassNames)
                    app.instructionTicket = cat(1,app.instructionTicket,strcat("PrepareData,",extract(app.FinalImageSizeEditField.Value,digitsPattern),app.ClassNames))
                end
            else
                location = dir(app.DatasetSourceEditField.Value);
                subFolderCount = length(location) - 2;
                if ~isempty(app.FinalImageSizeEditField.Value)
                    prepareDataset(fullfile(app.OutputDirectory,"Classification"),app.DatasetSourceEditField.Value,extract(app.FinalImageSizeEditField.Value,digitsPattern),extract(num2str(1:subFolderCount,' %02.f'),digitsPattern));
                else
                    prepareDataset(fullfile(app.OutputDirectory,"Classification"),app.DatasetSourceEditField.Value,[227,227],extract(num2str(1:subFolderCount,' %02.f'),digitsPattern))
                end
            end

        end

        % Button pushed function: ClearAllButton, ClearMemoryButton
        function ClearMemoryButtonPushed(app, event)
            answer = questdlg("Are you sure you want to clear all variables? This will keep your chosen settings but remove all variables from memory, any unsaved work will be lost.","Are you sure?","Continue","Cancel","Cancel");
            if answer == "Continue"
                clearvars -except app app.ZooplanktonAppUIFigure
            end
        end

        % Value changed function: MorphOpt1
        function MorphOpt1ValueChanged(app, event)
            value = app.MorphOpt1.Value;
            app.MorphOptions = [{value} {app.MorphOpt2.Value} {lower(app.StructureDropDown.Value)}];
        end

        % Value changed function: MorphOpt2
        function MorphOpt2ValueChanged(app, event)
            value = app.MorphOpt2.Value;
            app.MorphOptions = [{app.MorphOpt1.Value} {value} {lower(app.StructureDropDown.Value)}];
        end

        % Button pushed function: BeginContinuetoSegmentationButton
        function BeginContinuetoSegmentationButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.SegmentationTab;
            app.instructionTicket = cat(1,app.instructionTicket,"End Prep","StartSeg","SegInputDirectory,PipedInput");
            app.DirectorySourceEditField.Value = "Piped from PreProcessing";
        end

        % Callback function: not associated with a component
        function BrowseButton_4Pushed(app, event)
            app.ZooplanktonAppUIFigure.WindowStyle = "normal";
            location = uigetdir('.');
            app.ZooplanktonAppUIFigure.WindowStyle = "alwaysontop";
            if ~isequal(location,0)
                app.DatasetSourceEditField.Value = location;
            end
        end

        % Callback function: not associated with a component
        function DatasetSourceEditFieldValueChanged(app, event)
            value = app.DatasetSourceEditField.Value;
            if exist(value)
            strs = strsplit(value,'/');
            app.rawImds = strs(end);
            elseif ~isempty(value)
                app.ZooplanktonAppUIFigure.WindowStyle = "normal";
                uialert(app.ZooplanktonAppUIFigure,"File not found, please provide valid path.","Invalid File Path")
                app.ZooplanktonAppUIFigure.WindowStyle = "alwaysontop";
            end  
        end

        % Value changed function: ClassIdentifiersEditField
        function ClassIdentifiersEditFieldValueChanged(app, event)
            value = app.ClassIdentifiersEditField.Value;
            if contains(value,"[") & contains(value,",") & contains(value,"]")
                str1 = extractBetween(value,"[",",");
                str2 = extractBetween(value,",","]");
                string(str1)
                strsplit(string(str2),",")
                app.ClassNames = [string(str1),strsplit(string(str2),",")];
                app.ZooplanktonAppUIFigure.WindowStyle = "normal";
                answer = questdlg(strcat(sprintf("ClassNames read in as:"),sprintf(" %s,",app.ClassNames)," Is this correct?"),'Correct Identifiers?','Yes','No','Yes');
                app.ZooplanktonAppUIFigure.WindowStyle = "alwaysontop";
            elseif ~isempty(value)
                uialert(app.ZooplanktonAppUIFigure,"Classnames are in an unknown format, please ensure they are in the form '[Var1,Var2,Var3,...]'.","Invalid Format")
            end
        end

        % Value changed function: FinalImageSizeEditField
        function FinalImageSizeEditFieldValueChanged(app, event)
            value = app.FinalImageSizeEditField.Value;
            strs = extract(value,digitsPattern);
            if ~isempty(strs)
                app.ZooplanktonAppUIFigure.WindowStyle = "normal";
                answer = questdlg(strcat(sprintf("Image size read in as:"),sprintf(" %s,",string(strs))," Is this correct?"),'Correct Image Size?','Yes','No','Yes');
                app.ZooplanktonAppUIFigure.WindowStyle = "alwaysontop";
            end
        end

        % Callback function
        function BeginFlatFieldButtonPushed2(app, event)
            
        end

        % Callback function: not associated with a component
        function VideoSourceEditField_2ValueChanged2(app, event)
            value = app.VideoSourceEditField_2.Value;
            if exist(value)
            app.Image2.Visible = 'on';
            
            app.rawVid = read_avi(value);
            app.Image2.ImageSource = cat(3,app.rawVid(:,:,1),app.rawVid(:,:,1),app.rawVid(:,:,1));
            app.GenerateCalibrationFrameButton.Enable = "on";
            strs = strsplit(value,'/');
            app.rawVidFilePath = strs(end);
            elseif ~isempty(value)
                uialert(app.ZooplanktonAppUIFigure,"File not found, please provide valid path.","Invalid File Path")
            end    
        end

        % Value changed function: SavesmallimagesCheckBox
        function SavesmallimagesCheckBoxValueChanged(app, event)

        end

        % Callback function: not associated with a component
        function UnsortedImagesEditFieldValueChanged(app, event)
        value = app.UnsortedImagesEditField.Value;
            if exist(value)
                app.imds = imageDatastore(location,"IncludeSubfolders",true,"LabelSource","foldernames");
                if app.TrainedNetworkDropDown.Value ~= "Select..."
                    inputSize = app.net.Layers(1).InputSize;
                    if any(size(app.imds.preview) ~= [inputSize(1),inputSize(2)])

                        answer = uiconfirm(app.ZooplanktonAppUIFigure,sprintf("One or more dimensions are invalid, images must be [%d %d] for chosen network. Auto resize images?",inputSize(1),inputSize(2)),"Invalid Image Size","Options",{'Yes','No'},"DefaultOption","Yes");
                        if answer == "Yes"
                            prepareDataset(value,[inputSize(1),inputSize(2)],"1")
                            app.imds = imageDatastore('./PreparedDataset',"IncludeSubfolders",true,"LabelSource","foldernames");
                            app.ClassifyImagesButton.Enable = "on";
                            app.UnsortedImagesEditField.Value = "./PreparedDataset";
                        else
                            uialert(app.ZooplanktonAppUIFigure,"Please choose a new network or manually prepare images in the 'Prepare Dataset' panel.")
                        end
                    else
                        app.ClassifyImagesButton.Enable = "on";
                    end
                end
            elseif ~isempty(value)
                uialert(app.ZooplanktonAppUIFigure,"File not found, please provide valid path.","Invalid File Path")
            end
        end

        % Value changed function: CheckMovementCheckBox
        function CheckMovementCheckBoxValueChanged(app, event)
            value = app.CheckMovementCheckBox.Value;
            if value
                app.FrameDepthSlider.Enable = "on";
                app.FrameDepthSliderLabel.Enable = "on";
            else
                app.FrameDepthSlider.Enable = "off";
                app.FrameDepthSliderLabel.Enable = "off";
            end
        end

        % Callback function
        function ValidateButtonPushed(app, event)
            startFrame = app.EditField.Value;
            endFrame = app.EditField_2.Value;
            app.boxedVideo = insert_boxesv3(app.vid(:,:,startFrame:endFrame),app.CSVDirectory);
            app.ZooplanktonAppUIFigure.WindowStyle = "normal";
            boxedVideoValidate(app.boxedVideo);
            app.ZooplanktonAppUIFigure.WindowStyle = "alwaysontop";
        end

        % Value changed function: EditField_10
        function EditField_10ValueChanged(app, event)
            value = app.EditField_10.Value;
            app.EditField_10.Value = ceil(value);
            app.MaxFrequencySlider.Value = ceil(value);
        end

        % Value changed function: MaxFrequencySlider
        function MaxFrequencySliderValueChanged(app, event)
            value = app.MaxFrequencySlider.Value;
            app.EditField_10.Value = ceil(value);
            app.MaxFrequencySlider.Value = ceil(value);
        end

        % Value changed function: MinAreaSlider
        function MinAreaSliderValueChanged(app, event)
            value = app.MinAreaSlider.Value;
            app.MinAreaSlider.Value = ceil(value);
            app.EditField_11.Value = ceil(value);
        end

        % Value changed function: EditField_11
        function EditField_11ValueChanged(app, event)
            value = app.EditField_11.Value;
            app.MinAreaSlider.Value = ceil(value);
            app.EditField_11.Value = ceil(value);            
        end

        % Value changed function: ExpansionDistanceSlider
        function ExpansionDistanceSliderValueChanged(app, event)
            value = app.ExpansionDistanceSlider.Value;
            app.ExpansionDistanceSlider.Value = ceil(value);
            app.EditField_12.Value = ceil(value);
        end

        % Value changed function: EditField_12
        function EditField_12ValueChanged(app, event)
            value = app.EditField_12.Value;
            app.ExpansionDistanceSlider.Value = ceil(value);
            app.EditField_12.Value = ceil(value);            
        end

        % Value changed function: CropPaddingSlider
        function CropPaddingSliderValueChanged(app, event)
            value = app.CropPaddingSlider.Value;
            app.CropPaddingSlider.Value = ceil(value);
            app.EditField_13.Value = ceil(value);
        end

        % Value changed function: EditField_13
        function EditField_13ValueChanged(app, event)
            value = app.EditField_13.Value;
            app.CropPaddingSlider.Value = ceil(value);
            app.EditField_13.Value = ceil(value);
        end

        % Value changed function: BinaryThresholdSlider
        function BinaryThresholdSliderValueChanged(app, event)
            value = app.BinaryThresholdSlider.Value;
            app.EditField_14.Value = value;
        end

        % Value changed function: EditField_14
        function EditField_14ValueChanged(app, event)
            value = app.EditField_14.Value;
            app.BinaryThresholdSlider.Value = value;
        end

        % Value changed function: ParallelCheckBox
        function ParallelCheckBoxValueChanged(app, event)
            value = app.ParallelCheckBox.Value;
            if value
                app.DropDown.Enable = "on";
                app.EditField_15.Enable = "on";
            else
                app.DropDown.Enable = "off";
                app.EditField_15.Enable = "off";
            end
        end

        % Button pushed function: StartRunButton
        function StartRunButtonPushed(app, event)
            

        end

        % Callback function: not associated with a component
        function ExportTrainingStatsButtonPushed(app, event)
            writematrix(app.trainingStats,fullfile(app.OutputDirectory,"Classification",strcat("TrainingStats_",string(datetime("today","Format",'ddMMyy')),".csv")));

        end

        % Value changed function: DirectorySourceEditField
        function DirectorySourceEditFieldValueChanged(app, event)
            value = app.DirectorySourceEditField.Value;
            if exist(value)
            app.Image2.Visible = 'on';
            
            strs = strsplit(value,'/');
            app.rawVidFilePath = strs(end);
            elseif ~isempty(value)
                uialert(app.ZooplanktonAppUIFigure,"Folder not found, please provide valid path.","Invalid File Path")
            end    
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ZooplanktonAppUIFigure = uifigure('Visible', 'off');
            app.ZooplanktonAppUIFigure.Name = 'Zooplankton App';
            app.TabGroup = uitabgroup(app.ZooplanktonAppUIFigure);
            app.PreprocessingTab = uitab(app.TabGroup);
            app.PreprocessingTab.Title = 'Preprocessing';
            app.GridLayout2 = uigridlayout(app.PreprocessingTab);
            app.Image2 = uiaxes(app.GridLayout2);
            zlabel(app.Image2, 'Z')
            app.Image2.Visible = 'off';
            app.FlatFieldPanel = uipanel(app.GridLayout2);
            app.FlatFieldPanel.Title = 'Flat-Field';
            app.GridLayout7 = uigridlayout(app.FlatFieldPanel);
            app.FlatFieldingMethodDropDownLabel = uilabel(app.GridLayout7);
            app.FlatFieldingMethodDropDownLabel.Text = 'Flat-Fielding Method';
            app.FlatFieldingMethodDropDown = uidropdown(app.GridLayout7);
            app.FlatFieldingMethodDropDown.Items = {'Standard', 'Dynamic', 'Precise'};
            app.FlatFieldingMethodDropDown.Editable = 'on';
            app.FlatFieldingMethodDropDown.Value = 'Standard';
            app.BrightnessSliderLabel = uilabel(app.GridLayout7);
            app.BrightnessSliderLabel.Text = 'Brightness';
            app.BrightnessSlider = uislider(app.GridLayout7);
            app.BrightnessSlider.Limits = [0 2];
            app.BrightnessSlider.Value = 1;
            app.EditField_3 = uieditfield(app.GridLayout7, 'numeric');
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.EditField_3.Value = 1;
            app.FlatFieldButton = uibutton(app.GridLayout7, 'push');
            app.FlatFieldButton.ButtonPushedFcn = createCallbackFcn(app, @FlatFieldButtonPushed, true);
            app.FlatFieldButton.Text = 'Flat Field';
            app.ContrastToolsPanel = uipanel(app.GridLayout2);
            app.ContrastToolsPanel.Tooltip = {'Tools to Adjust Contrast'};
            app.ContrastToolsPanel.Title = 'Contrast Tools';
            app.GridLayout9 = uigridlayout(app.ContrastToolsPanel);
            app.SharpenLabel = uilabel(app.GridLayout9);
            app.SharpenLabel.Text = 'Sharpen';
            app.SharpRadSpinner = uieditfield(app.GridLayout9, 'numeric');
            app.SharpRadSpinner.Enable = 'off';
            app.SharpRadSpinner.Value = 1;
            app.SharpAmountSpinner = uieditfield(app.GridLayout9, 'numeric');
            app.SharpAmountSpinner.Enable = 'off';
            app.SharpAmountSpinner.Value = 0.8;
            app.SharpThresholdSpinner = uieditfield(app.GridLayout9, 'numeric');
            app.SharpThresholdSpinner.Enable = 'off';
            app.RadiusLabel = uilabel(app.GridLayout9);
            app.RadiusLabel.Text = 'Radius';
            app.AmountLabel = uilabel(app.GridLayout9);
            app.AmountLabel.Text = 'Amount';
            app.ThresholdLabel = uilabel(app.GridLayout9);
            app.ThresholdLabel.Text = 'Threshold';
            app.SharpenButton = uibutton(app.GridLayout9, 'push');
            app.SharpenButton.ButtonPushedFcn = createCallbackFcn(app, @SharpenButtonPushed, true);
            app.SharpenButton.Text = 'Apply';
            app.AdjustLabel = uilabel(app.GridLayout9);
            app.AdjustLabel.Text = 'Adjust';
            app.LowHighInLabel = uilabel(app.GridLayout9);
            app.LowHighInLabel.Text = 'Low/High In';
            app.LowHighOutLabel = uilabel(app.GridLayout9);
            app.LowHighOutLabel.Text = 'Low/High Out';
            app.AdjustButton = uibutton(app.GridLayout9, 'push');
            app.AdjustButton.ButtonPushedFcn = createCallbackFcn(app, @AdjustButtonPushed, true);
            app.AdjustButton.Text = 'Apply';
            app.LaplaceFilterLabel = uilabel(app.GridLayout9);
            app.LaplaceFilterLabel.Tooltip = {'Sigma: [0,1],  Amplitude of edges'; 'Alpha: <1) Increases detail, =1) Unchanged, >1) Smoothes detail'};
            app.LaplaceFilterLabel.Text = 'Laplace Filter';
            app.Sigma = uieditfield(app.GridLayout9, 'numeric');
            app.Sigma.Value = 1;
            app.Alpha = uieditfield(app.GridLayout9, 'numeric');
            app.Alpha.Value = 1;
            app.LaplaceFiltButton = uibutton(app.GridLayout9, 'push');
            app.LaplaceFiltButton.ButtonPushedFcn = createCallbackFcn(app, @LaplaceFiltButtonPushed, true);
            app.LaplaceFiltButton.Text = 'Apply';
            app.BilateralFilterLabel = uilabel(app.GridLayout9);
            app.BilateralFilterLabel.Text = 'Bilateral Filter';
            app.BilatSigma = uieditfield(app.GridLayout9, 'numeric');
            app.BilatSigma.Enable = 'off';
            app.BilatSigma.Value = 1;
            app.DoSLabel = uilabel(app.GridLayout9);
            app.DoSLabel.Text = 'DoS';
            app.SigmaLabel = uilabel(app.GridLayout9);
            app.SigmaLabel.Text = 'Sigma';
            app.BilatFiltButton = uibutton(app.GridLayout9, 'push');
            app.BilatFiltButton.ButtonPushedFcn = createCallbackFcn(app, @BilatFiltButtonPushed, true);
            app.BilatFiltButton.Text = 'Apply';
            app.SigmaLabel_2 = uilabel(app.GridLayout9);
            app.SigmaLabel_2.Text = 'Sigma';
            app.AlphaLabel = uilabel(app.GridLayout9);
            app.AlphaLabel.Text = 'Alpha';
            app.DefaultCheckBox_2 = uicheckbox(app.GridLayout9);
            app.DefaultCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @DefaultCheckBox_2ValueChanged, true);
            app.DefaultCheckBox_2.Text = 'Default';
            app.DefaultCheckBox_2.Value = true;
            app.IntensityLevelsLabel = uilabel(app.GridLayout9);
            app.IntensityLevelsLabel.Text = 'Intensity Levels';
            app.DefaultCheckBox = uicheckbox(app.GridLayout9);
            app.DefaultCheckBox.ValueChangedFcn = createCallbackFcn(app, @DefaultCheckBoxValueChanged, true);
            app.DefaultCheckBox.Text = 'Default';
            app.DefaultCheckBox.Value = true;
            app.DefaultCheckBox_3 = uicheckbox(app.GridLayout9);
            app.DefaultCheckBox_3.ValueChangedFcn = createCallbackFcn(app, @DefaultCheckBox_3ValueChanged, true);
            app.DefaultCheckBox_3.Text = 'Default';
            app.DefaultCheckBox_3.Value = true;
            app.DefaultCheckBox_4 = uicheckbox(app.GridLayout9);
            app.DefaultCheckBox_4.ValueChangedFcn = createCallbackFcn(app, @DefaultCheckBox_4ValueChanged, true);
            app.DefaultCheckBox_4.Text = 'Default';
            app.DefaultCheckBox_4.Value = true;
            app.Intensity = uieditfield(app.GridLayout9, 'text');
            app.Intensity.InputType = 'alphanumerics';
            app.Intensity.Enable = 'off';
            app.Intensity.Placeholder = 'auto';
            app.LowOut = uieditfield(app.GridLayout9, 'text');
            app.LowOut.Enable = 'off';
            app.LowOut.Placeholder = 'auto';
            app.HighOut = uieditfield(app.GridLayout9, 'text');
            app.HighOut.Enable = 'off';
            app.HighOut.Placeholder = 'auto';
            app.LowIn = uieditfield(app.GridLayout9, 'text');
            app.LowIn.Enable = 'off';
            app.LowIn.Placeholder = 'auto';
            app.HighIn = uieditfield(app.GridLayout9, 'text');
            app.HighIn.Enable = 'off';
            app.HighIn.Placeholder = 'auto';
            app.DegreeSmooth = uieditfield(app.GridLayout9, 'text');
            app.DegreeSmooth.Enable = 'off';
            app.DegreeSmooth.Placeholder = 'auto';
            app.MorphologicalOperationsPanel = uipanel(app.GridLayout2);
            app.MorphologicalOperationsPanel.Title = 'Morphological Operations';
            app.GridLayout10 = uigridlayout(app.MorphologicalOperationsPanel);
            app.StructureDropDownLabel = uilabel(app.GridLayout10);
            app.StructureDropDownLabel.Text = 'Structure';
            app.StructureDropDown = uidropdown(app.GridLayout10);
            app.StructureDropDown.Items = {'Select...', 'Diamond', 'Disk', 'Octagon', 'Line', 'Rectangle'};
            app.StructureDropDown.ValueChangedFcn = createCallbackFcn(app, @StructureDropDownValueChanged, true);
            app.StructureDropDown.Value = 'Select...';
            app.MorphOpt1 = uispinner(app.GridLayout10);
            app.MorphOpt1.ValueChangedFcn = createCallbackFcn(app, @MorphOpt1ValueChanged, true);
            app.MorphOpt1.Visible = 'off';
            app.MorphOpt2 = uispinner(app.GridLayout10);
            app.MorphOpt2.ValueChangedFcn = createCallbackFcn(app, @MorphOpt2ValueChanged, true);
            app.MorphOpt2.Visible = 'off';
            app.DilateButton = uibutton(app.GridLayout10, 'push');
            app.DilateButton.ButtonPushedFcn = createCallbackFcn(app, @DilateButtonPushed, true);
            app.DilateButton.Enable = 'off';
            app.DilateButton.Text = 'Dilate';
            app.ErodeButton = uibutton(app.GridLayout10, 'push');
            app.ErodeButton.ButtonPushedFcn = createCallbackFcn(app, @ErodeButtonPushed, true);
            app.ErodeButton.Enable = 'off';
            app.ErodeButton.Text = 'Erode';
            app.OpenButton = uibutton(app.GridLayout10, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Enable = 'off';
            app.OpenButton.Text = 'Open';
            app.CloseButton = uibutton(app.GridLayout10, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Enable = 'off';
            app.CloseButton.Text = 'Close';
            app.MorphOpt1Label = uilabel(app.GridLayout10);
            app.MorphOpt1Label.Visible = 'off';
            app.MorphOpt1Label.Text = 'MorphOpt1';
            app.MorphOpt2Label = uilabel(app.GridLayout10);
            app.MorphOpt2Label.Visible = 'off';
            app.MorphOpt2Label.Text = 'MorphOpt2';
            app.Panel = uipanel(app.GridLayout2);
            app.GridLayout8 = uigridlayout(app.Panel);
            app.TextArea = uitextarea(app.GridLayout8);
            app.TextArea.Editable = 'off';
            app.UndoLastButton = uibutton(app.GridLayout8, 'push');
            app.UndoLastButton.ButtonPushedFcn = createCallbackFcn(app, @UndoLastButtonPushed, true);
            app.UndoLastButton.Text = 'Undo Last';
            app.ClearAllButton = uibutton(app.GridLayout8, 'push');
            app.ClearAllButton.ButtonPushedFcn = createCallbackFcn(app, @ClearMemoryButtonPushed, true);
            app.ClearAllButton.Text = 'Clear All';
            app.BeginContinuetoSegmentationButton = uibutton(app.GridLayout8, 'push');
            app.BeginContinuetoSegmentationButton.ButtonPushedFcn = createCallbackFcn(app, @BeginContinuetoSegmentationButtonPushed, true);
            app.BeginContinuetoSegmentationButton.Text = 'Begin & Continue to Segmentation';
            app.BeginPreProcessingButton = uibutton(app.GridLayout8, 'push');
            app.BeginPreProcessingButton.Text = 'Begin Pre-Processing';
            app.LoadingImage = uiimage(app.GridLayout2);
            app.LoadingImage.Visible = 'off';
            app.LoadingImage.ImageSource = 'Wedges@1x-4.0s-200px-200px(1).gif';
            app.Panel_3 = uipanel(app.GridLayout2);
            app.GridLayout15 = uigridlayout(app.Panel_3);
            app.BrowseButton_3 = uibutton(app.GridLayout15, 'push');
            app.BrowseButton_3.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_3Pushed, true);
            app.BrowseButton_3.Text = 'Browse...';
            app.DirectorySourceEditFieldLabel = uilabel(app.GridLayout15);
            app.DirectorySourceEditFieldLabel.Text = 'Directory Source';
            app.DirectorySourceEditField = uieditfield(app.GridLayout15, 'text');
            app.DirectorySourceEditField.ValueChangedFcn = createCallbackFcn(app, @DirectorySourceEditFieldValueChanged, true);
            app.SegmentationTab = uitab(app.TabGroup);
            app.SegmentationTab.Title = 'Segmentation';
            app.GridLayout11 = uigridlayout(app.SegmentationTab);
            app.SegmentationParametersPanel = uipanel(app.GridLayout11);
            app.SegmentationParametersPanel.Title = 'Segmentation Parameters';
            app.GridLayout14 = uigridlayout(app.SegmentationParametersPanel);
            app.BeginSegmentationButton = uibutton(app.GridLayout14, 'push');
            app.BeginSegmentationButton.ButtonPushedFcn = createCallbackFcn(app, @BeginSegmentationButtonPushed, true);
            app.BeginSegmentationButton.Text = 'Begin Segmentation';
            app.SettoReccomendedButton = uibutton(app.GridLayout14, 'push');
            app.SettoReccomendedButton.ButtonPushedFcn = createCallbackFcn(app, @SettoReccomendedButtonPushed, true);
            app.SettoReccomendedButton.Text = 'Set to Reccomended';
            app.BeginContinuetoClassificationButton = uibutton(app.GridLayout14, 'push');
            app.BeginContinuetoClassificationButton.ButtonPushedFcn = createCallbackFcn(app, @BeginContinuetoClassificationButtonPushed, true);
            app.BeginContinuetoClassificationButton.Text = 'Begin & Continue to Classification';
            app.EditField = uieditfield(app.GridLayout14, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Enable = 'off';
            app.EditField.Value = 1;
            app.EditField_2 = uieditfield(app.GridLayout14, 'numeric');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_2.Enable = 'off';
            app.EditField_2.Value = 100;
            app.ExpansionDistanceSliderLabel = uilabel(app.GridLayout14);
            app.ExpansionDistanceSliderLabel.Text = 'Expansion Distance';
            app.ExpansionDistanceSlider = uislider(app.GridLayout14);
            app.ExpansionDistanceSlider.Limits = [0 20];
            app.ExpansionDistanceSlider.ValueChangedFcn = createCallbackFcn(app, @ExpansionDistanceSliderValueChanged, true);
            app.MinAreaSliderLabel = uilabel(app.GridLayout14);
            app.MinAreaSliderLabel.Text = 'Min Area';
            app.MinAreaSlider = uislider(app.GridLayout14);
            app.MinAreaSlider.Limits = [0 30];
            app.MinAreaSlider.ValueChangedFcn = createCallbackFcn(app, @MinAreaSliderValueChanged, true);
            app.MaxFrequencySliderLabel = uilabel(app.GridLayout14);
            app.MaxFrequencySliderLabel.Text = 'Max Frequency';
            app.MaxFrequencySlider = uislider(app.GridLayout14);
            app.MaxFrequencySlider.Limits = [0 1];
            app.MaxFrequencySlider.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.MaxFrequencySlider.ValueChangedFcn = createCallbackFcn(app, @MaxFrequencySliderValueChanged, true);
            app.CropPaddingSliderLabel = uilabel(app.GridLayout14);
            app.CropPaddingSliderLabel.Text = 'Crop Padding';
            app.CropPaddingSlider = uislider(app.GridLayout14);
            app.CropPaddingSlider.Limits = [0 50];
            app.CropPaddingSlider.ValueChangedFcn = createCallbackFcn(app, @CropPaddingSliderValueChanged, true);
            app.FrameDepthSliderLabel = uilabel(app.GridLayout14);
            app.FrameDepthSliderLabel.Enable = 'off';
            app.FrameDepthSliderLabel.Text = 'Frame Depth';
            app.FrameDepthSlider = uislider(app.GridLayout14);
            app.FrameDepthSlider.Limits = [0 10];
            app.FrameDepthSlider.Enable = 'off';
            app.BinaryThresholdSliderLabel = uilabel(app.GridLayout14);
            app.BinaryThresholdSliderLabel.Text = 'Binary Threshold';
            app.BinaryThresholdSlider = uislider(app.GridLayout14);
            app.BinaryThresholdSlider.Limits = [0 1];
            app.BinaryThresholdSlider.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.BinaryThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @BinaryThresholdSliderValueChanged, true);
            app.EditField_8 = uieditfield(app.GridLayout14, 'text');
            app.EditField_8.Enable = 'off';
            app.EditField_8.Tooltip = {'Defaults to 400px if blank'};
            app.EditField_8.Placeholder = 'Min Length';
            app.EditField_9 = uieditfield(app.GridLayout14, 'text');
            app.EditField_9.Enable = 'off';
            app.EditField_9.Tooltip = {'Defaults to 400px if blank'};
            app.EditField_9.Placeholder = 'Min Width';
            app.SavesmallimagesCheckBox = uicheckbox(app.GridLayout14);
            app.SavesmallimagesCheckBox.ValueChangedFcn = createCallbackFcn(app, @SavesmallimagesCheckBoxValueChanged, true);
            app.SavesmallimagesCheckBox.Text = 'Save small images?';
            app.StartFrameLabel = uilabel(app.GridLayout14);
            app.StartFrameLabel.Enable = 'off';
            app.StartFrameLabel.Text = 'Start Frame';
            app.EndFrameLabel = uilabel(app.GridLayout14);
            app.EndFrameLabel.Enable = 'off';
            app.EndFrameLabel.Text = 'End Frame';
            app.CheckMovementCheckBox = uicheckbox(app.GridLayout14);
            app.CheckMovementCheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckMovementCheckBoxValueChanged, true);
            app.CheckMovementCheckBox.Text = 'Check Movement?';
            app.EditField_10 = uieditfield(app.GridLayout14, 'numeric');
            app.EditField_10.ValueChangedFcn = createCallbackFcn(app, @EditField_10ValueChanged, true);
            app.EditField_11 = uieditfield(app.GridLayout14, 'numeric');
            app.EditField_11.ValueChangedFcn = createCallbackFcn(app, @EditField_11ValueChanged, true);
            app.EditField_12 = uieditfield(app.GridLayout14, 'numeric');
            app.EditField_12.ValueChangedFcn = createCallbackFcn(app, @EditField_12ValueChanged, true);
            app.EditField_13 = uieditfield(app.GridLayout14, 'numeric');
            app.EditField_13.ValueChangedFcn = createCallbackFcn(app, @EditField_13ValueChanged, true);
            app.EditField_14 = uieditfield(app.GridLayout14, 'numeric');
            app.EditField_14.ValueChangedFcn = createCallbackFcn(app, @EditField_14ValueChanged, true);
            app.DropDown = uidropdown(app.GridLayout14);
            app.DropDown.Items = {'Processes', 'Threads'};
            app.DropDown.Enable = 'off';
            app.DropDown.Value = 'Processes';
            app.ParallelCheckBox = uicheckbox(app.GridLayout14);
            app.ParallelCheckBox.ValueChangedFcn = createCallbackFcn(app, @ParallelCheckBoxValueChanged, true);
            app.ParallelCheckBox.Text = 'Parallel?';
            app.EditField_15 = uieditfield(app.GridLayout14, 'text');
            app.EditField_15.Enable = 'off';
            app.EditField_15.Placeholder = 'NumCores';
            app.FullVideoCheckBox = uicheckbox(app.GridLayout14);
            app.FullVideoCheckBox.Text = 'Full Video?';
            app.FullVideoCheckBox.Value = true;
            app.DirectorySourceEditField_2 = uieditfield(app.GridLayout14, 'text');
            app.DirectorySourceEditField_2Label = uilabel(app.GridLayout14);
            app.DirectorySourceEditField_2Label.Text = 'Directory Source';
            app.BrowseButton = uibutton(app.GridLayout14, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse...';
            app.ClassificationTab = uitab(app.TabGroup);
            app.ClassificationTab.Title = 'Classification';
            app.GridLayout3 = uigridlayout(app.ClassificationTab);
            app.ClassifyZooplanktonImagesPanel = uipanel(app.GridLayout3);
            app.ClassifyZooplanktonImagesPanel.Title = 'Classify Zooplankton Images';
            app.GridLayout5 = uigridlayout(app.ClassifyZooplanktonImagesPanel);
            app.TrainedNetworkDropDown = uidropdown(app.GridLayout5);
            app.TrainedNetworkDropDown.Items = {'Select...', 'Adapted ResNet', 'Browse...'};
            app.TrainedNetworkDropDown.ValueChangedFcn = createCallbackFcn(app, @TrainedNetworkDropDownValueChanged, true);
            app.TrainedNetworkDropDown.Value = 'Select...';
            app.TrainedNetworkDropDownLabel = uilabel(app.GridLayout5);
            app.TrainedNetworkDropDownLabel.Text = 'Trained Network';
            app.ClassifyImagesButton = uibutton(app.GridLayout5, 'push');
            app.ClassifyImagesButton.ButtonPushedFcn = createCallbackFcn(app, @ClassifyImagesButtonPushed, true);
            app.ClassifyImagesButton.Text = 'Classify Images';
            app.ConfidenceThresholdSliderLabel = uilabel(app.GridLayout5);
            app.ConfidenceThresholdSliderLabel.Text = 'Confidence Threshold';
            app.ConfidenceThresholdSlider = uislider(app.GridLayout5);
            app.ConfidenceThresholdSlider.Limits = [0 1];
            app.ConfidenceThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @ConfidenceThresholdSliderValueChanged, true);
            app.EditField_7 = uieditfield(app.GridLayout5, 'numeric');
            app.EditField_7.ValueChangedFcn = createCallbackFcn(app, @EditField_7ValueChanged, true);
            app.histPanel = uipanel(app.GridLayout5);
            app.GridLayout12 = uigridlayout(app.histPanel);
            app.histAxis = uiaxes(app.GridLayout12);
            title(app.histAxis, 'Class Distribution')
            xlabel(app.histAxis, 'Class ID')
            zlabel(app.histAxis, 'Z')
            app.histAxis.YTick = [];
            app.histAxis.Visible = 'off';
            app.Image4 = uiimage(app.GridLayout12);
            app.Image4.Visible = 'off';
            app.Image4.ImageSource = 'Wedges@1x-4.0s-200px-200px(1).gif';
            app.PreparedDatasetsDirectoryEditFieldLabel = uilabel(app.GridLayout5);
            app.PreparedDatasetsDirectoryEditFieldLabel.Text = 'Prepared Datasets Directory';
            app.PreparedDatasetsDirectoryEditField = uieditfield(app.GridLayout5, 'text');
            app.BrowseButton_4 = uibutton(app.GridLayout5, 'push');
            app.BrowseButton_4.Text = 'Browse';
            app.PrepareDatasetPanel = uipanel(app.GridLayout3);
            app.PrepareDatasetPanel.Title = 'Prepare Dataset';
            app.GridLayout6 = uigridlayout(app.PrepareDatasetPanel);
            app.ClassIdentifiersEditFieldLabel = uilabel(app.GridLayout6);
            app.ClassIdentifiersEditFieldLabel.Text = 'Class Identifiers';
            app.ClassIdentifiersEditField = uieditfield(app.GridLayout6, 'text');
            app.ClassIdentifiersEditField.ValueChangedFcn = createCallbackFcn(app, @ClassIdentifiersEditFieldValueChanged, true);
            app.ClassIdentifiersEditField.Tooltip = {'For custom Class ID, please use the format: [X,Y,Z,etc...]'};
            app.ClassIdentifiersEditField.Placeholder = 'Default [1,2,3,etc...]';
            app.FinalImageSizeEditFieldLabel = uilabel(app.GridLayout6);
            app.FinalImageSizeEditFieldLabel.Text = 'Final Image Size';
            app.FinalImageSizeEditField = uieditfield(app.GridLayout6, 'text');
            app.FinalImageSizeEditField.ValueChangedFcn = createCallbackFcn(app, @FinalImageSizeEditFieldValueChanged, true);
            app.FinalImageSizeEditField.Placeholder = 'Default [227,227]';
            app.PrepareImagesButton = uibutton(app.GridLayout6, 'push');
            app.PrepareImagesButton.ButtonPushedFcn = createCallbackFcn(app, @PrepareImagesButtonPushed, true);
            app.PrepareImagesButton.Text = 'Prepare Images';
            app.Panel_2 = uipanel(app.GridLayout6);
            app.GridLayout13 = uigridlayout(app.Panel_2);
            app.histAxes2 = uiaxes(app.GridLayout13);
            title(app.histAxes2, 'Dataset Distribution')
            xlabel(app.histAxes2, 'Class ID')
            app.histAxes2.Visible = 'off';
            app.Image5 = uiimage(app.GridLayout13);
            app.Image5.Visible = 'off';
            app.Image5.ImageSource = 'Wedges@1x-4.0s-200px-200px(1).gif';
            app.RawDatasetDirectoriesEditFieldLabel = uilabel(app.GridLayout6);
            app.RawDatasetDirectoriesEditFieldLabel.Text = 'Raw Dataset Directories';
            app.RawDatasetDirectoriesEditField = uieditfield(app.GridLayout6, 'text');
            app.BrowseButton_5 = uibutton(app.GridLayout6, 'push');
            app.BrowseButton_5.Text = 'Browse';
            app.ClearMemoryButton = uibutton(app.ZooplanktonAppUIFigure, 'push');
            app.ClearMemoryButton.ButtonPushedFcn = createCallbackFcn(app, @ClearMemoryButtonPushed, true);
            app.ClearMemoryButton.Text = 'Clear Memory';
            app.StartRunButton = uibutton(app.ZooplanktonAppUIFigure, 'push');
            app.StartRunButton.ButtonPushedFcn = createCallbackFcn(app, @StartRunButtonPushed, true);
            app.StartRunButton.Text = 'Start Run';
            % Show the figure after all components are created
            app.ZooplanktonAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SegmentationAppV3_Batch

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ZooplanktonAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ZooplanktonAppUIFigure)
        end
    end
end