classdef SegmentationAppV3_BatchV2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout6                     matlab.ui.container.GridLayout
        SegmentSwitch                   matlab.ui.control.Switch
        SegmentationSwitchLabel         matlab.ui.control.Label
        DataSourceEditField             matlab.ui.control.EditField
        DataSourceEditFieldLabel        matlab.ui.control.Label
        ClassifySwitch                  matlab.ui.control.Switch
        ClassifySwitchLabel             matlab.ui.control.Label
        FlatFieldSwitch                 matlab.ui.control.Switch
        FlatFieldSwitchLabel            matlab.ui.control.Label
        BrowseButton                    matlab.ui.control.Button
        SegmentationParametersPanel_2   matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        ProgressAmount_2                matlab.ui.control.Label
        ProgressLabel_2                 matlab.ui.control.Label
        BinaryThresholdEditField        matlab.ui.control.NumericEditField
        BinaryThresholdEditFieldLabel   matlab.ui.control.Label
        CropPaddingEditField            matlab.ui.control.NumericEditField
        CropPaddingEditFieldLabel       matlab.ui.control.Label
        ExpansionDistanceEditField      matlab.ui.control.NumericEditField
        ExpansionDistanceEditFieldLabel  matlab.ui.control.Label
        MinAreaEditField                matlab.ui.control.NumericEditField
        MinAreaEditFieldLabel           matlab.ui.control.Label
        MaxFrequencyEditField           matlab.ui.control.NumericEditField
        MaxFrequencyEditFieldLabel      matlab.ui.control.Label
        SetToRecomendedButton           matlab.ui.control.Button
        SegmentationLamp                matlab.ui.control.Lamp
        parallelDropDown                matlab.ui.control.DropDown
        ParallelCheckBox                matlab.ui.control.CheckBox
        FrameDepthSlider                matlab.ui.control.Slider
        FrameDepthSliderLabel           matlab.ui.control.Label
        minHeight                       matlab.ui.control.EditField
        minWidth                        matlab.ui.control.EditField
        SeperateSmallImagesCheckBox     matlab.ui.control.CheckBox
        MovementCheckCheckBox           matlab.ui.control.CheckBox
        BinaryThresholdSlider           matlab.ui.control.Slider
        CropPaddingSlider               matlab.ui.control.Slider
        ExpansionDistanceSlider         matlab.ui.control.Slider
        MinAreaSlider                   matlab.ui.control.Slider
        MaxFrequencySlider              matlab.ui.control.Slider
        StartRunButton                  matlab.ui.control.Button
        ClassificationParametersPanel   matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        ProgressAmount_3                matlab.ui.control.Label
        ProgressLabel_3                 matlab.ui.control.Label
        UniformImageSizeEditField       matlab.ui.control.EditField
        UniformImageSizeEditFieldLabel  matlab.ui.control.Label
        ClassifyLamp                    matlab.ui.control.Lamp
        AnomalyDetectionCheckBox        matlab.ui.control.CheckBox
        IsotonicBinningCheckBox         matlab.ui.control.CheckBox
        PreTrainedNetworkDropDown       matlab.ui.control.DropDown
        PreTrainedNetworkDropDownLabel  matlab.ui.control.Label
        ConfidenceEditField             matlab.ui.control.NumericEditField
        ConfidenceThresholdSlider_2     matlab.ui.control.Slider
        ConfidenceThresholdSlider_2Label  matlab.ui.control.Label
        FlatFieldParametersPanel        matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        SaveFlatFieldedVideoCheckBox    matlab.ui.control.CheckBox
        ProgressAmount                  matlab.ui.control.Label
        ProgressLabel                   matlab.ui.control.Label
        BrightnessEditField             matlab.ui.control.NumericEditField
        FlatFieldLamp                   matlab.ui.control.Lamp
        FlatFieldingMethodDropDown_2Label  matlab.ui.control.Label
        BrightnessSlider                matlab.ui.control.Slider
        BrightnessSlider_2Label         matlab.ui.control.Label
        FlatFieldingMethodDropDown      matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        baseOutputDirectory
        netTable
        OutputDirectory
        % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            filePath = which('SegmentationAppV3_BatchV2.mlapp');
            answer = uiconfirm(app.UIFigure,'Where would you like to save the following outputs?','Output Directory',"Options",{'Current Folder','Choose Location'},"DefaultOption","Current Folder");

            if answer == "Current Folder"
                app.baseOutputDirectory = pwd;
            else
                app.baseOutputDirectory = uigetdir;
            end
            fileParts = strsplit(filePath,"\");
            basePath = fileParts(1);
            for z = 2:length(fileParts)-2
            basePath = fullfile(basePath,fileParts(z));
            end
            addpath(fullfile(basePath,"assets"))
            addpath(fullfile(basePath,"segmentationFunctions"))
            addpath(fullfile(basePath,"networkFunctions"))
            addpath(fullfile(basePath,"trainedNetworks"))
            addpath(fullfile(basePath,"miscFunctions"))
            addpath(fullfile(basePath,"videoProcessing"))
            % [previousData,runDate] = check4Dat(app.baseOutputDirectory,"ZooPlanktonBatchOutput");
            if exist(fullfile(app.baseOutputDirectory,strcat("ZooPlanktonBatchOutput_",string(datetime("today","Format",'MMddyy')))))
                k=1;
                while exist(fullfile(app.baseOutputDirectory,strcat("ZooPlanktonBatchOutput_",string(datetime("today","Format",'MMddyy')),"(",num2str(k),")")))
                    k = k + 1;
                end
                mkdir(fullfile(app.baseOutputDirectory,strcat("ZooPlanktonBatchOutput_",string(datetime("today","Format",'MMddyy')),"(",num2str(k),")")))
                app.OutputDirectory = (fullfile(app.baseOutputDirectory,strcat("ZooPlanktonBatchOutput_",string(datetime("today","Format",'MMddyy')),"(",num2str(k),")")));
            else
                mkdir(fullfile(app.baseOutputDirectory,strcat("ZooPlanktonBatchOutput_",string(datetime("today","Format",'MMddyy')))))
                app.OutputDirectory =(fullfile(app.baseOutputDirectory,strcat("ZooPlanktonBatchOutput_",string(datetime("today","Format",'MMddyy')))));
            end

            % if iscell(previousData(1))
            %     answer = uiconfirm(app.ZooplanktonAppUIFigure,sprintf('Previous run found on %s, load options selected?',string(datetime(runDate,"Format","MMM-d-yy"))),'Load .dat file',"Options",{'Load Run','Start Fresh'},"DefaultOption","Load Run");
            %     if answer == "Load Run"
            %         indicatorCol = previousData(:,1);
            %         if any(indicatorCol == "SEG")
            %             z = find(indicatorCol == "SEG") + 1;
            %             while true
            %                 if (indicatorCol(z)) == "END"
            %                     break;
            %                 end
            %                 if isnan(double(string(previousData(z,2))))
            %                     eval(strcat(previousData(z,1),"=","'",previousData(z,2),"';"));
            %                 else
            %                     eval(strcat(previousData(z,1),"=",previousData(z,2),";"));
            %                 end
            %                 z = z + 1;
            %             end
            %         end
            %         if any(indicatorCol == "PROC")
            %             z = find(indicatorCol == "PROC") + 1;
            %             while true
            %                 if (indicatorCol(z)) == "END"
            %                     break;
            %                 end
            %                 if isnan(double(string(previousData(z,2))))
            %                     eval(strcat(previousData(z,1),"=","'",previousData(z,2),"';"));
            %                 else
            %                     eval(strcat(previousData(z,1),"=",previousData(z,2),";"));
            %                 end
            %                 z = z + 1;
            %             end
            %         end
            %         if any(indicatorCol == "CLASS")
            %             z = find(indicatorCol == "CLASS") + 1;
            %             while true
            %                 if (indicatorCol(z)) == "END"
            %                     break;
            %                 end
            %                 if isnan(double(string(previousData(z,2))))
            %                     eval(strcat(previousData(z,1),"=","'",previousData(z,2),"';"));
            %                 else
            %                     eval(strcat(previousData(z,1),"=",previousData(z,2),";"));
            %                 end
            %                 z = z + 1;
            %             end
            %         end
            %     end
            % end


        end

        % Value changed function: FlatFieldSwitch
        function FlatFieldSwitchValueChanged(app, event)
            value = app.FlatFieldSwitch.Value;
            if value == "On"
                app.FlatFieldParametersPanel.Enable = "on";
            else
                app.FlatFieldParametersPanel.Enable = "off";
            end
        end

        % Value changed function: SegmentSwitch
        function SegmentSwitchValueChanged(app, event)
            value = app.SegmentSwitch.Value;
            if value == "On"
                app.SegmentationParametersPanel_2.Enable = "on";
            else
                app.SegmentationParametersPanel_2.Enable = "off";
            end
        end

        % Value changed function: ClassifySwitch
        function ClassifySwitchValueChanged(app, event)
            value = app.ClassifySwitch.Value;
            if value == "On"
                app.ClassificationParametersPanel.Enable = "on";
            else
                app.ClassificationParametersPanel.Enable = "off";
            end
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            app.UIFigure.WindowStyle = "normal";
            location = uigetdir();    
            app.UIFigure.WindowStyle = "alwaysontop";
            if ~isequal(location,0)
                app.DataSourceEditField.Value = location;
            end
        end

        % Value changed function: DataSourceEditField
        function DataSourceEditFieldValueChanged(app, event)
            value = app.DataSourceEditField.Value;
            if ~exist(value)
                uialert(app.UIFigure,"The file specified could not be found, please try a different path.","Invalid Path");
            end
        end

        % Button pushed function: StartRunButton
        function StartRunButtonPushed(app, event)
            h_vars = [app.MaxFrequencySlider.Value,app.MinAreaSlider.Value,app.ExpansionDistanceSlider.Value,app.CropPaddingSlider.Value,app.FrameDepthSlider.Value];
            if isempty(app.UniformImageSizeEditField.Value)
                imgSize = [229,229];
            else
                imgSize = extract(app.UniformImageSizeEditField.Value,digitsPattern);
            end
            if app.FlatFieldSwitch.Value == "On" & app.ClassifySwitch.Value == "On" & app.SegmentSwitch.Value == "Off"
                uialert(app.UIFigure,"Video input cannot be piped directly to classifier, please enable segmentation or classify with pre-segmented images.","Invalid Operation")
            else
                if app.FlatFieldSwitch.Value == "On"
                    app.FlatFieldLamp.Color = [1 1 0];
                    app.FlatFieldLamp.Tooltip = "Processing";
                    app.ProgressLabel.Visible = "on";
                    app.ProgressAmount.Visible = "on";
                    
                    batchFlatField(app.ProgressAmount,app.DataSourceEditField.Value,fullfile(app.OutputDirectory,"PreProcessingOutput"),app.FlatFieldingMethodDropDown.Value);
                    app.FlatFieldLamp.Color = [0 1 0];
                    app.FlatFieldLamp.Tooltip = "Complete";
                    if app.SegmentSwitch.Value == "On"
                        app.ProgressLabel_2.Visible = "on";
                        app.ProgressAmount_2.Visible = "on";
                        app.SegmentationLamp.Color = [1 1 0];
                        app.SegmentationLamp.Tooltip = "Processing";
                        % try
                            allDataSetPaths = batchSegment(app.ProgressAmount_2,fullfile(app.OutputDirectory,"PreProcessingOutput"),h_vars,app.BinaryThresholdSlider.Value,app.SeperateSmallImagesCheckBox.Value,app.minHeight.Value,app.minWidth.Value,fullfile(app.OutputDirectory,"SegmentationOutput"),app.ParallelCheckBox.Value,app.parallelDropDown.Value);
                        % catch ME
                        %     app.SegmentationLamp.Color = [1 0 0];
                        %     app.SegmentationLamp.Tooltip = "Error";
                        %     stackTrace = ME.stack.name;
                        %     stackLine = ME.stack.line;
                        %     errorMessage = ME.message;
                        %     uialert(app.UIFigure,strcat("Run resulted in error. Error message: ",string(stackTrace)," ",string(stackLine)," ",string(errorMessage)),"Error");
                        %     return
                        % end
                        app.SegmentationLamp.Color = [0 1 0];
                        app.SegmentationLamp.Tooltip = "Complete";
                        if app.ClassifySwitch.Value == "On"
                            app.ProgressLabel_3.Visible = "on";
                            app.ProgressAmount_3.Visible = "on";
                            app.ClassifyLamp.Color = [1 1 0];
                            app.ClassifyLamp.Tooltip = "Processing";
                            try
                            batchClassify(app.ProgressAmount_3,fullfile(app.OutputDirectory,"SegmentationOutput"),app.netTable,app.IsotonicBinningCheckBox.Value, ...
                                app.AnomalyDetectionCheckBox.Value, imgSize,app.ConfidenceThresholdSlider_2.Value,fullfile(app.OutputDirectory,"ClassificationOutput"));
                            catch ME
                                app.ClassifyLamp.Color = [1 0 0];
                                app.ClassifyLamp.Tooltip = "Error";
                                stackTrace = ME.stack.name;
                                stackLine = ME.stack.line;
                                errorMessage = ME.message;
                                uialert(app.UIFigure,strcat("Run resulted in error. Error message: ",string(stackTrace)," ",string(stackLine)," ",string(errorMessage)),"Error");
                                return
                            end
                                app.ClassifyLamp.Color = [0 1 0];
                                app.ClassifyLamp.Tooltip = "Complete";

                        end
                    end
                    if app.SaveFlatFieldedVideoCheckBox.Value == false
                        rmdir(fullfile(app.OutputDirectory,"PreProcessingOutput"),"s")
                    end
                elseif app.SegmentSwitch.Value == "On"
                        allDataSetPaths = batchSegment(app.ProgressAmount_2,app.DataSourceEditField.Value,h_vars,app.BinaryThresholdSlider.Value,app.SeperateSmallImagesCheckBox.Value,app.minHeight.Value,app.minWidth.Value,fullfile(app.OutputDirectory,"SegmentationOutput"),app.ParallelCheckBox.Value,app.parallelDropDown.Value);
                    if app.ClassifySwitch.Value == "On"
                            batchClassify(app.ProgressAmount_3,fullfile(app.OutputDirectory,"SegmentationOutput"),app.netTable,app.IsotonicBinningCheckBox.Value, ...
                                app.AnomalyDetectionCheckBox.Value, imgSize,app.ConfidenceThresholdSlider_2.Value,fullfile(app.OutputDirectory,"ClassificationOutput"));
                    end
                elseif app.ClassifySwitch.Value == "On"
                    app.ProgressLabel_3.Visible = "on";
                    app.ProgressAmount_3.Visible = "on";
                    app.ClassifyLamp.Color = [1 1 0];
                    app.ClassifyLamp.Tooltip = "Processing";
                    batchClassify(app.ProgressAmount_3,app.DataSourceEditField.Value,app.netTable,app.IsotonicBinningCheckBox.Value, ...
                        app.AnomalyDetectionCheckBox.Value, ...
                        imgSize,app.ConfidenceThresholdSlider_2.Value,fullfile(app.OutputDirectory,"ClassificationOutput"));
                    app.ClassifyLamp.Color = [0 1 0];
                    app.ClassifyLamp.Tooltip = "Complete";
                else
                    uialert(app.UIFigure,"No operations selected.","Invalid Operation")
                end
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            delete(app)
            
        end

        % Value changed function: PreTrainedNetworkDropDown
        function PreTrainedNetworkDropDownValueChanged(app, event)
            value = app.PreTrainedNetworkDropDown.Value;

            if value == "Adapted GoogLeNet"
                S = load("trainedNetworks/adaptedGoogLe.mat");
            elseif value == "Adapted ResNet"
                S = load("trainedNetworks/adaptedResNet.mat");
            elseif value == "Adapted DarkNet"
                S = load("trainedNetworks/adaptedDarkNet.mat");
            elseif value == "Adapted DenseNet"
                S = load("trainedNetworks/adaptedDenseNet.mat");
            elseif value == "Adapted Google365"
                S = load("trainedNetworks/adaptedGoogle365.mat");
            elseif value == "Adapted Xception"
                S = load("trainedNetworks/adaptedXception.mat");
            elseif value == "Import Custom"

            elseif value == "Binary Xception"
                S = load("trainedNetworks\adaptedXception_binary.mat");
            elseif value == "Binary ResNet"
                S = load("trainedNetworks\adaptedResnet_binary.mat");
            end
                app.netTable = S;


        end

        % Value changed function: BrightnessSlider
        function BrightnessSliderValueChanged(app, event)
            value = app.BrightnessSlider.Value;
            app.BrightnessEditField.Value = round(value,2);
            app.BrightnessSlider.Value = round(value,2);
        end

        % Value changed function: BrightnessEditField
        function BrightnessEditFieldValueChanged(app, event)
            value = app.BrightnessEditField.Value;
            app.BrightnessSlider.Value = value;
        end

        % Value changed function: MaxFrequencySlider
        function MaxFrequencySliderValueChanged(app, event)
            value = app.MaxFrequencySlider.Value;
            app.MaxFrequencyEditField.Value = round(value,2);
            app.MaxFrequencySlider.Value = round(value,2);
        end

        % Value changed function: MaxFrequencyEditField
        function MaxFrequencyEditFieldValueChanged(app, event)
            value = app.MaxFrequencyEditField.Value;
            app.MaxFrequencySlider.Value = value;
        end

        % Value changed function: MinAreaSlider
        function MinAreaSliderValueChanged(app, event)
            value = app.MinAreaSlider.Value;
            app.MinAreaEditField.Value = round(value,2);
            app.MinAreaSlider.Value = round(value,2);
        end

        % Value changed function: MinAreaEditField
        function MinAreaEditFieldValueChanged(app, event)
            value = app.MinAreaEditField.Value;
            app.MinAreaSlider.Value = value;
        end

        % Value changed function: ExpansionDistanceSlider
        function ExpansionDistanceSliderValueChanged(app, event)
            value = app.ExpansionDistanceSlider.Value;
            app.ExpansionDistanceEditField.Value = round(value,2);
            app.ExpansionDistanceSlider.Value = round(value,2);
        end

        % Value changed function: ExpansionDistanceEditField
        function ExpansionDistanceEditFieldValueChanged(app, event)
            value = app.ExpansionDistanceEditField.Value;
            app.ExpansionDistanceSlider.Value = value;
        end

        % Value changed function: CropPaddingSlider
        function CropPaddingSliderValueChanged(app, event)
            value = app.CropPaddingSlider.Value;
            app.CropPaddingEditField.Value = round(value,2);
            app.CropPaddingSlider.Value = round(value,2);
        end

        % Value changed function: CropPaddingEditField
        function CropPaddingEditFieldValueChanged(app, event)
            value = app.CropPaddingEditField.Value;
            app.CropPaddingSlider.Value = value;
        end

        % Value changed function: BinaryThresholdSlider
        function BinaryThresholdSliderValueChanged(app, event)
            value = app.BinaryThresholdSlider.Value;
            app.BinaryThresholdEditField.Value = round(value,2);
            app.BinaryThresholdSlider.Value = round(value,2);
        end

        % Callback function
        function BinaryThresholdEditFieldValueChanged(app, event)
            value = app.BinaryThresholdEditField.Value;
            app.BinaryThresholdSlider.Value = value;
        end

        % Value changed function: MovementCheckCheckBox
        function MovementCheckCheckBoxValueChanged(app, event)
            value = app.MovementCheckCheckBox.Value;
            if value
               app.FrameDepthSlider.Enable = "on"; 
            else
                app.FrameDepthSlider.Enable = "off";
            end
        end

        % Value changed function: SeperateSmallImagesCheckBox
        function SeperateSmallImagesCheckBoxValueChanged(app, event)
            value = app.SeperateSmallImagesCheckBox.Value;
            if value
                app.minWidth.Enable = "on";
                app.minHeight.Enable = "on";
            else
                app.minHeight.Enable = "off";
                app.minWidth.Enable = "off";
            end
        end

        % Value changed function: ParallelCheckBox
        function ParallelCheckBoxValueChanged(app, event)
            value = app.ParallelCheckBox.Value;
            if value
                app.parallelDropDown.Enable = "on";
            else
                app.parallelDropDown.Enable = "off";
            end
        end

        % Button pushed function: SetToRecomendedButton
        function SetToRecomendedButtonPushed(app, event)
            app.MaxFrequencySlider.Value = 0.2;
            app.MinAreaSlider.Value = 15;
            app.ExpansionDistanceSlider.Value = 5;
            app.CropPaddingSlider.Value = 5;
            app.BinaryThresholdSlider.Value = 0.60;
            app.BinaryThresholdEditField.Value = 0.60;
            app.ExpansionDistanceEditField.Value = 5;
            app.CropPaddingEditField.Value = 5;
            app.MinAreaEditField.Value = 15;
            app.MaxFrequencyEditField.Value = 0.2;
            app.SeperateSmallImagesCheckBox.Value = true;
            app.ParallelCheckBox.Value = true;
            app.minWidth.Value = "35";
            app.minHeight.Value = "35";
            app.minHeight.Enable = "on";
            app.minWidth.Enable = "on";
            app.parallelDropDown.Enable = "on";
        end

        % Value changed function: ConfidenceThresholdSlider_2
        function ConfidenceThresholdSlider_2ValueChanged(app, event)
            value = app.ConfidenceThresholdSlider_2.Value;
            app.ConfidenceEditField.Value = round(value,2);
            app.ConfidenceThresholdSlider_2.Value = round(value,2);
        end

        % Value changed function: ConfidenceEditField
        function ConfidenceEditFieldValueChanged(app, event)
            value = app.ConfidenceEditField.Value;
            app.ConfidenceThresholdSlider_2.Value = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout6 = uigridlayout(app.UIFigure);
            app.FlatFieldParametersPanel = uipanel(app.GridLayout6);
            app.FlatFieldParametersPanel.Enable = 'off';
            app.FlatFieldParametersPanel.Title = 'Flat Field Parameters';
            app.GridLayout3 = uigridlayout(app.FlatFieldParametersPanel);
            app.FlatFieldingMethodDropDown = uidropdown(app.GridLayout3);
            app.FlatFieldingMethodDropDown.Items = {'Standard', 'Dynamic', 'Precise'};
            app.FlatFieldingMethodDropDown.Editable = 'on';
            app.FlatFieldingMethodDropDown.Value = 'Standard';
            app.BrightnessSlider_2Label = uilabel(app.GridLayout3);
            app.BrightnessSlider_2Label.Text = 'Brightness';
            app.BrightnessSlider = uislider(app.GridLayout3);
            app.BrightnessSlider.Limits = [0 2];
            app.BrightnessSlider.ValueChangedFcn = createCallbackFcn(app, @BrightnessSliderValueChanged, true);
            app.BrightnessSlider.Value = 1;
            app.FlatFieldingMethodDropDown_2Label = uilabel(app.GridLayout3);
            app.FlatFieldingMethodDropDown_2Label.Text = 'Flat-Fielding Method';
            app.FlatFieldLamp = uilamp(app.GridLayout3);
            app.FlatFieldLamp.Tooltip = {'Inactive'};
            app.BrightnessEditField = uieditfield(app.GridLayout3, 'numeric');
            app.BrightnessEditField.ValueChangedFcn = createCallbackFcn(app, @BrightnessEditFieldValueChanged, true);
            app.BrightnessEditField.Value = 1;
            app.ProgressLabel = uilabel(app.GridLayout3);
            app.ProgressLabel.Visible = 'off';
            app.ProgressLabel.Text = 'Progress:';
            app.ProgressAmount = uilabel(app.GridLayout3);
            app.ProgressAmount.Visible = 'off';
            app.ProgressAmount.Text = '0%';
            app.SaveFlatFieldedVideoCheckBox = uicheckbox(app.GridLayout3);
            app.SaveFlatFieldedVideoCheckBox.Text = 'Save Flat-Fielded Video';
            app.ClassificationParametersPanel = uipanel(app.GridLayout6);
            app.ClassificationParametersPanel.Enable = 'off';
            app.ClassificationParametersPanel.Title = 'Classification Parameters';
            app.GridLayout4 = uigridlayout(app.ClassificationParametersPanel);
            app.ConfidenceThresholdSlider_2Label = uilabel(app.GridLayout4);
            app.ConfidenceThresholdSlider_2Label.Text = 'Confidence Threshold';
            app.ConfidenceThresholdSlider_2 = uislider(app.GridLayout4);
            app.ConfidenceThresholdSlider_2.Limits = [0 1];
            app.ConfidenceThresholdSlider_2.ValueChangedFcn = createCallbackFcn(app, @ConfidenceThresholdSlider_2ValueChanged, true);
            app.ConfidenceEditField = uieditfield(app.GridLayout4, 'numeric');
            app.ConfidenceEditField.ValueChangedFcn = createCallbackFcn(app, @ConfidenceEditFieldValueChanged, true);
            app.PreTrainedNetworkDropDownLabel = uilabel(app.GridLayout4);
            app.PreTrainedNetworkDropDownLabel.Text = 'Pre-Trained Network';
            app.PreTrainedNetworkDropDown = uidropdown(app.GridLayout4);
            app.PreTrainedNetworkDropDown.Items = {'None Selected...', 'Adapted GoogLeNet', 'Adapted ResNet', 'Adapted DarkNet', 'Adapted DenseNet', 'Adapted Google365', 'Adapted Xception', 'Binary Xception', 'Binary ResNet', 'Import Custom'};
            app.PreTrainedNetworkDropDown.ValueChangedFcn = createCallbackFcn(app, @PreTrainedNetworkDropDownValueChanged, true);
            app.PreTrainedNetworkDropDown.Value = 'None Selected...';
            app.IsotonicBinningCheckBox = uicheckbox(app.GridLayout4);
            app.IsotonicBinningCheckBox.Text = 'Isotonic Binning';
            app.AnomalyDetectionCheckBox = uicheckbox(app.GridLayout4);
            app.AnomalyDetectionCheckBox.Text = 'Anomaly Detection';
            app.ClassifyLamp = uilamp(app.GridLayout4);
            app.ClassifyLamp.Tooltip = {'Inactive'};
            app.UniformImageSizeEditFieldLabel = uilabel(app.GridLayout4);
            app.UniformImageSizeEditFieldLabel.Text = 'Uniform Image Size';
            app.UniformImageSizeEditField = uieditfield(app.GridLayout4, 'text');
            app.UniformImageSizeEditField.Placeholder = 'Default: [229,229]';
            app.ProgressLabel_3 = uilabel(app.GridLayout4);
            app.ProgressLabel_3.Visible = 'off';
            app.ProgressLabel_3.Text = 'Progress:';
            app.ProgressAmount_3 = uilabel(app.GridLayout4);
            app.ProgressAmount_3.Visible = 'off';
            app.ProgressAmount_3.Text = '0%';
            app.StartRunButton = uibutton(app.GridLayout6, 'push');
            app.StartRunButton.ButtonPushedFcn = createCallbackFcn(app, @StartRunButtonPushed, true);
            app.StartRunButton.Text = 'Start Run';
            app.SegmentationParametersPanel_2 = uipanel(app.GridLayout6);
            app.SegmentationParametersPanel_2.Enable = 'off';
            app.SegmentationParametersPanel_2.Title = 'Segmentation Parameters';
            app.GridLayout5 = uigridlayout(app.SegmentationParametersPanel_2);
            app.MaxFrequencySlider = uislider(app.GridLayout5);
            app.MaxFrequencySlider.Limits = [0 1];
            app.MaxFrequencySlider.ValueChangedFcn = createCallbackFcn(app, @MaxFrequencySliderValueChanged, true);
            app.MinAreaSlider = uislider(app.GridLayout5);
            app.MinAreaSlider.ValueChangedFcn = createCallbackFcn(app, @MinAreaSliderValueChanged, true);
            app.ExpansionDistanceSlider = uislider(app.GridLayout5);
            app.ExpansionDistanceSlider.Limits = [0 20];
            app.ExpansionDistanceSlider.ValueChangedFcn = createCallbackFcn(app, @ExpansionDistanceSliderValueChanged, true);
            app.CropPaddingSlider = uislider(app.GridLayout5);
            app.CropPaddingSlider.Limits = [0 20];
            app.CropPaddingSlider.ValueChangedFcn = createCallbackFcn(app, @CropPaddingSliderValueChanged, true);
            app.BinaryThresholdSlider = uislider(app.GridLayout5);
            app.BinaryThresholdSlider.Limits = [0 1];
            app.BinaryThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @BinaryThresholdSliderValueChanged, true);
            app.MovementCheckCheckBox = uicheckbox(app.GridLayout5);
            app.MovementCheckCheckBox.ValueChangedFcn = createCallbackFcn(app, @MovementCheckCheckBoxValueChanged, true);
            app.MovementCheckCheckBox.Text = 'Movement Check';
            app.SeperateSmallImagesCheckBox = uicheckbox(app.GridLayout5);
            app.SeperateSmallImagesCheckBox.ValueChangedFcn = createCallbackFcn(app, @SeperateSmallImagesCheckBoxValueChanged, true);
            app.SeperateSmallImagesCheckBox.Text = 'Seperate Small Images';
            app.minWidth = uieditfield(app.GridLayout5, 'text');
            app.minWidth.InputType = 'digits';
            app.minWidth.Enable = 'off';
            app.minWidth.Placeholder = 'minWidth';
            app.minHeight = uieditfield(app.GridLayout5, 'text');
            app.minHeight.InputType = 'digits';
            app.minHeight.Enable = 'off';
            app.minHeight.Placeholder = 'minHeight';
            app.FrameDepthSliderLabel = uilabel(app.GridLayout5);
            app.FrameDepthSliderLabel.Enable = 'off';
            app.FrameDepthSliderLabel.Text = 'Frame Depth';
            app.FrameDepthSlider = uislider(app.GridLayout5);
            app.FrameDepthSlider.Limits = [0 10];
            app.FrameDepthSlider.Enable = 'off';
            app.ParallelCheckBox = uicheckbox(app.GridLayout5);
            app.ParallelCheckBox.ValueChangedFcn = createCallbackFcn(app, @ParallelCheckBoxValueChanged, true);
            app.ParallelCheckBox.Text = 'Parallel';
            app.parallelDropDown = uidropdown(app.GridLayout5);
            app.parallelDropDown.Items = {'Threads', 'Processes'};
            app.parallelDropDown.Enable = 'off';
            app.parallelDropDown.Value = 'Threads';
            app.SegmentationLamp = uilamp(app.GridLayout5);
            app.SegmentationLamp.Tooltip = {'Inactive'};
            app.SetToRecomendedButton = uibutton(app.GridLayout5, 'push');
            app.SetToRecomendedButton.ButtonPushedFcn = createCallbackFcn(app, @SetToRecomendedButtonPushed, true);
            app.SetToRecomendedButton.Text = 'Set To Recomended';
            app.MaxFrequencyEditFieldLabel = uilabel(app.GridLayout5);
            app.MaxFrequencyEditFieldLabel.Text = 'Max Frequency';
            app.MaxFrequencyEditField = uieditfield(app.GridLayout5, 'numeric');
            app.MaxFrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @MaxFrequencyEditFieldValueChanged, true);
            app.MinAreaEditFieldLabel = uilabel(app.GridLayout5);
            app.MinAreaEditFieldLabel.Text = 'Min Area';
            app.MinAreaEditField = uieditfield(app.GridLayout5, 'numeric');
            app.MinAreaEditField.ValueChangedFcn = createCallbackFcn(app, @MinAreaEditFieldValueChanged, true);
            app.ExpansionDistanceEditFieldLabel = uilabel(app.GridLayout5);
            app.ExpansionDistanceEditFieldLabel.Text = 'Expansion Distance';
            app.ExpansionDistanceEditField = uieditfield(app.GridLayout5, 'numeric');
            app.ExpansionDistanceEditField.ValueChangedFcn = createCallbackFcn(app, @ExpansionDistanceEditFieldValueChanged, true);
            app.CropPaddingEditFieldLabel = uilabel(app.GridLayout5);
            app.CropPaddingEditFieldLabel.Text = 'Crop Padding';
            app.CropPaddingEditField = uieditfield(app.GridLayout5, 'numeric');
            app.CropPaddingEditField.ValueChangedFcn = createCallbackFcn(app, @CropPaddingEditFieldValueChanged, true);
            app.BinaryThresholdEditFieldLabel = uilabel(app.GridLayout5);
            app.BinaryThresholdEditFieldLabel.Text = 'Binary Threshold';
            app.BinaryThresholdEditField = uieditfield(app.GridLayout5, 'numeric');
            app.ProgressLabel_2 = uilabel(app.GridLayout5);
            app.ProgressLabel_2.Visible = 'off';
            app.ProgressLabel_2.Text = 'Progress:';
            app.ProgressAmount_2 = uilabel(app.GridLayout5);
            app.ProgressAmount_2.Visible = 'off';
            app.ProgressAmount_2.Text = '0%';
            app.BrowseButton = uibutton(app.GridLayout6, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.FlatFieldSwitchLabel = uilabel(app.GridLayout6);
            app.FlatFieldSwitchLabel.Text = 'FlatField';
            app.FlatFieldSwitch = uiswitch(app.GridLayout6, 'slider');
            app.FlatFieldSwitch.ValueChangedFcn = createCallbackFcn(app, @FlatFieldSwitchValueChanged, true);
            app.ClassifySwitchLabel = uilabel(app.GridLayout6);
            app.ClassifySwitchLabel.Text = 'Classify';
            app.ClassifySwitch = uiswitch(app.GridLayout6, 'slider');
            app.ClassifySwitch.ValueChangedFcn = createCallbackFcn(app, @ClassifySwitchValueChanged, true);
            app.DataSourceEditFieldLabel = uilabel(app.GridLayout6);
            app.DataSourceEditFieldLabel.Text = 'Data Source';
            app.DataSourceEditField = uieditfield(app.GridLayout6, 'text');
            app.DataSourceEditField.ValueChangedFcn = createCallbackFcn(app, @DataSourceEditFieldValueChanged, true);
            app.SegmentationSwitchLabel = uilabel(app.GridLayout6);
            app.SegmentationSwitchLabel.Text = 'Segmentation';
            app.SegmentSwitch = uiswitch(app.GridLayout6, 'slider');
            app.SegmentSwitch.ValueChangedFcn = createCallbackFcn(app, @SegmentSwitchValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SegmentationAppV3_BatchV2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end