classdef advancedOptions < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        ApplyButton                     matlab.ui.control.Button
        AugmentedDataStoreSwitch        matlab.ui.control.Switch
        AugmentedDataStoreSwitchLabel   matlab.ui.control.Label
        LearnRateDropPeriodEditField    matlab.ui.control.EditField
        LearnRateDropPeriodEditFieldLabel  matlab.ui.control.Label
        MiniBatchSizeEditField          matlab.ui.control.EditField
        MiniBatchSizeEditFieldLabel     matlab.ui.control.Label
        LearnRateScheduleDropDown       matlab.ui.control.DropDown
        LearnRateScheduleDropDownLabel  matlab.ui.control.Label
        LearnRateDropFactorEditField    matlab.ui.control.EditField
        LearnRateDropFactorEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        Property % Description
    end
    
    properties (Access = public)
        output % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            app.output=[app.AugmentedDataStoreSwitch.Value,app.LearnRateScheduleDropDown.Value,string(app.LearnRateDropFactorEditField.Value),string(app.LearnRateDropPeriodEditField.Value),string(app.MiniBatchSizeEditField.Value)]; % Just to demonstrate...
            app.ApplyButton.UserData=1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LearnRateDropFactorEditFieldLabel = uilabel(app.GridLayout);
            app.LearnRateDropFactorEditFieldLabel.Text = 'Learn Rate Drop Factor';
            app.LearnRateDropFactorEditField = uieditfield(app.GridLayout, 'text');
            app.LearnRateScheduleDropDownLabel = uilabel(app.GridLayout);
            app.LearnRateScheduleDropDownLabel.Text = 'Learn Rate Schedule';
            app.LearnRateScheduleDropDown = uidropdown(app.GridLayout);
            app.LearnRateScheduleDropDown.Items = {'None', 'PieceWise'};
            app.LearnRateScheduleDropDown.Value = 'None';
            app.MiniBatchSizeEditFieldLabel = uilabel(app.GridLayout);
            app.MiniBatchSizeEditFieldLabel.Text = 'MiniBatch Size';
            app.MiniBatchSizeEditField = uieditfield(app.GridLayout, 'text');
            app.LearnRateDropPeriodEditFieldLabel = uilabel(app.GridLayout);
            app.LearnRateDropPeriodEditFieldLabel.Text = 'Learn Rate Drop Period';
            app.LearnRateDropPeriodEditField = uieditfield(app.GridLayout, 'text');
            app.AugmentedDataStoreSwitchLabel = uilabel(app.GridLayout);
            app.AugmentedDataStoreSwitchLabel.Text = 'Augmented DataStore';
            app.AugmentedDataStoreSwitch = uiswitch(app.GridLayout, 'slider');
            app.ApplyButton = uibutton(app.GridLayout, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = advancedOptions

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end