classdef advancedOptionsv2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        EditClassDropDown             matlab.ui.control.DropDown
        EditClassDropDownLabel        matlab.ui.control.Label
        CNNNicknameEditField          matlab.ui.control.EditField
        CNNNicknameEditFieldLabel     matlab.ui.control.Label
        ApplyChangesButton            matlab.ui.control.Button
        BrowseforsubclassCNNButton    matlab.ui.control.Button
        EditField                     matlab.ui.control.EditField
        SubclassestobeaddedEditField  matlab.ui.control.EditField
        SubclassestobeaddedEditFieldLabel  matlab.ui.control.Label
        Tree                          matlab.ui.container.Tree
        BaseClassesNode               matlab.ui.container.TreeNode
        BaseCNNNode                   matlab.ui.container.TreeNode
        ChaetognathNode               matlab.ui.container.TreeNode
        CrustaceanNode                matlab.ui.container.TreeNode
        DetritusANode                 matlab.ui.container.TreeNode
        DetritusBNode                 matlab.ui.container.TreeNode
        GelatinousNode                matlab.ui.container.TreeNode
        LarvaceanNode                 matlab.ui.container.TreeNode
    end

    
    properties (Access = public)
        cnnLocation; % Description
        advancedOptions;
        subClassInfo;
        appCalled = 0;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, advancedOptions)
            app.BaseClassesNode.expand;
            app.advancedOptions = advancedOptions;
            if ~isempty(app.advancedOptions.dataArea.UserInput)
                classes = app.BaseClassesNode.Children;
                previousOptions = app.advancedOptions.dataArea.UserInput;
                for z = 1:size(app.advancedOptions.dataArea.UserInput,1)
                    uitreenode("Text",string(previousOptions(z,end)),"Icon",app.BaseCNNNode.Icon,"Parent",classes(cell2mat(previousOptions(z,1))));
                    tempNodes = strip(split(string(cell2table(previousOptions(z,2)).(1)),","));
                    for j = 1:length(tempNodes)
                        uitreenode("Text",tempNodes(j),"Parent",classes(cell2mat(previousOptions(z,1))));
                    end
                end
            end
            % app.advancedOptions.dataArea.subClassNodes = [];
            % app.advancedOptions.dataArea.subClassTitles = {};
        end

        % Button pushed function: ApplyChangesButton
        function ApplyChangesButtonPushed(app, event)
                classes = app.BaseClassesNode.Children;
                if isempty(app.EditField.Value)
                    uialert(app.UIFigure,"Must provide path for custom sub-layer CNN.","Error");
                elseif ~isempty(app.SubclassestobeaddedEditField.Value)
                    if ~isempty(app.CNNNicknameEditField.Value)
                        cnnNickName = string(app.CNNNicknameEditField.Value);
                    else
                        [~,pathParts,~] = fileparts(app.EditField.Value);
                        cnnNickName = pathParts;
                    end

                    classToAdd = classes(app.EditClassDropDown.ValueIndex+1);
                    subClassNames = char(app.SubclassestobeaddedEditField.Value);
                    subClassNames([1,end]) = [];
                    newStr = strip(split(string(subClassNames),","));
                    uitreenode("Text",cnnNickName,"Icon",app.BaseCNNNode.Icon,"Parent",classToAdd);
                    for z = 1:length(newStr)
                        uitreenode("Text",newStr(z),"Parent",classToAdd);
                    end
                else
                    uialert(app.UIFigure,"Subclass titles are required.","Error");
                end
                app.advancedOptions.dataArea.UserInput = cat(1,app.advancedOptions.dataArea.UserInput,[{app.EditClassDropDown.ValueIndex+1},{strjoin(newStr(:),",")},{app.EditField.Value},{cnnNickName}]);
                % app.advancedOptions.dataArea.UserInput = cat(1,app.advancedOptions.dataArea.UserInput,app.subClassInfo);

        end

        % Button pushed function: BrowseforsubclassCNNButton
        function BrowseforsubclassCNNButtonPushed(app, event)
            app.UIFigure.WindowStyle = "normal";
            [file,location] = uigetfile();    
            app.UIFigure.WindowStyle = "alwaysontop";
            if ~isequal(location,0)
                app.EditField.Value = file;
            end
            app.cnnLocation = [file,location];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Tree = uitree(app.GridLayout);
            app.BaseClassesNode = uitreenode(app.Tree);
            app.BaseClassesNode.Text = 'Base Classes';
            app.BaseCNNNode = uitreenode(app.BaseClassesNode);
            app.BaseCNNNode.Icon = 'artificial-intelligence-neural-network-22161.svg';
            app.BaseCNNNode.Text = 'Base CNN';
            app.ChaetognathNode = uitreenode(app.BaseClassesNode);
            app.ChaetognathNode.Text = '1. Chaetognath';
            app.CrustaceanNode = uitreenode(app.BaseClassesNode);
            app.CrustaceanNode.Text = '2. Crustacean';
            app.DetritusANode = uitreenode(app.BaseClassesNode);
            app.DetritusANode.Text = '3. DetritusA';
            app.DetritusBNode = uitreenode(app.BaseClassesNode);
            app.DetritusBNode.Text = '4. DetritusB';
            app.GelatinousNode = uitreenode(app.BaseClassesNode);
            app.GelatinousNode.Text = '5. Gelatinous';
            app.LarvaceanNode = uitreenode(app.BaseClassesNode);
            app.LarvaceanNode.Text = '6. Larvacean';
            app.SubclassestobeaddedEditFieldLabel = uilabel(app.GridLayout);
            app.SubclassestobeaddedEditFieldLabel.Text = 'Subclasses to be added';
            app.SubclassestobeaddedEditField = uieditfield(app.GridLayout, 'text');
            app.SubclassestobeaddedEditField.Placeholder = 'Format: [Class1,Class2,...]';
            app.EditField = uieditfield(app.GridLayout, 'text');
            app.EditField.Placeholder = 'Insert path or browse ...';
            app.BrowseforsubclassCNNButton = uibutton(app.GridLayout, 'push');
            app.BrowseforsubclassCNNButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseforsubclassCNNButtonPushed, true);
            app.BrowseforsubclassCNNButton.Text = 'Browse for subclass CNN';
            app.ApplyChangesButton = uibutton(app.GridLayout, 'push');
            app.ApplyChangesButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyChangesButtonPushed, true);
            app.ApplyChangesButton.Text = 'Apply Changes';
            app.CNNNicknameEditFieldLabel = uilabel(app.GridLayout);
            app.CNNNicknameEditFieldLabel.Text = 'CNN Nickname';
            app.CNNNicknameEditField = uieditfield(app.GridLayout, 'text');
            app.CNNNicknameEditField.Tooltip = {'Optional'};
            app.EditClassDropDownLabel = uilabel(app.GridLayout);
            app.EditClassDropDownLabel.Text = 'Edit Class';
            app.EditClassDropDown = uidropdown(app.GridLayout);
            app.EditClassDropDown.Items = {'Chaetognath', 'Crustacean', 'DetritusA', 'DetritusB', 'Gelatinous', 'Larvacean'};
            app.EditClassDropDown.Value = 'Chaetognath';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = advancedOptionsv2(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end